classdef Calibration_LAICPMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        XMapToolsCalibrationLaICPMS     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        GridLayout2                     matlab.ui.container.GridLayout
        Button_Help                     matlab.ui.control.Button
        GridLayout3                     matlab.ui.container.GridLayout
        AppyCalibration                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CalibrationDefinitionTab        matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        ClassDropDownLabel              matlab.ui.control.Label
        ClassDropDown                   matlab.ui.control.DropDown
        InternalElementStdDropDownLabel  matlab.ui.control.Label
        InternalElementStdDropDown      matlab.ui.control.DropDown
        ConstantcompositionCheckBox     matlab.ui.control.CheckBox
        VariablecompositionCheckBox     matlab.ui.control.CheckBox
        FixedInternalStdConc_EditFieldLabel  matlab.ui.control.Label
        FixedInternalStdConc_EditField  matlab.ui.control.NumericEditField
        InternalCompositionConverterLabel  matlab.ui.control.Label
        wtOxideDropDownLabel            matlab.ui.control.Label
        wtOxideDropDown                 matlab.ui.control.DropDown
        Oxide_EditField                 matlab.ui.control.NumericEditField
        ppm_EditField                   matlab.ui.control.NumericEditField
        toXXLabel                       matlab.ui.control.Label
        ggLabel                         matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        ElementDropDownLabel            matlab.ui.control.Label
        ElementDropDown                 matlab.ui.control.DropDown
        DisplayValue                    matlab.ui.control.NumericEditField
        ButtonTarget                    matlab.ui.control.Button
        UITable_ElemList                matlab.ui.control.Table
        GridLayout5                     matlab.ui.container.GridLayout
        Standard_DropDown               matlab.ui.control.DropDown
        StdApplytoallButton             matlab.ui.control.Button
        StdApplytoSelectionButton       matlab.ui.control.Button
        SaveunfilteredmapsCheckBox      matlab.ui.control.CheckBox
        BydefaultLODfilteredmapsaresavedLabel  matlab.ui.control.Label
        LODfilteringLabel               matlab.ui.control.Label
        Plot2                           matlab.ui.control.UIAxes
        Plot3                           matlab.ui.control.UIAxes
        Plot1                           matlab.ui.control.UIAxes
        SuperPrecisionInterrogatorTab   matlab.ui.container.Tab
        SPI_GridLayout                  matlab.ui.container.GridLayout
        SPI_Table_ROI                   matlab.ui.control.Table
        SPI_Table_Analysis              matlab.ui.control.Table
        GridLayout8                     matlab.ui.container.GridLayout
        SPI_AddROI                      matlab.ui.control.Button
        SPI_DeleteROI                   matlab.ui.control.Button
        SPI_MenuROI                     matlab.ui.control.DropDown
        SPI_Export                      matlab.ui.control.Button
        GridLayout9                     matlab.ui.container.GridLayout
        SPI_MapMenu                     matlab.ui.control.DropDown
        SPI_Limits_Min                  matlab.ui.control.NumericEditField
        SPI_Limits_Max                  matlab.ui.control.NumericEditField
        SPI_LogScale                    matlab.ui.control.CheckBox
        SPI_Plot                        matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        CopyMenu                        matlab.ui.container.Menu
    end

    
    properties (Access = private)
        XMapToolsApp        % Description
        MaskFile
        
        ElNamesStd
        NbElStd
        
        % MapStandardsData    % eliminated and replaced by MapStandards
        MapStandards
        MapCpsData
        
        PxDataRaw       % For SPI
        
        Map_k
        
        ROI_sampling
        ROI_sampling_Listener
        ROI_sampling_Listener2
        
        DataInter
        NbPoints
        PointsValid
        
        CalibrationOptions
        
        SelectedCell
        
        SPI_Activated
        
        WaitBar
        
        UpdatePlotROI
        
        SPI_ROIs
        SPI_ROIs_Listener
        SPI_ROIs_Data
        
        
    end
    
    methods (Access = private)
        
        function PlotAllMaps(app)
            
            % Mask
            MaskId = app.ClassDropDown.Value; 
            
            MaskMap = zeros(size(app.MaskFile.MaskMap));
            MaskInd = find(app.MaskFile.MaskMap == MaskId);
            MaskMap(MaskInd) = ones(size(MaskInd));
            
            % Intensity
            ElId = app.ElementDropDown.Value;
            CpsMap = app.MapCpsData(ElId).Cps;
            
            imagesc(app.Plot1,CpsMap.*MaskMap)
            axis(app.Plot1,'image')
            if size(MaskMap,2) > 1.2*size(MaskMap,1)  
                colorbar(app.Plot1,'horizontal');
            else
                colorbar(app.Plot1,'vertical');
            end
            colormap(app.Plot1,app.XMapToolsApp.ColorMapValues_noMask);
            title(app.Plot1,[char(app.ElNamesStd{ElId}),' intensity (in cps)'])
            
            tb = axtoolbar(app.Plot1,{'pan','zoomin','zoomout','restoreview'});
            disableDefaultInteractivity(app.Plot1);
            
            drawnow
            
            WePlot = 0;
            if isequal(app.ConstantcompositionCheckBox.Value,1) 
                RefConc = app.FixedInternalStdConc_EditField.Value;
                if ~isequal(RefConc,0)
                    WePlot = 1;
                end
            end
            
            if isequal(app.VariablecompositionCheckBox.Value,1) 
                if sum(app.PointsValid) >= 2
                    WePlot = 1;
                end
            end
            
            
            if WePlot
                
                SelStd = app.CalibrationOptions.SelStd(ElId);
                
                imagesc(app.Plot3,app.Map_k(:,:,SelStd))
                axis(app.Plot3,'image')
                if size(MaskMap,2) > 1.2*size(MaskMap,1)  
                    colorbar(app.Plot3,'horizontal');
                else
                    colorbar(app.Plot3,'vertical');
                end
                colormap(app.Plot3,app.XMapToolsApp.ColorMapValues_noMask);
                title(app.Plot3,['k matrix (',app.CalibrationOptions.StdOptions{SelStd},')'],'Interpreter','none')
                
                imagesc(app.Plot2,app.MapCpsData(ElId).Conc)
                axis(app.Plot2,'image')
                if size(MaskMap,2) > 1.2*size(MaskMap,1)
                    colorbar(app.Plot2,'horizontal');
                else
                    colorbar(app.Plot2,'vertical');
                end
                colormap(app.Plot2,app.XMapToolsApp.ColorMapValues_noMask);
                title(app.Plot2,[char(app.ElNamesStd{ElId}),' (in µg/g)'])
                
                app.Plot2.Visible = 'on';
                app.Plot3.Visible = 'on';
                
                disableDefaultInteractivity(app.Plot2);
                disableDefaultInteractivity(app.Plot3);
            else
                app.AppyCalibration.Enable = 'off';
                cla(app.Plot2)
                cla(app.Plot3)
                app.Plot2.Visible = 'off';
                app.Plot3.Visible = 'off';
            end
           
            %keyboard
            
        end
        
        
        function Plot_SPI(app)
            % Mask
            MaskId = app.ClassDropDown.Value; 
            
            MaskMap = zeros(size(app.MaskFile.MaskMap));
            MaskInd = find(app.MaskFile.MaskMap == MaskId);
            MaskMap(MaskInd) = ones(size(MaskInd));
            
            % Intensity
            ElId = app.SPI_MapMenu.Value;
            ConcMap = app.MapCpsData(ElId).Conc;
            
            cla(app.SPI_Plot,'reset')
            
            imagesc(app.SPI_Plot,ConcMap.*MaskMap);
            axis(app.SPI_Plot,'image')
            if size(MaskMap,2) > 1.2*size(MaskMap,1)  
                colorbar(app.SPI_Plot,'horizontal');
            else
                colorbar(app.SPI_Plot,'vertical');
            end
            colormap(app.SPI_Plot,[app.XMapToolsApp.ColorMapValues_noMask;1.0000,0.0784,0.5765]);
            
            app.SPI_Limits_Min.Value = min(ConcMap(:));
            app.SPI_Limits_Max.Value = max(ConcMap(:));
            
            LodMap = app.MapCpsData(ElId).LOD;
            
            WhereBelowLOD = find(ConcMap > 0 & ConcMap <= LodMap);
            
            LOD_Filter = nan(size(LodMap));
            LOD_Filter(WhereBelowLOD) = 2 * app.SPI_Limits_Max.Value;
            
            AlphaMap = zeros(size(LOD_Filter));
            AlphaMap(WhereBelowLOD) = 1;
            
            hold(app.SPI_Plot,'on') 
            imagesc(app.SPI_Plot,LOD_Filter, "AlphaData", AlphaMap);
            
            app.SPI_Plot.XTick = [];
            app.SPI_Plot.YTick = [];
            
            disableDefaultInteractivity(app.SPI_Plot)
            
            CheckLogScale(app)
        end
        
        
        function CalculateConversion(app)
            
            OxIdx = app.wtOxideDropDown.Value;
            OxValue = app.Oxide_EditField.Value;
            
            app.toXXLabel.Text = ['to ',app.XMapToolsApp.ElOxDataDef.ElList{app.XMapToolsApp.ElOxDataDef.OxElIdx(OxIdx)}];
            
            app.ppm_EditField.Value = OxValue * app.XMapToolsApp.ElOxDataDef.OxFact(OxIdx) * 1e4;
            
        end
        
        function CalibrateMaps(app)
            
            % Fixed composition for the internal standard
            if isequal(app.ConstantcompositionCheckBox.Value,1)
                RefConc = app.FixedInternalStdConc_EditField.Value;
                if ~isequal(RefConc,0)
                    % Mask
                    MaskId = app.ClassDropDown.Value;
                    
                    MaskMap = zeros(size(app.MaskFile.MaskMap));
                    MaskInd = find(app.MaskFile.MaskMap == MaskId);
                    MaskMap(MaskInd) = ones(size(MaskInd));
                    
                    %
                    IntStd_Id = app.InternalElementStdDropDown.Value;
                    
                    if isequal(app.ConstantcompositionCheckBox.Value,1)
                        Con_Unk = ones(size(MaskMap)).* app.FixedInternalStdConc_EditField.Value .* MaskMap;
                    end
                    
                    k_mtx = [];
                    for i =1:length(app.MapStandards)
                        It_Std = app.MapStandards(i).Data(IntStd_Id).Cps .* MaskMap;
                        Con_Std = app.MapStandards(i).Data(IntStd_Id).Conc .* MaskMap;
                        
                        It_Unk = app.MapCpsData(IntStd_Id).Cps .* MaskMap;
                        
                        k_mtx(:,:,i) = (It_Unk.*Con_Std)./(Con_Unk.*It_Std);
                    end
                    
                    for i = 1:length(app.InternalElementStdDropDown.Items)
                        
                        SelStd = app.CalibrationOptions.SelStd(i);
                        
                        It_Unk_mtx = app.MapCpsData(i).Cps;
                        
                        It_Std_mtx = app.MapStandards(SelStd).Data(i).Cps;
                        Con_Std_mxt = app.MapStandards(SelStd).Data(i).Conc;
                        
                        app.MapCpsData(i).Conc = (It_Unk_mtx.*Con_Std_mxt)./(k_mtx(:,:,SelStd).*It_Std_mtx);
                        
                        % Limit of detection (Theoule-sur-Mer)
                        Si = k_mtx(:,:,SelStd).*It_Std_mtx./Con_Std_mxt;                % cps/µg.g-1
                        Bi = app.MapStandards(SelStd).Data(i).Int_Back;                 % cps
                        DTi = app.CalibrationOptions.SweepTime(i)/1e3;                  % seconds                                           
                        NbAnal = app.MapStandards(SelStd).Data(i).Sweeps_Pixel;         % dimensionless
                        NbBack = app.MapStandards(SelStd).Data(i).Sweeps_Back;          % dimensionless
                        
                        app.MapCpsData(i).LOD = (3.29.*sqrt(Bi.*DTi.*NbAnal.*(1+NbAnal./NbBack)+2.71))./(NbAnal.*DTi.*Si);    % µg.g-1  
                        
                        % Filter LOD for values with zero intensities for
                        % background (Cavalaire 2023):
                        
                        % FilterLOD = find(Bi == 0);
                        % app.MapCpsData(i).LOD(FilterLOD) = 0;
                        
                        % --> Deactivated after Cassis for SPI module.
                        
                    end
                    
                    app.Map_k =  k_mtx;
                    
                    app.AppyCalibration.Enable = 'on';
                    
                    app.VariablecompositionCheckBox.Enable = 'off';
                    app.ConstantcompositionCheckBox.Enable = 'off';
                    app.ClassDropDown.Enable = 'off';
                end
            else
                if sum(app.PointsValid) >= 2
                    % Mask
                    MaskId = app.ClassDropDown.Value;
                    
                    MaskMap = zeros(size(app.MaskFile.MaskMap));
                    MaskInd = find(app.MaskFile.MaskMap == MaskId);
                    MaskMap(MaskInd) = ones(size(MaskInd));
                    
                    %
                    IntStd_Id = app.InternalElementStdDropDown.Value;
                    
                    k_mtx = [];
                    for i =1:length(app.MapStandards)
                        It_Std = app.MapStandards(i).Data(IntStd_Id).Cps .* MaskMap;
                        Con_Std = app.MapStandards(i).Data(IntStd_Id).Conc .* MaskMap;
                        
                        It_Unk = app.MapCpsData(IntStd_Id).Cps .* MaskMap;
                        
                        % 
                        FitData = app.DataInter(:,find(app.PointsValid));
                        p = polyfit(FitData(1,:),FitData(2,:),1);
                        
                        Con_Unk = polyval(p,It_Unk) .* MaskMap;
                        
                        k_mtx(:,:,i) = (It_Unk.*Con_Std)./(Con_Unk.*It_Std);
                    end
                    
                    for i = 1:length(app.InternalElementStdDropDown.Items)
                        SelStd = app.CalibrationOptions.SelStd(i);
                        
                        It_Unk_mtx = app.MapCpsData(i).Cps;
                        
                        It_Std_mtx = app.MapStandards(SelStd).Data(i).Cps;
                        Con_Std_mxt = app.MapStandards(SelStd).Data(i).Conc;
                        
                        app.MapCpsData(i).Conc = (It_Unk_mtx.*Con_Std_mxt)./(k_mtx(:,:,SelStd).*It_Std_mtx);
                        
                        % Limit of detection (Theoule-sur-Mer)
                        Si = k_mtx(:,:,SelStd).*It_Std_mtx./Con_Std_mxt;                % cps/µg.g-1
                        Bi = app.MapStandards(SelStd).Data(i).Int_Back;                 % cps
                        DTi = app.CalibrationOptions.SweepTime(i)/1e3;                  % seconds                                           
                        NbAnal = app.MapStandards(SelStd).Data(i).Sweeps_Pixel;         % dimensionless
                        NbBack = app.MapStandards(SelStd).Data(i).Sweeps_Back;          % dimensionless
                        
                        app.MapCpsData(i).LOD = (3.29.*sqrt(Bi.*DTi.*NbAnal.*(1+NbAnal./NbBack)+2.71))./(NbAnal.*DTi.*Si);    % µg.g-1  
                        
                        %Con_Unk_mtx = (It_Unk_mtx.*Con_Std_mxt)./(k_mtx.*It_Std_mtx);
                    end
                    
                    app.Map_k =  k_mtx; 
                    
                    app.AppyCalibration.Enable = 'on';
                    
                    app.VariablecompositionCheckBox.Enable = 'off';
                    app.ConstantcompositionCheckBox.Enable = 'off';
                end
            end
            
            
        end
        
        function ChangeMode(app,Mode)
            
            switch Mode
                
                case 'Fixed'
                    app.ConstantcompositionCheckBox.Value = 1;
                    app.VariablecompositionCheckBox.Value = 0;
                    
                    app.FixedInternalStdConc_EditField.Visible = 'on';
                    app.FixedInternalStdConc_EditFieldLabel.Visible = 'on';
                    
                    app.UITable.Visible = 'off';
                    app.DisplayValue.Visible = 'off';
                    app.ButtonTarget.Visible = 'off';
                    
                    %keyboard
                    
                case 'Variable'
                    app.ConstantcompositionCheckBox.Value = 0;
                    app.VariablecompositionCheckBox.Value = 1;
                    
                    
                    app.FixedInternalStdConc_EditField.Visible = 'off';
                    app.FixedInternalStdConc_EditFieldLabel.Visible = 'off';
                    
                    app.UITable.Visible = 'on';
                    app.DisplayValue.Visible = 'on';
                    app.ButtonTarget.Visible = 'on';
                    
                    %keyboard
                    
            end
            
        end
        
        function ROI_Value_Extractor(app, ~)
            
            ImageData = PlotMap_ExtractPlottedImage(app);
            mask = createMask(app.ROI_sampling);
            Ind = find(mask);
            app.DisplayValue.Value = mean(ImageData(Ind));
            
        end
        
        function ImageData = PlotMap_ExtractPlottedImage(app)
            lesInd = app.Plot1.Children;
            for i=1:numel(lesInd)
                if isequal(lesInd(i).Type,'image')
                    ImageData = lesInd(i).CData;
                    break
                else
                    ImageData = [];
                end
            end
        end
        
        
        function UpdateTableElementStandard(app)
            
            TableEl = {};
            
            for i = 1:length(app.CalibrationOptions.ElNames)
                TableEl{i,1} = ['#',num2str(i)];
                TableEl{i,2} = app.CalibrationOptions.ElNames{i};
                TableEl{i,3} = app.CalibrationOptions.StdOptions{app.CalibrationOptions.SelStd(i)};
                TableEl{i,4} = app.CalibrationOptions.SweepTime(i);
            end
            
            app.UITable_ElemList.Data = TableEl;
            app.UITable_ElemList.ColumnFormat = {'char','char',app.CalibrationOptions.StdOptions,'numeric'};
            app.UITable_ElemList.ColumnEditable = [false,false,true,true];
            app.UITable_ElemList.ColumnName = {'Idx','Element','Primary Standard','Sweep time (ms)'};
            app.UITable_ElemList.ColumnWidth = {'1x','1x','3x','2x'};
            
            s = uistyle('BackgroundColor','white');
            addStyle(app.UITable_ElemList,s);
            
            s2 = uistyle('BackgroundColor',[0.6863,0.8549,1]);
            addStyle(app.UITable_ElemList,s2,'row',app.InternalElementStdDropDown.Value);
            
            WePlot = 0;
            if isequal(app.ConstantcompositionCheckBox.Value,1) 
                RefConc = app.FixedInternalStdConc_EditField.Value;
                if ~isequal(RefConc,0)
                    WePlot = 1;
                end
            end
            if isequal(app.VariablecompositionCheckBox.Value,1) 
                if sum(app.PointsValid) >= 2
                    WePlot = 1;
                end
            end
            
            if WePlot
                CalibrateMaps(app);
                PlotAllMaps(app);
            end
            
            
        end
        
        function Overwrite_Import_DT(app)
            fid = fopen('Import_DT.txt','w');
            fprintf(fid,'%s\n','##########################################################################');
            fprintf(fid,'%s\n','#     XMapTools Last Import Settings for DT & STANDARDS (DO NOT EDIT)    #');
            fprintf(fid,'%s\n','##########################################################################');
            fprintf(fid,'%s\n','...');
            for i = 1:length(app.CalibrationOptions.ElNames)
                fprintf(fid,'%s\t%s\t%f\n',app.CalibrationOptions.ElNames{i},app.CalibrationOptions.StdOptions{app.CalibrationOptions.SelStd(i)},app.CalibrationOptions.SweepTime(i));
            end
            fclose(fid);
            %disp('Import_DT.txt saved!')
        end
        
        function DeactivatePlotZoomPanOptions(app)
            
            if ~isempty(app.Plot1)
                
                if ~isempty(app.Plot1.Toolbar.Children)
                    app.Plot1.Toolbar.Children(1).Value = 'off';
                    matlab.graphics.interaction.webmodes.toggleMode(app.Plot1,'pan',app.Plot1.Toolbar.Children(1).Value)
                    
                    app.Plot1.Toolbar.Children(2).Value = 'off';
                    matlab.graphics.interaction.webmodes.toggleMode(app.Plot1,'zoom',app.Plot1.Toolbar.Children(2).Value)
                    
                    app.Plot1.Toolbar.Children(3).Value = 'off';
                    matlab.graphics.interaction.webmodes.toggleMode(app.Plot1,'zoomout',app.Plot1.Toolbar.Children(3).Value)
                end
            end
        end
        
        function CheckLogScale(app)
            %log
            if app.SPI_LogScale.Value
                app.SPI_Plot.ColorScale = 'log';
            else
                app.SPI_Plot.ColorScale = 'linear';
            end
        end
        
        function CheckLimits_SPI(app)
            if app.SPI_Limits_Max.Value > app.SPI_Limits_Min.Value
                caxis(app.SPI_Plot,[app.SPI_Limits_Min.Value,app.SPI_Limits_Max.Value])
            end
        end
        
        function eliminate_ROI(app)
            delete(findall(app.SPI_Plot, 'Type',  'images.roi.Circle'));
            delete(findall(app.SPI_Plot, 'Type',  'images.roi.Rectangle'));
            delete(findall(app.SPI_Plot, 'Type',  'images.roi.Polygon'));
        end
        
        function SPI_ROIs_DataExtraction_FIRST(app, SelROI)
            
            Mask = createMask(app.SPI_ROIs.ROI(SelROI).ROI,app.SPI_Plot.Children(end));
            Ind = find(Mask);
            
            % Check that we are on the phase?
            Type = app.SPI_ROIs.ROI(SelROI).ROI.Type;
            
            switch Type
                case 'images.roi.circle'
                    app.SPI_ROIs_Data(SelROI).Type = 'Circle';
                case 'images.roi.rectangle'
                    app.SPI_ROIs_Data(SelROI).Type = 'Rectangle';
                case 'images.roi.polygon'
                    app.SPI_ROIs_Data(SelROI).Type = 'Polygon';
            end
            
            [X_grid,Y_grid] = meshgrid([1:size(Mask,2)],[1:size(Mask,1)]);
            
            app.SPI_ROIs_Data(SelROI).PxIdx = Ind;
            app.SPI_ROIs_Data(SelROI).PxCoordXY = [X_grid(Ind),Y_grid(Ind)];
            app.SPI_ROIs_Data(SelROI).NbIdx = length(Ind);
            app.SPI_ROIs_Data(SelROI).Mask = Mask;
            
        end
        
        function SPI_ROIs_DataExtraction(app, ~)
            
            SelROI = app.SPI_Table_ROI.Selection; 
            
            %for i = 1:length(app.SPI_ROIs.ROI)
            
            Data4Table = app.SPI_Table_ROI.Data;
            
            Mask = createMask(app.SPI_ROIs.ROI(SelROI).ROI,app.SPI_Plot.Children(end));
            Ind = find(Mask);
            
            % Update the number of pixels
            app.SPI_ROIs_Data(SelROI).NbIdx = length(Ind);
            Data4Table{SelROI,3} = app.SPI_ROIs_Data(SelROI).NbIdx;
            app.SPI_Table_ROI.Data = Data4Table;
            
            % Check that we are on the phase?
            Type = app.SPI_ROIs.ROI(SelROI).ROI.Type;
            
            switch Type
                case 'images.roi.circle'
                    app.SPI_ROIs_Data(SelROI).Type = 'Circle';
                    
                    app.SPI_ROIs.ROI_Data(SelROI).Center = app.SPI_ROIs.ROI(SelROI).ROI.Center;
                    app.SPI_ROIs.ROI_Data(SelROI).Radius = app.SPI_ROIs.ROI(SelROI).ROI.Radius;
                    
                case 'images.roi.rectangle'
                    app.SPI_ROIs_Data(SelROI).Type = 'Rectangle';
                    
                    app.SPI_ROIs.ROI_Data(SelROI).Position = app.SPI_ROIs.ROI(SelROI).ROI.Position;
                    
                case 'images.roi.polygon'
                    app.SPI_ROIs_Data(SelROI).Type = 'Polygon';
                    
                    app.SPI_ROIs.ROI_Data(SelROI).Position = app.SPI_ROIs.ROI(SelROI).ROI.Position;
            end
            
            [X_grid,Y_grid] = meshgrid([1:size(Mask,2)],[1:size(Mask,1)]);
            
            % figure, imagesc(Mask), hold on, plot(X_grid(Ind),Y_grid(Ind),'or'), axis image
            
            app.SPI_ROIs_Data(SelROI).PxIdx = Ind;
            app.SPI_ROIs_Data(SelROI).PxCoordXY = [X_grid(Ind),Y_grid(Ind)];
            app.SPI_ROIs_Data(SelROI).NbIdx = length(Ind);
            app.SPI_ROIs_Data(SelROI).Mask = Mask;
                
            %end
            
            CalculateCalibrationROIs(app); 
            
            SPI_Table_ROICellSelection(app);
            
            % SPI_UpdateTableROI(app)
        end
        
        function SPI_UpdateTableROI(app)
            
            Data4Table = {};
            
            for i = 1:length(app.SPI_ROIs.ROI)
                Data4Table{i,1} = ['ROI_',num2str(i)];
                Data4Table{i,2} = app.SPI_ROIs_Data(i).Type;
                Data4Table{i,3} = app.SPI_ROIs_Data(i).NbIdx;
            end
            
            app.SPI_Table_ROI.Data = Data4Table;
            app.SPI_Table_ROI.Selection = i;
            
%             CalculateCalibrationROIs(app);
%             
%             app.UpdatePlotROI = 0;
%             SPI_Table_ROICellSelection(app);
%             app.UpdatePlotROI = 1;
        end
        
        function CalculateCalibrationROIs(app)
            
            for iROI = 1:length(app.SPI_ROIs_Data)
                for iStd = 1:length(app.MapStandards)
                    
                    PxIdxROI_XMap = app.SPI_ROIs_Data(iROI).PxIdx;                       % This doesn't work for PxDataRaw, but I don't understand why!
                    
                    PxCoordXY = app.SPI_ROIs_Data(iROI).PxCoordXY;
                    PxCoordXY_data = app.PxDataRaw.PixelCoordXY;
                    
                    PxIdxROI = find(ismember(PxCoordXY_data,PxCoordXY,'rows'));     % This works 
                    
                    % figure, imagesc(app.SPI_ROIs_Data(iROI).Mask), hold on, plot(app.SPI_ROIs_Data(iROI).PxCoordXY(:,1),app.SPI_ROIs_Data(iROI).PxCoordXY(:,2),'or'), axis image

                    % Calculate It_Unk for the ROI
                    DataSweeps = zeros(1,length(app.PxDataRaw.ElNames)); 
                    Pos = 1;
                    
                    for iPx = 1:length(PxIdxROI)
                        if iPx > 1
                            Pos = size(DataSweeps,1)+1;
                        end
                        for iElem = 1:length(app.PxDataRaw.ElNames)
                            IntData = app.PxDataRaw.ElData(iElem).PxData(PxIdxROI(iPx)).Intensity;
                            DataSweeps(Pos:Pos+length(IntData)-1,iElem) = IntData;
                        end
                    end
                    
                    IdxNaN = find(isnan(DataSweeps));
                    DataSweeps(IdxNaN) = 0;
                    
                    MeanDataSweeps = mean(DataSweeps,1); % 
                    
                    for i = 1:size(DataSweeps,2)
                        IdxPos = find(DataSweeps(:,i) > 0);
                        MeanDataSweeps_ExclZeros(i) = mean(DataSweeps(IdxPos,i));
                    end
                    
                    IntStd_Id = app.InternalElementStdDropDown.Value;
                    
                    It_Std = mean(app.MapStandards(iStd).Data(IntStd_Id).Cps(PxIdxROI_XMap));
                    Con_Std = mean(app.MapStandards(iStd).Data(IntStd_Id).Conc(PxIdxROI_XMap));
                    
                    It_Unk = MeanDataSweeps(IntStd_Id);
                    Con_Unk = app.FixedInternalStdConc_EditField.Value;
                    
                    k_mtx(iStd) = (It_Unk.*Con_Std)./(Con_Unk.*It_Std);
                    
                    
                end
                
                Conc = zeros(length(app.PxDataRaw.ElNames),1);
                ConcMeanPixel = zeros(length(app.PxDataRaw.ElNames),1);
                LOD_Px = zeros(length(app.PxDataRaw.ElNames),1);
                ConcStdPixel = zeros(length(app.PxDataRaw.ElNames),1);
                LOD_ROI = zeros(length(app.PxDataRaw.ElNames),1);
                
                for i = 1:length(app.PxDataRaw.ElNames)
                    
                    SelStd = app.CalibrationOptions.SelStd(i);
                    
                    It_Unk = MeanDataSweeps(i);
                    It_Std = mean(app.MapStandards(SelStd).Data(i).Cps(PxIdxROI_XMap));
                    Con_Std = mean(app.MapStandards(SelStd).Data(i).Conc(PxIdxROI_XMap));
                    
                    Conc(i) = (It_Unk.*Con_Std)./(k_mtx(SelStd).*It_Std);
                    
                    CalibratedPx = app.MapCpsData(i).Conc(find(app.SPI_ROIs_Data(iROI).Mask));
                    ConcMeanPixel(i) = median(CalibratedPx);
                    ConcStdPixel(i) = mad(CalibratedPx,1);
                    
                    PxLOD = app.MapCpsData(i).LOD(find(app.SPI_ROIs_Data(iROI).Mask));
                    LOD_Px(i) = median(PxLOD);
                    
                    
                    % Limit of detection (Theoule-sur-Mer)
                    Si = k_mtx(iStd).*It_Std./Con_Std;                                                   % cps/µg.g-1        probably ok
                    Bi = mean(app.MapStandards(iStd).Data(i).Int_Back(PxIdxROI_XMap));                   % cps               mean of background int (sum would be too much)
                    DTi = app.CalibrationOptions.SweepTime(i)/1e3 * length(size(DataSweeps,1));          % seconds           probably ok (* nb_sweeps)
                    NbAnal = sum(app.MapStandards(SelStd).Data(i).Sweeps_Pixel(PxIdxROI_XMap));          % dimensionless     nb_sweeps
                    NbBack = sum(app.MapStandards(SelStd).Data(i).Sweeps_Back(PxIdxROI_XMap));           % dimensionless     wrong, too high!
                    
                    if Bi > 0
                        LOD_ROI(i) = (3.29.*sqrt(Bi.*DTi.*NbAnal.*(1+NbAnal./NbBack)+2.71))./(NbAnal.*DTi.*Si);    % µg.g-1
                    end
                    % including filter LOD for values with zero intensities for background (Cavalaire 2023)
                    
                end 
                
                app.SPI_ROIs_Data(iROI).Conc = Conc;
                app.SPI_ROIs_Data(iROI).LOD_ROI = LOD_ROI;
                app.SPI_ROIs_Data(iROI).ConcMeanPixel = ConcMeanPixel;
                app.SPI_ROIs_Data(iROI).ConcStdPixel = ConcStdPixel;
                app.SPI_ROIs_Data(iROI).LOD_Px = LOD_Px;
            end
        end
        
        function Count = CountROI(app)
            
            Count = sum(length(findall(app.SPI_Plot, 'Type',  'images.roi.Circle'))+length(findall(app.SPI_Plot, 'Type',  'images.roi.Rectangle'))+length(findall(app.SPI_Plot, 'Type',  'images.roi.Polygon')));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, XMapToolsApp, SelectedMaskFile)
            %
            % XMapTools is a free software solution for the analysis of chemical maps
            % Copyright © 2022-2025 University of Bern, Institute of Geological Sciences, Pierre Lanari
            %
            % XMapTools is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or any
            % later version.
            %
            % XMapTools is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with XMapTools. If not, see https://www.gnu.org/licenses.
            
            app.XMapToolsCalibrationLaICPMS.Visible = 'off';
            
            movegui(app.XMapToolsCalibrationLaICPMS,'center');
            
            app.SPI_GridLayout.Visible = 'off';
            
            app.XMapToolsApp = XMapToolsApp;
            
            % Take data that were previously loaded (otherwise empty):
            app.PxDataRaw = app.XMapToolsApp.XMapToolsData.PxDataRaw;
            
            app.MaskFile.Name = app.XMapToolsApp.XMapToolsData.MapData.MaskFile.Names{SelectedMaskFile};
            app.MaskFile.NbMasks = app.XMapToolsApp.XMapToolsData.MapData.MaskFile.NbMasks(SelectedMaskFile);
            app.MaskFile.Names = app.XMapToolsApp.XMapToolsData.MapData.MaskFile.Masks(SelectedMaskFile).Names;
            app.MaskFile.MaskMap = app.XMapToolsApp.XMapToolsData.MapData.MaskFile.Masks(SelectedMaskFile).MaskMap;
            
            app.ClassDropDown.Items = app.MaskFile.Names(2:end);
            app.ClassDropDown.ItemsData = 1:app.MaskFile.NbMasks;
            
            for iStd = 1:length(app.XMapToolsApp.XMapToolsData.MapStandards)
                
                % Standard data
                Names = app.XMapToolsApp.XMapToolsData.MapStandards(iStd).Names;
                Types = app.XMapToolsApp.XMapToolsData.MapStandards(iStd).Types;
                
                app.ElNamesStd = Names(find(Types == 1));
                app.NbElStd = length(app.ElNamesStd);
                
                 % Data types:
                %       - Type 1        standard maps (Std_Map_Cps)
                %       - Type 2        composition of the standard (Std_Map_Conc)
                %       - Type 3        intensity of background (Int_Back)
                %       - Type 4        number of sweeps for background measurement (Sweeps_Back)
                %       - Type 5        number of sweeps for pixel measurement (Sweeps_Pixel)
                
                for i = 1:app.NbElStd
                    app.MapStandards(iStd).Data(i).Cps = app.XMapToolsApp.XMapToolsData.MapStandards(iStd).Data(i).Map;
                    app.MapStandards(iStd).Data(i).Int_Back = app.XMapToolsApp.XMapToolsData.MapStandards(iStd).Data(app.NbElStd+i).Map;
                    app.MapStandards(iStd).Data(i).Conc = app.XMapToolsApp.XMapToolsData.MapStandards(iStd).Data(app.NbElStd*2+i).Map;
                    app.MapStandards(iStd).Data(i).Sweeps_Back = app.XMapToolsApp.XMapToolsData.MapStandards(iStd).Data(app.NbElStd*3+i).Map;
                    app.MapStandards(iStd).Data(i).Sweeps_Pixel = app.XMapToolsApp.XMapToolsData.MapStandards(iStd).Data(app.NbElStd*4+i).Map;
                end
                
                StdOptions{iStd} = app.XMapToolsApp.XMapToolsData.MapStandards(iStd).StandardName;
                
            end
            
            app.Standard_DropDown.Items = StdOptions;
            app.Standard_DropDown.ItemsData = [1:length(StdOptions)];
            
            app.InternalElementStdDropDown.Items = app.ElNamesStd;
            app.InternalElementStdDropDown.ItemsData = 1:app.NbElStd;
            
            WhereSi = find(ismember(app.ElNamesStd,'Si'));
            if ~isempty(WhereSi)
                app.InternalElementStdDropDown.Value = WhereSi;
            end
            
            app.ElementDropDown.Items = app.ElNamesStd;
            app.ElementDropDown.ItemsData = 1:app.NbElStd;
            
            % MapData
            ElNamesCps = app.XMapToolsApp.XMapToolsData.MapData.It.Names;
            [ElIsInStd,ElIndex] = ismember(app.ElNamesStd,ElNamesCps);
            
            if ~isequal(sum(ElIsInStd),length(ElIsInStd))
                
                Where0 = find(ElIsInStd == 0);
                Str = 'Following map(s) not available:';
                for i = 1:length(Where0)
                    Str = [Str,' ',char(app.ElNamesStd(Where0(i)))];
                end
                
                errordlg({'Mismatch between intensity maps and maps of standards',Str},'Error')
                close(app.XMapToolsCalibrationLaICPMS)
                return
            end
            
            for i = 1:app.NbElStd
                app.MapCpsData(i).Cps = app.XMapToolsApp.XMapToolsData.MapData.It.Data(ElIndex(i)).Map;
                app.MapCpsData(i).Conc = zeros(size(app.MapCpsData(i).Cps));
                app.MapCpsData(i).LOD = zeros(size(app.MapCpsData(i).Cps));
            end
            
            app.UITable.Data = {0,0,0,0,0,0;0,0,0,0,0,0};
            
            app.ConstantcompositionCheckBox.Value = 1;
            app.UITable.Visible = 'Off'; 
            app.DisplayValue.Visible = 'off';
            app.ButtonTarget.Visible = 'off';
            app.SPI_Export.Enable = 'Off';
            
            app.wtOxideDropDown.Items = app.XMapToolsApp.ElOxDataDef.OxList;
            app.wtOxideDropDown.ItemsData = 1:length(app.wtOxideDropDown.Items);
            CalculateConversion(app);
            
            app.CalibrationOptions.ElNames = app.InternalElementStdDropDown.Items;
            app.CalibrationOptions.StdOptions = StdOptions;
            app.CalibrationOptions.SelStd = ones(size(ElNamesCps));
            app.CalibrationOptions.SweepTime = 10*ones(size(ElNamesCps));
            
            if exist('Import_DT.txt')
                EN = {};
                SN = {};
                DT = [];
                
                fid = fopen('Import_DT.txt','r');
                TheL = fgetl(fid); TheL = fgetl(fid); TheL = fgetl(fid); TheL = fgetl(fid);
                while 1
                    TheL = fgetl(fid);
                    if isequal(TheL,-1)
                        break
                    end
                    TheStr = textscan(TheL,'%s','Delimiter','\t');
                    TheStr = TheStr{1};
                    if isequal(length(TheStr),3)
                        EN{end+1} = TheStr{1};
                        SN{end+1} = TheStr{2};
                        DT(end+1) = str2num(TheStr{3});
                    end
                end
                
                fclose(fid);
                
                      
                for i = 1:length(app.InternalElementStdDropDown.Items)
                    Idx = find(ismember(EN,app.InternalElementStdDropDown.Items{i}));
                    if ~isempty(Idx)
                        WhereStd = find(ismember(StdOptions,SN{i}));
                        if ~isempty(WhereStd)
                            app.CalibrationOptions.SelStd(i) = WhereStd;
                        end
                        app.CalibrationOptions.SweepTime(i) = DT(i);
                    end
                end
            end
            
            app.XMapToolsCalibrationLaICPMS.Visible = 'on';
            
            app.WaitBar = uiprogressdlg(app.XMapToolsCalibrationLaICPMS,'Title','XMapTools','Indeterminate','on');
            app.WaitBar.Message = 'Updating the interface';
            
            UpdateTableElementStandard(app)
             
            PlotAllMaps(app);
            
            app.SPI_Activated = 0;
            app.SPI_ROIs.Nb = 0;
            app.SPI_Table_ROI.SelectionType = 'row';
            
            InternalElementStdDropDownValueChanged(app);
            
            close(app.WaitBar)
        end

        % Value changed function: InternalElementStdDropDown
        function InternalElementStdDropDownValueChanged(app, event)
            app.WaitBar = uiprogressdlg(app.XMapToolsCalibrationLaICPMS,'Title','XMapTools','Indeterminate','on');
            app.WaitBar.Message = 'Updating the interface';
            
            app.ElementDropDown.Value = app.InternalElementStdDropDown.Value;
            
            % Update oxides
            ElList_DB = app.XMapToolsApp.ElOxDataDef.ElList;
            [Ok,ElIdx] = ismember(char(app.ElementDropDown.Items(app.ElementDropDown.Value)),ElList_DB);
            OxId = find(app.XMapToolsApp.ElOxDataDef.OxElIdx == ElIdx);
            if ~isempty(OxId)
                app.wtOxideDropDown.Value = OxId(1);
            end
            
            CalculateConversion(app);
            
            UpdateTableElementStandard(app);
            
            CalibrateMaps(app);
            PlotAllMaps(app);
            
            close(app.WaitBar);
        end

        % Value changed function: ClassDropDown
        function ClassDropDownValueChanged(app, event)
            delete(findall(app.Plot1, 'Type',  'images.roi.Circle'));
            CalibrateMaps(app);
            PlotAllMaps(app);
        end

        % Value changed function: ElementDropDown
        function ElementDropDownValueChanged(app, event)
            delete(findall(app.Plot1, 'Type',  'images.roi.Circle'));
            PlotAllMaps(app);
        end

        % Value changed function: FixedInternalStdConc_EditField
        function FixedInternalStdConc_EditFieldValueChanged(app, event)
            CalibrateMaps(app);
            PlotAllMaps(app);
            if isequal(app.SPI_Activated,0)
                 
                % Module initialization
                app.SPI_MapMenu.Items = app.ElementDropDown.Items;
                app.SPI_MapMenu.ItemsData = app.ElementDropDown.ItemsData;
                
                Plot_SPI(app)
                
                app.SPI_Activated = 1;
                
                if ~isempty(app.PxDataRaw.ElNames)
                    app.SPI_GridLayout.Visible = 'on';
                end
                
            end
        end

        % Value changed function: wtOxideDropDown
        function wtOxideDropDownValueChanged(app, event)
            CalculateConversion(app);
        end

        % Value changed function: Oxide_EditField
        function Oxide_EditFieldValueChanged(app, event)
            CalculateConversion(app);
            if app.ConstantcompositionCheckBox.Value
                app.FixedInternalStdConc_EditField.Value = app.ppm_EditField.Value;
                FixedInternalStdConc_EditFieldValueChanged(app, event)
            end
        end

        % Button pushed function: AppyCalibration
        function AppyCalibrationButtonPushed(app, event)
            
            Pos = length(app.XMapToolsApp.XMapToolsData.MapData.Qt.Names) + 1;
            
            % ------------------------------------------------------------
            % Save maps filtered for LOD: 
            app.XMapToolsApp.XMapToolsData.MapData.Qt.Names{Pos} = [char(app.ClassDropDown.Items{app.ClassDropDown.Value}),' µg/g'];
            app.XMapToolsApp.XMapToolsData.MapData.Qt.IsOxide(Pos) = 0;
            app.XMapToolsApp.XMapToolsData.MapData.Qt.MaskFile{Pos} = app.MaskFile.Name;
            app.XMapToolsApp.XMapToolsData.MapData.Qt.NbCalibPoints(Pos) = 0;
            
            p = uitreenode(app.XMapToolsApp.Node_Qt,'Text',char(app.XMapToolsApp.XMapToolsData.MapData.Qt.Names{Pos}),'NodeData',[2,Pos,0]);
            
            for i = 1:length(app.ElNamesStd)
                app.XMapToolsApp.XMapToolsData.MapData.Qt.Data(Pos).ElNames{i} = app.ElNamesStd{i};
                app.XMapToolsApp.XMapToolsData.MapData.Qt.Data(Pos).ElInd(i) = find(ismember(app.XMapToolsApp.ElOxDataDef.ElList,app.ElNamesStd{i}));
                
                MAP = app.MapCpsData(i).Conc;
                LOD = app.MapCpsData(i).LOD;
                
                IdxBelowLOD = find(MAP(:) <= LOD(:));
                
                MAP(IdxBelowLOD) = 0;
                
                app.XMapToolsApp.XMapToolsData.MapData.Qt.Data(Pos).CData(i).Map = MAP;
                
                p1 = uitreenode(p,'Text',char(app.XMapToolsApp.XMapToolsData.MapData.Qt.Data(Pos).ElNames{i}),'NodeData',[2,Pos,i]);
            end
            
            % ------------------------------------------------------------
            % Save maps not filtered for LOD: 
            if app.SaveunfilteredmapsCheckBox.Value
                
                Pos = length(app.XMapToolsApp.XMapToolsData.MapData.Qt.Names) + 1;
                
                app.XMapToolsApp.XMapToolsData.MapData.Qt.Names{Pos} = [char(app.ClassDropDown.Items{app.ClassDropDown.Value}),' µg/g (no LOD filter)'];
                app.XMapToolsApp.XMapToolsData.MapData.Qt.IsOxide(Pos) = 0;
                app.XMapToolsApp.XMapToolsData.MapData.Qt.MaskFile{Pos} = app.MaskFile.Name;
                app.XMapToolsApp.XMapToolsData.MapData.Qt.NbCalibPoints(Pos) = 0;
                
                p = uitreenode(app.XMapToolsApp.Node_Qt,'Text',char(app.XMapToolsApp.XMapToolsData.MapData.Qt.Names{Pos}),'NodeData',[2,Pos,0]);
                
                for i = 1:length(app.ElNamesStd)
                    app.XMapToolsApp.XMapToolsData.MapData.Qt.Data(Pos).ElNames{i} = app.ElNamesStd{i};
                    app.XMapToolsApp.XMapToolsData.MapData.Qt.Data(Pos).ElInd(i) = find(ismember(app.XMapToolsApp.ElOxDataDef.ElList,app.ElNamesStd{i}));
                    app.XMapToolsApp.XMapToolsData.MapData.Qt.Data(Pos).CData(i).Map = app.MapCpsData(i).Conc;
                    
                    p1 = uitreenode(p,'Text',char(app.XMapToolsApp.XMapToolsData.MapData.Qt.Data(Pos).ElNames{i}),'NodeData',[2,Pos,i]);
                end
            end
            
            % ------------------------------------------------------------
            % Limit of detection (version 4.2)
            Pos = length(app.XMapToolsApp.XMapToolsData.MapLOD.Names);
            
            app.XMapToolsApp.XMapToolsData.MapLOD.Names{Pos+1} = [char(app.ClassDropDown.Items{app.ClassDropDown.Value}),' µg/g'];
            app.XMapToolsApp.XMapToolsData.MapLOD.Types(Pos+1) = 1;     % not used for now
            
            p = uitreenode(app.XMapToolsApp.Node_LOD,'Text',char(app.XMapToolsApp.XMapToolsData.MapLOD.Names{Pos+1}),'NodeData',[16,Pos+1,0,0]);
            
            for i = 1:length(app.ElNamesStd)
                app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElNames{i} = app.ElNamesStd{i};
                
                p = uitreenode(app.XMapToolsApp.Node_LOD.Children(Pos+1),'Text',char(app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElNames{i}),'NodeData',[16,Pos+1,i,0]);
                
                app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Label_LOD = ['LOD ',app.ElNamesStd{i},' µg/g'];
                app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Map_LOD = app.MapCpsData(i).LOD;
                
                p = uitreenode(app.XMapToolsApp.Node_LOD.Children(Pos+1).Children(i),'Text',char(app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Label_LOD),'NodeData',[16,Pos+1,i,1]);
                
                if length(app.CalibrationOptions.StdOptions) > 1
                    IdStd = app.CalibrationOptions.SelStd(i);
                    It_Std_mtx = app.MapStandards(IdStd).Data(i).Cps;
                    Con_Std_mxt = app.MapStandards(IdStd).Data(i).Conc;
                    
                    app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Label_Si_mtx = ['Si_mtx (',app.CalibrationOptions.StdOptions{IdStd},')'];
                    app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Map_Si_mtx = app.Map_k(:,:,IdStd).*It_Std_mtx./Con_Std_mxt; % cps/µg.g-1
                    
                    app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Label_Back_mtx = ['Back_mtx'];
                    app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Map_Back_mtx = app.MapStandards(IdStd).Data(i).Int_Back; % cps
                    
                    app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).NbAnal = app.MapStandards(IdStd).Data(i).Sweeps_Pixel; % dimensionless
                    app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).NbBack = app.MapStandards(IdStd).Data(i).Sweeps_Back; % dimensionless
                else
                    It_Std_mtx = app.MapStandards(1).Data(i).Cps;
                    Con_Std_mxt = app.MapStandards(1).Data(i).Conc;
                    
                    app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Label_Si_mtx = ['Si_mtx (',char(app.CalibrationOptions.StdOptions),')'];
                    app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Map_Si_mtx = app.Map_k.*It_Std_mtx./Con_Std_mxt; % cps/µg.g-1
                    
                    app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Label_Back_mtx = ['Back_mtx'];
                    app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Map_Back_mtx = app.MapStandards(1).Data(i).Int_Back;  % cps
                    
                    app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).NbAnal = app.MapStandards(1).Data(i).Sweeps_Pixel; % dimensionless
                    app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).NbBack = app.MapStandards(1).Data(i).Sweeps_Back; % dimensionless
                end
                
                p = uitreenode(app.XMapToolsApp.Node_LOD.Children(Pos+1).Children(i),'Text',char(app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Label_Si_mtx),'NodeData',[16,Pos+1,i,2]);
                
                p = uitreenode(app.XMapToolsApp.Node_LOD.Children(Pos+1).Children(i),'Text',char(app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).Label_Back_mtx),'NodeData',[16,Pos+1,i,3]);
                
                app.XMapToolsApp.XMapToolsData.MapLOD.Data(Pos+1).ElData(i).DTi = app.CalibrationOptions.SweepTime(i)/1e3; % seconds
                
            end
            
%             % Limit of detection:
%             Pos = length(app.XMapToolsApp.XMapToolsData.MapData.Ot.Names);
%             
%             for i = 1:length(app.ElNamesStd)
%                 app.XMapToolsApp.XMapToolsData.MapData.Ot.Names{Pos+i} = [char(app.ElNamesStd{i}),'_',char(app.ClassDropDown.Items{app.ClassDropDown.Value}),' µg/g'];
%                 app.XMapToolsApp.XMapToolsData.MapData.Ot.Types(Pos+i) = 1;
%                 app.XMapToolsApp.XMapToolsData.MapData.Ot.Data(Pos+i).Map = app.MapCpsData(i).LOD;
%                 
%                 p = uitreenode(app.XMapToolsApp.Node_Ot,'Text',char(app.XMapToolsApp.XMapToolsData.MapData.Ot.Names{Pos+i}),'NodeData',[5,Pos+i]);
%             end
            
            close(app.XMapToolsCalibrationLaICPMS); 
            
        end

        % Value changed function: ConstantcompositionCheckBox
        function ConstantcompositionCheckBoxValueChanged(app, event)
            value = app.ConstantcompositionCheckBox.Value;
            if isequal(value,1) && isequal(app.VariablecompositionCheckBox.Value,1)
                % We go to this mode
                ChangeMode(app,'Fixed');
            else
                ChangeMode(app,'Variable'); 
            end
        end

        % Value changed function: VariablecompositionCheckBox
        function VariablecompositionCheckBoxValueChanged(app, event)
            value = app.VariablecompositionCheckBox.Value;
            if isequal(value,1) && isequal(app.ConstantcompositionCheckBox.Value,1)
                % We go to this mode
                ChangeMode(app,'Variable');
            else
                ChangeMode(app,'Fixed');
            end
        end

        % Button pushed function: ButtonTarget
        function ButtonTargetPushed(app, event)
            
            delete(findall(app.Plot1, 'Type',  'images.roi.Circle'));
            
            DeactivatePlotZoomPanOptions(app);
            
            app.ROI_sampling = drawcircle(app.Plot1,'Color',[0.47,0.67,0.19],'InteractionsAllowed','all');
            
            app.ROI_sampling_Listener = addlistener(app.ROI_sampling, 'ROIMoved', @(varargin)ROI_Value_Extractor(app, app.ROI_sampling));
            app.ROI_sampling_Listener2 = addlistener(app.ROI_sampling, 'MovingROI', @(varargin)DeactivatePlotZoomPanOptions(app));
            
            ROI_Value_Extractor(app, app.ROI_sampling);
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            NewDisplayData = app.UITable.DisplayData;
            
            app.DataInter = cell2mat(NewDisplayData);
            
            app.NbPoints = 0;
            app.PointsValid = zeros(1,size(app.DataInter,2));
            for i = 1:size(app.DataInter,2)
                if app.DataInter(1,i) > 0 && app.DataInter(2,i) > 0
                    app.NbPoints = app.NbPoints+1;
                    app.PointsValid(i) = 1;
                end
            end
            
            if sum(app.PointsValid) >= 2
                CalibrateMaps(app);
                PlotAllMaps(app);
            end
            
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            if isequal(indices(1),1)
                app.UITable.Data(indices(1),indices(2)) = {app.DisplayValue.Value};
            end
            
            if isequal(indices(1),2)
                app.UITable.Data(indices(1),indices(2)) = {app.ppm_EditField.Value};
            end
            
            UITableDisplayDataChanged(app, event)
        end

        % Button pushed function: Button_Help
        function Button_HelpPushed(app, event)
            
            
            if isempty(app.XMapToolsApp.Id_HelpTool)
                Help_Display(app.XMapToolsApp,'XMT_help_Calibration_LAICPMS.html');
            else
                app.XMapToolsApp.Id_HelpTool.UpdateTextHelp('XMT_help_Calibration_LAICPMS.html');
            end


        end

        % Cell edit callback: UITable_ElemList
        function UITable_ElemListCellEdit(app, event)
            
            Column = event.Indices(2);
            Row = event.Indices(1);
            NewData = event.NewData; 
            
            switch Column
                case 4
                    app.CalibrationOptions.SweepTime(Row) = NewData;
                case 2
                    Value = find(ismember(app.CalibrationOptions.StdOptions,event.NewData));
                    app.CalibrationOptions.SelStd(Row) = Value;
            end
            
            UpdateTableElementStandard(app);
            Overwrite_Import_DT(app);
        end

        % Button pushed function: StdApplytoallButton
        function StdApplytoallButtonPushed(app, event)
            
            app.CalibrationOptions.SelStd(:) = app.Standard_DropDown.Value;
            
            UpdateTableElementStandard(app);
            Overwrite_Import_DT(app);
            
        end

        % Button pushed function: StdApplytoSelectionButton
        function StdApplytoSelectionButtonPushed(app, event)
            
            Selection = app.SelectedCell(:,1);
            
            app.CalibrationOptions.SelStd(Selection) = app.Standard_DropDown.Value;
            
            UpdateTableElementStandard(app);
            Overwrite_Import_DT(app);
            
        end

        % Cell selection callback: UITable_ElemList
        function UITable_ElemListCellSelection(app, event)
            app.SelectedCell = event.Indices;
        end

        % Value changed function: SPI_MapMenu
        function SPI_MapMenuValueChanged(app, event)
            Plot_SPI(app);
        end

        % Value changed function: SPI_LogScale
        function SPI_LogScaleValueChanged(app, event)
            CheckLogScale(app)
        end

        % Value changed function: SPI_Limits_Min
        function SPI_Limits_MinValueChanged(app, event)
            CheckLimits_SPI(app)
        end

        % Value changed function: SPI_Limits_Max
        function SPI_Limits_MaxValueChanged(app, event)
            CheckLimits_SPI(app)
        end

        % Button pushed function: SPI_AddROI
        function SPI_AddROIButtonPushed(app, event)
            
            eliminate_ROI(app);
            
            DeactivatePlotZoomPanOptions(app);
            
            PosROI = app.SPI_ROIs.Nb + 1;
            
            switch app.SPI_MenuROI.Value
                
                case 'Circle'
                    app.SPI_ROIs.ROI(PosROI).ROI = drawcircle(app.SPI_Plot,'Color',[0.47,0.67,0.19],'InteractionsAllowed','all');
                    
                    app.SPI_ROIs.ROI_Data(PosROI).Type = 'Circle';
                    app.SPI_ROIs.ROI_Data(PosROI).Center = app.SPI_ROIs.ROI(PosROI).ROI.Center;
                    app.SPI_ROIs.ROI_Data(PosROI).Radius = app.SPI_ROIs.ROI(PosROI).ROI.Radius;
                    
                case 'Rectangle'
                    app.SPI_ROIs.ROI(PosROI).ROI = drawrectangle(app.SPI_Plot,'Color',[0.47,0.67,0.19],'InteractionsAllowed','all');
                    
                    app.SPI_ROIs.ROI_Data(PosROI).Type = 'Rectangle';
                    app.SPI_ROIs.ROI_Data(PosROI).Position = app.SPI_ROIs.ROI(PosROI).ROI.Position;
                    
                case 'Polygon'
                    app.SPI_ROIs.ROI(PosROI).ROI = drawpolygon(app.SPI_Plot,'Color',[0.47,0.67,0.19],'InteractionsAllowed','all');
                    
                    app.SPI_ROIs.ROI_Data(PosROI).Type = 'Polygon';
                    app.SPI_ROIs.ROI_Data(PosROI).Position = app.SPI_ROIs.ROI(PosROI).ROI.Position; 
            end
            
            app.SPI_ROIs_Listener = addlistener(app.SPI_ROIs.ROI(PosROI).ROI, 'ROIMoved', @(varargin)SPI_ROIs_DataExtraction(app, app.SPI_ROIs));
            
            app.SPI_ROIs.Nb = PosROI; 
            
            SPI_ROIs_DataExtraction_FIRST(app, PosROI);
            
            SPI_UpdateTableROI(app);
            
            SPI_ROIs_DataExtraction(app, app.SPI_ROIs); 
            
        end

        % Button pushed function: SPI_DeleteROI
        function SPI_DeleteROIButtonPushed(app, event)
            
            SelROI = app.SPI_Table_ROI.Selection;
            
            if ~isempty(SelROI)
                eliminate_ROI(app);
                
                app.SPI_ROIs.Nb = app.SPI_ROIs.Nb - 1;
                app.SPI_ROIs.ROI(SelROI) = [];
                app.SPI_ROIs.ROI_Data(SelROI) = [];
                
                SPI_UpdateTableROI(app);
                
                SPI_Table_ROICellSelection(app);
            end
            
        end

        % Button pushed function: SPI_Export
        function SPI_ExportButtonPushed(app, event)
            
        end

        % Cell selection callback: SPI_Table_ROI
        function SPI_Table_ROICellSelection(app, event)
            
            SelROI = app.SPI_Table_ROI.Selection;
            
            %if app.UpdatePlotROI
                
                eliminate_ROI(app);
                
                switch app.SPI_ROIs.ROI_Data(SelROI).Type
                    
                    case 'Circle'
                        app.SPI_ROIs.ROI(SelROI).ROI = drawcircle(app.SPI_Plot,'Color',[0.47,0.67,0.19],'InteractionsAllowed','all','Center',app.SPI_ROIs.ROI_Data(SelROI).Center,'Radius',app.SPI_ROIs.ROI_Data(SelROI).Radius);
                    
                    case 'Rectangle'
                        app.SPI_ROIs.ROI(SelROI).ROI = drawrectangle(app.SPI_Plot,'Color',[0.47,0.67,0.19],'InteractionsAllowed','all','Position',app.SPI_ROIs.ROI_Data(SelROI).Position);

                    case 'Polygon'
                        app.SPI_ROIs.ROI(SelROI).ROI = drawpolygon(app.SPI_Plot,'Color',[0.47,0.67,0.19],'InteractionsAllowed','all','Position',app.SPI_ROIs.ROI_Data(SelROI).Position);
                end
                
                app.SPI_ROIs_Listener = addlistener(app.SPI_ROIs.ROI(SelROI).ROI, 'ROIMoved', @(varargin)SPI_ROIs_DataExtraction(app, app.SPI_ROIs));
            %end
            
            ElNames = app.PxDataRaw.ElNames;
            
            Data4Table = {};
            BackgroundTable = ones(length(ElNames),3);
            
            for i = 1:length(ElNames)
                Data4Table{i,1} = ElNames{i};
                Data4Table{i,2} = app.SPI_ROIs_Data(SelROI).Conc(i);
                Data4Table{i,3} = app.SPI_ROIs_Data(SelROI).LOD_ROI(i);
                
                Data4Table{i,4} = app.SPI_ROIs_Data(SelROI).ConcMeanPixel(i);
                Data4Table{i,5} = app.SPI_ROIs_Data(SelROI).ConcStdPixel(i);
                
                Data4Table{i,6} = app.SPI_ROIs_Data(SelROI).LOD_Px(i);
                
                if Data4Table{i,4} < Data4Table{i,6} && Data4Table{i,2} < Data4Table{i,3}
                    BackgroundTable(i,:) = [1,0.76,1];
                end
                
                if Data4Table{i,4} < Data4Table{i,6} && Data4Table{i,2} > Data4Table{i,3}
                    BackgroundTable(i,:) = [0.5608,0.7843,0.5373];
                end
                
            end
            
            app.SPI_Table_Analysis.ColumnName = {'Element','Weight(ROI)','LOD(ROI)','Weight(Px)_med','Weight(Px)_mad','LOD(Px)_med'};
            
            app.SPI_Table_Analysis.Data = Data4Table;
            
            app.SPI_Table_Analysis.BackgroundColor = BackgroundTable;


            % keyboard
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            selectedTab = app.TabGroup.SelectedTab;
            
            
            if isempty(app.PxDataRaw.ElNames)
            
                switch selectedTab.Title
                    
                    case 'Super Precision Interrogator'
                        
                        Result = uiconfirm(app.XMapToolsCalibrationLaICPMS, 'Missing sweep data. Do you want to load a file?', 'XMapTools', 'Options', {'Yes','No','Cancel'});
                        
                        switch Result
                            case 'Yes'
                                
                                app.WaitBar = uiprogressdlg(app.XMapToolsCalibrationLaICPMS,'Title','XMapTools','Indeterminate','on');
                                app.WaitBar.Message = 'Pick a sweep data file (SweepData_Import.mat)';
                                
                                app.PxDataRaw = [];
                                
                                f=figure('Position',[1,1,5,5],'Unit','Pixel'); drawnow; f.Visible = 'off';
                                [FileName,PathName] = uigetfile({'*.mat','Sweep data (*.mat)';'*.*',  'All Files (*.*)'},'Pick a file');
                                close(f);
                                figure(app.XMapToolsCalibrationLaICPMS)
                                if isempty(FileName)
                                    close(app.WaitBar);
                                    return
                                end
                                
                                app.WaitBar.Message = 'Loading the sweep data';
                                
                                load([PathName,FileName]);
                                
                                app.PxDataRaw = SweepData_Import.PxDataRaw;
                                
                                % Save back the data to XMapTools
                                app.XMapToolsApp.XMapToolsData.PxDataRaw = app.PxDataRaw;
                                
                                app.WaitBar.Message = 'Activating the Super Precision Interogator Module';
                                
                                app.SPI_GridLayout.Visible = 'on';
                                
                                close(app.WaitBar);
                                
                                
                            case 'No'
                                
                            case 'Cancel'
                                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                        end
                    
                end
            
            
            end
           
        end

        % Menu selected function: CopyMenu
        function CopyMenuSelected(app, event)


            d = [app.SPI_Table_Analysis.Data];
            
            str = 'Element';
            str = sprintf('%s\t%s',str,'LOD(ROI)');
            str = sprintf('%s\t%s',str,'Weight(ROI)');
            str = sprintf('%s\t%s',str,'Weight(Px_med)');
            str = sprintf('%s\t%s',str,'Weight(Px_mad)');
            str = sprintf('%s\t%s',str,'LOD(Px)_med');
            
            for i = 1:size(d,1)
                str = sprintf('%s\n%s\t%f\t%f\t%f\t%f\t%f',str,char(d{i,1}),d{i,2},d{i,3},d{i,4},d{i,5},d{i,6});
            end
            
            clipboard ('copy',str);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.XMapToolsCalibrationLaICPMS = uifigure('Visible', 'off');
            app.XMapToolsCalibrationLaICPMS.Name = 'Calibration Assistant For LA-ICP-MS Data – XMapTools';
            app.GridLayout = uigridlayout(app.XMapToolsCalibrationLaICPMS);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo_xmap_final.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Button_Help = uibutton(app.GridLayout2, 'push');
            app.Button_Help.ButtonPushedFcn = createCallbackFcn(app, @Button_HelpPushed, true);
            app.Button_Help.Icon = '061-info.png';
            app.Button_Help.Text = '';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.AppyCalibration = uibutton(app.GridLayout3, 'push');
            app.AppyCalibration.ButtonPushedFcn = createCallbackFcn(app, @AppyCalibrationButtonPushed, true);
            app.AppyCalibration.Icon = '044-repeat.png';
            app.AppyCalibration.Enable = 'off';
            app.AppyCalibration.Text = 'Apply standardization';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CalibrationDefinitionTab = uitab(app.TabGroup);
            app.CalibrationDefinitionTab.Title = 'Calibration Definition';
            app.GridLayout6 = uigridlayout(app.CalibrationDefinitionTab);
            app.GridLayout4 = uigridlayout(app.GridLayout6);
            app.ClassDropDownLabel = uilabel(app.GridLayout4);
            app.ClassDropDownLabel.Text = 'Class';
            app.ClassDropDown = uidropdown(app.GridLayout4);
            app.ClassDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassDropDownValueChanged, true);
            app.InternalElementStdDropDownLabel = uilabel(app.GridLayout4);
            app.InternalElementStdDropDownLabel.Text = 'Internal Element Std';
            app.InternalElementStdDropDown = uidropdown(app.GridLayout4);
            app.InternalElementStdDropDown.ValueChangedFcn = createCallbackFcn(app, @InternalElementStdDropDownValueChanged, true);
            app.ConstantcompositionCheckBox = uicheckbox(app.GridLayout4);
            app.ConstantcompositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConstantcompositionCheckBoxValueChanged, true);
            app.ConstantcompositionCheckBox.Text = 'Constant composition';
            app.VariablecompositionCheckBox = uicheckbox(app.GridLayout4);
            app.VariablecompositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @VariablecompositionCheckBoxValueChanged, true);
            app.VariablecompositionCheckBox.Text = 'Variable composition';
            app.FixedInternalStdConc_EditFieldLabel = uilabel(app.GridLayout4);
            app.FixedInternalStdConc_EditFieldLabel.Text = 'µg/g';
            app.FixedInternalStdConc_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.FixedInternalStdConc_EditField.ValueChangedFcn = createCallbackFcn(app, @FixedInternalStdConc_EditFieldValueChanged, true);
            app.InternalCompositionConverterLabel = uilabel(app.GridLayout4);
            app.InternalCompositionConverterLabel.Text = 'Internal Composition Converter';
            app.wtOxideDropDownLabel = uilabel(app.GridLayout4);
            app.wtOxideDropDownLabel.Text = 'wt% Oxide';
            app.wtOxideDropDown = uidropdown(app.GridLayout4);
            app.wtOxideDropDown.ValueChangedFcn = createCallbackFcn(app, @wtOxideDropDownValueChanged, true);
            app.Oxide_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Oxide_EditField.ValueChangedFcn = createCallbackFcn(app, @Oxide_EditFieldValueChanged, true);
            app.Oxide_EditField.Value = 1;
            app.ppm_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.ppm_EditField.Editable = 'off';
            app.toXXLabel = uilabel(app.GridLayout4);
            app.toXXLabel.Text = 'to XX';
            app.ggLabel = uilabel(app.GridLayout4);
            app.ggLabel.Text = 'µg/g';
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'Pt-1'; 'Pt-2'; 'Pt-3'; 'Pt-4'; 'Pt-5'; 'Pt-6'};
            app.UITable.RowName = {'Intensity (cps)'; 'Composition (µg/g)'; ''};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ElementDropDownLabel = uilabel(app.GridLayout4);
            app.ElementDropDownLabel.Text = 'Element';
            app.ElementDropDown = uidropdown(app.GridLayout4);
            app.ElementDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementDropDownValueChanged, true);
            app.DisplayValue = uieditfield(app.GridLayout4, 'numeric');
            app.DisplayValue.Editable = 'off';
            app.ButtonTarget = uibutton(app.GridLayout4, 'push');
            app.ButtonTarget.ButtonPushedFcn = createCallbackFcn(app, @ButtonTargetPushed, true);
            app.ButtonTarget.Icon = '142-target.png';
            app.ButtonTarget.Tooltip = {'Pick a ROI (circle)'};
            app.ButtonTarget.Text = '';
            app.UITable_ElemList = uitable(app.GridLayout6);
            app.UITable_ElemList.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_ElemList.RowName = {};
            app.UITable_ElemList.CellEditCallback = createCallbackFcn(app, @UITable_ElemListCellEdit, true);
            app.UITable_ElemList.CellSelectionCallback = createCallbackFcn(app, @UITable_ElemListCellSelection, true);
            app.GridLayout5 = uigridlayout(app.GridLayout6);
            app.Standard_DropDown = uidropdown(app.GridLayout5);
            app.StdApplytoallButton = uibutton(app.GridLayout5, 'push');
            app.StdApplytoallButton.ButtonPushedFcn = createCallbackFcn(app, @StdApplytoallButtonPushed, true);
            app.StdApplytoallButton.Text = 'Apply to all';
            app.StdApplytoSelectionButton = uibutton(app.GridLayout5, 'push');
            app.StdApplytoSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @StdApplytoSelectionButtonPushed, true);
            app.StdApplytoSelectionButton.Text = 'Apply to Selection';
            app.SaveunfilteredmapsCheckBox = uicheckbox(app.GridLayout5);
            app.SaveunfilteredmapsCheckBox.Text = 'Save unfiltered maps';
            app.BydefaultLODfilteredmapsaresavedLabel = uilabel(app.GridLayout5);
            app.BydefaultLODfilteredmapsaresavedLabel.Text = {'By default, LOD-filtered'; 'maps are saved.'};
            app.LODfilteringLabel = uilabel(app.GridLayout5);
            app.LODfilteringLabel.Text = 'LOD filtering';
            app.Plot2 = uiaxes(app.GridLayout6);
            title(app.Plot2, 'Title')
            app.Plot2.XTick = [];
            app.Plot2.YTick = [];
            app.Plot3 = uiaxes(app.GridLayout6);
            title(app.Plot3, 'Title')
            app.Plot3.XTick = [];
            app.Plot3.YTick = [];
            app.Plot1 = uiaxes(app.GridLayout6);
            title(app.Plot1, 'Title')
            app.Plot1.XTick = [];
            app.Plot1.YTick = [];
            app.SuperPrecisionInterrogatorTab = uitab(app.TabGroup);
            app.SuperPrecisionInterrogatorTab.Title = 'Super Precision Interrogator';
            app.SPI_GridLayout = uigridlayout(app.SuperPrecisionInterrogatorTab);
            app.SPI_Table_ROI = uitable(app.SPI_GridLayout);
            app.SPI_Table_ROI.ColumnName = {'ROI'; 'Type'; 'Pixels'};
            app.SPI_Table_ROI.RowName = {};
            app.SPI_Table_ROI.CellSelectionCallback = createCallbackFcn(app, @SPI_Table_ROICellSelection, true);
            app.SPI_Table_Analysis = uitable(app.SPI_GridLayout);
            app.SPI_Table_Analysis.ColumnName = {'Element'; 'Weight'; 'LOD(ROI)'; 'LOD(Pixel)'};
            app.SPI_Table_Analysis.RowName = {};
            app.GridLayout8 = uigridlayout(app.SPI_GridLayout);
            app.SPI_AddROI = uibutton(app.GridLayout8, 'push');
            app.SPI_AddROI.ButtonPushedFcn = createCallbackFcn(app, @SPI_AddROIButtonPushed, true);
            app.SPI_AddROI.Icon = '139-placeholder.png';
            app.SPI_AddROI.Text = 'Add ROI';
            app.SPI_DeleteROI = uibutton(app.GridLayout8, 'push');
            app.SPI_DeleteROI.ButtonPushedFcn = createCallbackFcn(app, @SPI_DeleteROIButtonPushed, true);
            app.SPI_DeleteROI.Icon = '058-error.png';
            app.SPI_DeleteROI.Text = 'Delete ROI';
            app.SPI_MenuROI = uidropdown(app.GridLayout8);
            app.SPI_MenuROI.Items = {'Circle', 'Rectangle', 'Polygon'};
            app.SPI_MenuROI.Value = 'Circle';
            app.SPI_Export = uibutton(app.GridLayout8, 'push');
            app.SPI_Export.ButtonPushedFcn = createCallbackFcn(app, @SPI_ExportButtonPushed, true);
            app.SPI_Export.Icon = '096-upload.png';
            app.SPI_Export.Text = 'Export';
            app.GridLayout9 = uigridlayout(app.SPI_GridLayout);
            app.SPI_MapMenu = uidropdown(app.GridLayout9);
            app.SPI_MapMenu.ValueChangedFcn = createCallbackFcn(app, @SPI_MapMenuValueChanged, true);
            app.SPI_Limits_Min = uieditfield(app.GridLayout9, 'numeric');
            app.SPI_Limits_Min.ValueChangedFcn = createCallbackFcn(app, @SPI_Limits_MinValueChanged, true);
            app.SPI_Limits_Max = uieditfield(app.GridLayout9, 'numeric');
            app.SPI_Limits_Max.ValueChangedFcn = createCallbackFcn(app, @SPI_Limits_MaxValueChanged, true);
            app.SPI_LogScale = uicheckbox(app.GridLayout9);
            app.SPI_LogScale.ValueChangedFcn = createCallbackFcn(app, @SPI_LogScaleValueChanged, true);
            app.SPI_LogScale.Text = 'Log color scale';
            app.SPI_LogScale.Value = true;
            app.SPI_Plot = uiaxes(app.SPI_GridLayout);
            title(app.SPI_Plot, 'Title')
            app.SPI_Plot.XTick = [];
            app.SPI_Plot.YTick = [];
            app.ContextMenu = uicontextmenu(app.XMapToolsCalibrationLaICPMS);
            % Assign app.ContextMenu
            app.SPI_Table_Analysis.ContextMenu = app.ContextMenu;
            app.CopyMenu = uimenu(app.ContextMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu.Text = 'Copy';
            % Show the figure after all components are created
            app.XMapToolsCalibrationLaICPMS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calibration_LAICPMS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.XMapToolsCalibrationLaICPMS)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.XMapToolsCalibrationLaICPMS)
        end
    end
end