classdef Classification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Classify             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Image                matlab.ui.control.Image
        GridLayout2          matlab.ui.container.GridLayout
        AppySaveButton       matlab.ui.control.Button
        GridLayout4          matlab.ui.container.GridLayout
        ClassesSpinner       matlab.ui.control.Spinner
        ClassesSpinnerLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
        UIAxes_SumD          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        XMapToolsApp
        
        ResNumAll
        Resolution
        SumDAll
        
    end
    
    methods (Access = private)
        
        function PlotSelectedClassification(app)
            
            Idx = app.ClassesSpinner.Value;
            
            TheMap = reshape(app.ResNumAll(:,Idx),app.Resolution(1,:));
            
            imagesc(app.UIAxes,TheMap)
            axis(app.UIAxes,'image')
            colorbar(app.UIAxes)
            colormap(app.UIAxes,[1,1,1;hsv(Idx)])
            caxis(app.UIAxes,[-0.5,Idx+0.5])
            
            
            app.UIAxes_SumD.Children(1).XData = Idx;
            app.UIAxes_SumD.Children(1).YData = app.SumDAll(Idx);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, XMapToolsApp, ResNumAll, Resolution, SumDAll)
                        %
            % XMapTools is a free software solution for the analysis of chemical maps
            % Copyright © 2022-2025 University of Bern, Institute of Geological Sciences, Pierre Lanari
            %
            % XMapTools is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or any
            % later version.
            %
            % XMapTools is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with XMapTools. If not, see https://www.gnu.org/licenses.
            
            app.Classify.Visible = 'off';
            
            app.XMapToolsApp = XMapToolsApp;
            app.ResNumAll = ResNumAll;
            app.Resolution = Resolution;
            app.SumDAll = SumDAll;
            
            plot(app.UIAxes_SumD,[1:size(ResNumAll,2)],app.SumDAll,'-k','LineWidth',2)
            hold(app.UIAxes_SumD,'on')
            plot(app.UIAxes_SumD,2,app.SumDAll(2),'or','MarkerEdgeColor','r','MarkerFaceColor','w','MarkerSize',8,'LineWidth',3)
            
            app.ClassesSpinner.Value = 2;
            app.ClassesSpinner.Limits = [1,size(ResNumAll,2)];
            
            PlotSelectedClassification(app);
            
            movegui(app.Classify,"center");
            app.Classify.Visible = 'on';
            
        end

        % Callback function
        function NbClassesSliderValueChanged(app, event)
            PlotSelectedClassification(app)
        end

        % Value changed function: ClassesSpinner
        function ClassesSpinnerValueChanged(app, event)
            PlotSelectedClassification(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Classify = uifigure('Visible', 'off');
            app.Classify.Name = 'Classification App';
            app.GridLayout = uigridlayout(app.Classify);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo_xmap_final.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AppySaveButton = uibutton(app.GridLayout2, 'push');
            app.AppySaveButton.Icon = '044-repeat.png';
            app.AppySaveButton.Text = 'Apply & Save Maskfile';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.ClassesSpinner = uispinner(app.GridLayout4);
            app.ClassesSpinner.Limits = [1 10];
            app.ClassesSpinner.ValueChangedFcn = createCallbackFcn(app, @ClassesSpinnerValueChanged, true);
            app.ClassesSpinner.Value = 1;
            app.ClassesSpinnerLabel = uilabel(app.GridLayout4);
            app.ClassesSpinnerLabel.Text = 'Classes';
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_SumD = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_SumD, 'Nb Classes')
            ylabel(app.UIAxes_SumD, 'Residual')
            % Show the figure after all components are created
            app.Classify.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Classification(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Classify)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Classify)
        end
    end
end