classdef Data_Export < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataExport                   matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        DataSourcePanel              matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        Mode_Merged_Button           matlab.ui.control.StateButton
        Mode_Quanti_Button           matlab.ui.control.StateButton
        Mode_Results_Button          matlab.ui.control.StateButton
        ExportMapDataPanel           matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        MeanCheckBox                 matlab.ui.control.CheckBox
        MedianCheckBox               matlab.ui.control.CheckBox
        ModeCheckBox                 matlab.ui.control.CheckBox
        ExportDataLabel_2            matlab.ui.control.Label
        UncertaintiesLabel           matlab.ui.control.Label
        StandarddeviationCheckBox    matlab.ui.control.CheckBox
        BRCCheckBox                  matlab.ui.control.CheckBox
        SizeEditFieldLabel           matlab.ui.control.Label
        SizeEditField                matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel      matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        PlotButton                   matlab.ui.control.Button
        MasksSubmasksLabel           matlab.ui.control.Label
        IncludebothMaskSubMasksCheckBox  matlab.ui.control.CheckBox
        IncludeSubMasksCheckBox      matlab.ui.control.CheckBox
        ExcludezerosCheckBox         matlab.ui.control.CheckBox
        NumberCheckBox               matlab.ui.control.CheckBox
        NumberValue                  matlab.ui.control.NumericEditField
        AllCheckBox                  matlab.ui.control.CheckBox
        OutlierrejectionCheckBox     matlab.ui.control.CheckBox
        MADCheckBox                  matlab.ui.control.CheckBox
        MetadataPanel                matlab.ui.container.Panel
        GridLayout5_2                matlab.ui.container.GridLayout
        SampleNameEditField          matlab.ui.control.EditField
        SampleNameEditFieldLabel     matlab.ui.control.Label
        PressureGPaEditField         matlab.ui.control.NumericEditField
        PressureGPaEditFieldLabel    matlab.ui.control.Label
        TemperatureCEditField        matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel   matlab.ui.control.Label
        PrintAssemblageCheckBox      matlab.ui.control.CheckBox
        Include_SampleCheckBox       matlab.ui.control.CheckBox
        Include_PressureCheckBox     matlab.ui.control.CheckBox
        Include_TemperatureCheckBox  matlab.ui.control.CheckBox
        GenerateSaveButton           matlab.ui.control.Button
        ExportFormatDropDown         matlab.ui.control.DropDown
        ExportFormatDropDownLabel    matlab.ui.control.Label
        ExportSpotDataPanel          matlab.ui.container.Panel
        GridLayout5_3                matlab.ui.container.GridLayout
        SourceDatasetLabel           matlab.ui.control.Label
        DatasetDropDown              matlab.ui.control.DropDown
        DatasetDropDownLabel         matlab.ui.control.Label
        SpotData_MedianCheckBox      matlab.ui.control.CheckBox
        ExportDataLabel              matlab.ui.control.Label
        SpotData_ExportAllDatasetsCheckBox  matlab.ui.control.CheckBox
        SpotData_MADCheckBox         matlab.ui.control.CheckBox
        UncertaintiesLabel_2         matlab.ui.control.Label
        ExportOptionsPanel           matlab.ui.container.Panel
        GridLayout5_4                matlab.ui.container.GridLayout
        SelectionofmaskfileandmergedmapsLabel  matlab.ui.control.Label
        MaskFileDropDownLabel        matlab.ui.control.Label
        MaskFileDropDown             matlab.ui.control.DropDown
        SecondaryDropDown            matlab.ui.control.DropDown
        SecondaryDropDownLabel       matlab.ui.control.Label
        PanelMode                    matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        ExportMapDataCheckBox        matlab.ui.control.CheckBox
        ExportSpotDataCheckBox       matlab.ui.control.CheckBox
        MODELabel                    matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        XMapToolsApp
        
        GeneratedDataCellFormat
        GeneratedLabelCellFormat
        
        BRC
        
        WaitBar
        
        
    end
    
    methods (Access = private)
        
        function update_export_source(app)
            
            % turn off the other buttons when they will be there.
            
            %if isequal(app.Mode_Merged_Button.Value,0)
            %app.Mode_Merged_Button.Value = 1;
            %end
            
            if isequal(app.Mode_Merged_Button.Value,1)
                app.SecondaryDropDown.Items = app.XMapToolsApp.XMapToolsData.MapData.Me.Names;
                app.SecondaryDropDown.ItemsData = [1:length(app.SecondaryDropDown.Items)];
                app.SecondaryDropDownLabel.Text = "Merged";
            end
            
            if isequal(app.Mode_Quanti_Button.Value,1)
                app.SecondaryDropDown.Items = app.XMapToolsApp.XMapToolsData.MapData.Qt.Names;
                app.SecondaryDropDown.ItemsData = [1:length(app.SecondaryDropDown.Items)];
                app.SecondaryDropDownLabel.Text = "Quanti";
            end
            
            if isequal(app.Mode_Results_Button.Value,1)
                app.SecondaryDropDown.Items = app.XMapToolsApp.XMapToolsData.MapData.Re.Names;
                app.SecondaryDropDown.ItemsData = [1:length(app.SecondaryDropDown.Items)];
                app.SecondaryDropDownLabel.Text = "Results";
            end
            
        end
        
        function update_gui_method(app)
            
            switch app.ExportFormatDropDown.Value
                case 'ThermoFit'
                    app.ModeCheckBox.Value = 1;
                    app.MedianCheckBox.Value = 0;
                    app.MeanCheckBox.Value = 0;
                    
                    app.StandarddeviationCheckBox.Value = 1;
                    
                    app.PrintAssemblageCheckBox.Value = 1;
                    app.Include_SampleCheckBox.Value = 1;
                    app.Include_PressureCheckBox.Value = 1;
                    app.Include_TemperatureCheckBox.Value = 1;
                    
                    app.NumberValue.Enable = 'off';
                    app.NumberCheckBox.Enable = 'off';
                    
                case 'MinPlot'
                    app.ModeCheckBox.Value = 0;
                    app.MedianCheckBox.Value = 1;
                    app.MeanCheckBox.Value = 0;
                    
                    app.StandarddeviationCheckBox.Value = 0;
                    
                    app.PrintAssemblageCheckBox.Value = 0;
                    app.Include_SampleCheckBox.Value = 0;
                    app.Include_PressureCheckBox.Value = 0;
                    app.Include_TemperatureCheckBox.Value = 0;
                    
                    app.NumberValue.Enable = 'on';
                    app.NumberCheckBox.Enable = 'on';
            end
        end
        
        function GenerateCellFromSelectedSpotData(app)
            
            if isequal(app.Mode_Merged_Button.Value,1)
                Data_Sel = app.XMapToolsApp.XMapToolsData.MapData.Me;
                ElemList = Data_Sel.Data(app.SecondaryDropDown.Value).ElNames;
            end
            
            if isequal(app.Mode_Quanti_Button.Value,1)
                Data_Sel = app.XMapToolsApp.XMapToolsData.MapData.Qt;
                ElemList = Data_Sel.Data(app.SecondaryDropDown.Value).ElNames;
            end
            
            if isequal(app.Mode_Results_Button.Value,1)
                Data_Sel = app.XMapToolsApp.XMapToolsData.MapData.Re;
                ElemList = Data_Sel.Data(app.SecondaryDropDown.Value).Labels;
            end
            
            MaskFile = app.XMapToolsApp.XMapToolsData.MapData.MaskFile;
            PhaseList = MaskFile.Masks(app.MaskFileDropDown.Value).Names(2:end);
            MaskMap = MaskFile.Masks(app.MaskFileDropDown.Value).MaskMap;
            
            SpotData = app.XMapToolsApp.XMapToolsData.SpotData;
            
            if isequal(app.SpotData_ExportAllDatasetsCheckBox.Value,1)
                SelectedDataset = app.DatasetDropDown.ItemsData;
            else
                SelectedDataset = app.DatasetDropDown.Value;
            end
            
            NbData = 0;
            LabelData = {};
            AccessData = [];
            for i = 1:length(SelectedDataset)
                for j = 1:numel(SpotData.Dataset(SelectedDataset(i)).Names)
                    NbData = NbData + 1;
                    LabelData{NbData} = [SpotData.Names{SelectedDataset(i)},'_',SpotData.Dataset(SelectedDataset(i)).Names{j}];
                    AccessData(NbData,:) = [SelectedDataset(i), j];
                end
            end
            
            NbColumns = 0;
            if app.SpotData_MedianCheckBox.Value
                NbColumns = NbColumns + numel(ElemList);
            end
            
            if app.SpotData_MADCheckBox.Value
                CellData = cell(NbData, 2 * NbColumns + 1);
            else
                CellData = cell(NbData, NbColumns + 1);
            end
            
            for i = 1:NbData
                
                CellData{i,1} = LabelData{i};
                
                for j = 1:length(ElemList)
                    
                    MapPxData = Data_Sel.Data(app.SecondaryDropDown.Value).CData(j).Map;
                    
                    if isequal(SpotData.Dataset(AccessData(i,1)).PxSelection(AccessData(i,2)).Selection,1)
                        XYCoordinates = SpotData.Dataset(AccessData(i,1)).PxSelection(AccessData(i,2)).XYCoord;
                    else
                        XYCoordinates = SpotData.Dataset(AccessData(i,1)).XYCoordinates(AccessData(i,2),:);
                    end
                    
                    % Sample the data:
                    PxData = zeros(size(XYCoordinates,1),1);
                    for k = 1:size(XYCoordinates,1)
                        PxData(k) = MapPxData(XYCoordinates(k,2),XYCoordinates(k,1));
                    end
                    if app.SpotData_MedianCheckBox.Value
                        CellData{i,j+1} = median(PxData);
                    end
                    if app.SpotData_MADCheckBox.Value
                        CellData{i,NbColumns+j+1} = mad(PxData);
                    end
                end
            end
            
            Labels = [{'mineral'},ElemList];
            if app.SpotData_MADCheckBox.Value
                for i=1:numel(ElemList)
                    Labels{end+1} = ['MAD_',ElemList{i}];
                end
            end
            
            % Add columns for other selections
            if app.Include_SampleCheckBox.Value
                SampleNames = cell(size(CellData,1),1);
                [SampleNames{:}] = deal(app.SampleNameEditField.Value);
                CellData = [SampleNames,CellData(:,1:end)];
                Labels = [{'sample'},Labels];
            end
            
            if app.Include_PressureCheckBox.Value
                PressureValues = cell(size(CellData,1),1);
                [PressureValues{:}] = deal(app.PressureGPaEditField.Value);
                CellData = [CellData(:,1:end),PressureValues];
                Labels = [Labels,{'pressure_gpa'}];
            end
            
            if app.Include_TemperatureCheckBox.Value
                TemperatureValues = cell(size(CellData,1),1);
                [TemperatureValues{:}] = deal(app.TemperatureCEditField.Value);
                CellData = [CellData(:,1:end),TemperatureValues];
                Labels = [Labels,{'temperature_degreeC'}];
            end
            
            if app.PrintAssemblageCheckBox.Value
                Assemblage = sprintf('%s+',PhaseList{:});
                AssemblageValues = cell(size(CellData,1),1);
                [AssemblageValues{:}] = deal(Assemblage(1:end-1));
                CellData = [CellData(:,1:end),AssemblageValues];
                Labels = [Labels,{'assemblage'}];
            end
            
            app.GeneratedDataCellFormat = CellData;
            app.GeneratedLabelCellFormat = Labels;
            
            close(app.WaitBar)
            
        end
        
        function [MaskFile,Data_Sel,ElemList,PhaseList,MaskMap,IsSubMask,MaskIdx,SubMaskIdx] = PrepareExtraction(app)
            MaskFile = app.XMapToolsApp.XMapToolsData.MapData.MaskFile;
            
            if isequal(app.Mode_Merged_Button.Value,1)
                Data_Sel = app.XMapToolsApp.XMapToolsData.MapData.Me;
                ElemList = Data_Sel.Data(app.SecondaryDropDown.Value).ElNames;
            end
            
            if isequal(app.Mode_Quanti_Button.Value,1)
                Data_Sel = app.XMapToolsApp.XMapToolsData.MapData.Qt;
                ElemList = Data_Sel.Data(app.SecondaryDropDown.Value).ElNames;
            end
            
            if isequal(app.Mode_Results_Button.Value,1)
                Data_Sel = app.XMapToolsApp.XMapToolsData.MapData.Re;
                ElemList = Data_Sel.Data(app.SecondaryDropDown.Value).Labels;
            end
            
            PhaseList = MaskFile.Masks(app.MaskFileDropDown.Value).Names(2:end);
            
            MaskMap = MaskFile.Masks(app.MaskFileDropDown.Value).MaskMap;
            
            PhaseList = {};
            IsSubMask = [];     % 1 is a submask, 0 is not.
            MaskIdx = [];
            SubMaskIdx = [];
            Compt = 0;
            
            app.WaitBar = uiprogressdlg(app.DataExport,'Title','XMapTools','Value',0);
            app.WaitBar.Message = 'XMapTools is pulling out numbers for you, please wait';
            
            for i = 2:length(MaskFile.Masks(app.MaskFileDropDown.Value).Names)
                if isempty(MaskFile.Masks(app.MaskFileDropDown.Value).SubMask(i).Names)
                    Compt = Compt+1;
                    PhaseList{Compt} = MaskFile.Masks(app.MaskFileDropDown.Value).Names{i};
                    IsSubMask(Compt) = 0;
                    MaskIdx(Compt) = i;
                    SubMaskIdx(Compt) = 0;
                else
                    if isequal(app.IncludeSubMasksCheckBox.Value,1)
                        if isequal(app.IncludebothMaskSubMasksCheckBox.Value,1)
                            % we include both
                            Compt = Compt+1;
                            PhaseList{Compt} = MaskFile.Masks(app.MaskFileDropDown.Value).Names{i};
                            IsSubMask(Compt) = 0;
                            MaskIdx(Compt) = i;
                            SubMaskIdx(Compt) = 0;
                        end
                        for j = 2:length(MaskFile.Masks(app.MaskFileDropDown.Value).SubMask(i).Names)
                            Compt = Compt+1;
                            PhaseList{Compt} = MaskFile.Masks(app.MaskFileDropDown.Value).SubMask(i).Names{j};
                            IsSubMask(Compt) = 1;
                            MaskIdx(Compt) = i;
                            SubMaskIdx(Compt) = j-1;
                        end
                    else
                        Compt = Compt+1;
                        PhaseList{Compt} = MaskFile.Masks(app.MaskFileDropDown.Value).Names{i};
                        IsSubMask(Compt) = 0;
                        MaskIdx(Compt) = i;
                        SubMaskIdx(Compt) = 0;
                    end
                end
            end
        end
        
        
        function GenerateCellFromSelectedDataIncludingSubmasks(app)
            
            [MaskFile,Data_Sel,ElemList,PhaseList,MaskMap,IsSubMask,MaskIdx,SubMaskIdx] = PrepareExtraction(app);
            
            % Export of compositions:
            NbOutput = 0;
            if app.MedianCheckBox.Value
                NbOutput = NbOutput + 1;
                Posmedian = NbOutput;
            end
            if app.MADCheckBox.Value
                NbOutput = NbOutput + 1;
                PosMAD = NbOutput;
            end
            if app.MeanCheckBox.Value
                NbOutput = NbOutput + 1;
                Posmean = NbOutput;
            end
            if app.StandarddeviationCheckBox.Value
                NbOutput = NbOutput + 1;
                PosStd = NbOutput;
            end
            if app.ModeCheckBox.Value
                NbOutput = NbOutput + 1;
                PosModes = NbOutput;
            end
            if app.NumberCheckBox.Value
                NbOutput = NbOutput + app.NumberValue.Value;
                PosNumber = [NbOutput-app.NumberValue.Value+1:NbOutput];
            end
            
            CellData = cell(NbOutput * length(PhaseList),length(ElemList) + 1);
            
            if isequal(app.ExcludezerosCheckBox.Value,1)
                NumberPixels = zeros(size(CellData));
                NumberPixelsExcluded = zeros(size(CellData));
            else
                NumberPixels = zeros(size(CellData));
                NumberPixelsExcluded = 0;
            end
            
            for i = 1:length(PhaseList)
                
                app.WaitBar.Value = i/(length(PhaseList));
                
                PhaseMap = zeros(size(MaskMap));
                
                if IsSubMask(i)
                    SubMaskMap = MaskFile.Masks(app.MaskFileDropDown.Value).SubMask(MaskIdx(i)).MaskSelMaskMap;
                    Idx = find(SubMaskMap == SubMaskIdx(i));
                    PhaseMap(Idx) = 1;
                else
                    Idx = find(MaskMap == MaskIdx(i)-1);
                    PhaseMap(Idx) = 1;
                end
                
                % Apply BRC filter
                PhaseMap = PhaseMap .* app.BRC;
                
                Idx = find(PhaseMap);
                
                count = 0;
                for j = 1:length(ElemList)
                    
                    PxData = Data_Sel.Data(app.SecondaryDropDown.Value).CData(j).Map(Idx);
                    
                    % Zero filter
                    NbPxTemp = 0;
                    NbPxExTemp = 0;
                    if isequal(app.ExcludezerosCheckBox.Value,1)
                        NbPxTemp = length(PxData);
                        IdxSelected = find(PxData <= 1e-12);
                        PxData(IdxSelected) = [];
                        NbPxExTemp = length(IdxSelected);
                    end
                    % Outlier filter
                    if isequal(app.OutlierrejectionCheckBox.Value,1)
                        if isequal(NbPxTemp,0)
                            NbPxTemp = length(PxData);
                        end
                        [xClean, keep] = sigma3iter(app,PxData);
                        PxData = PxData(find(keep));
                        NbPxExTemp = NbPxExTemp + length(find(keep == 0));
                    end
                    
                    if app.ModeCheckBox.Value
                        if isequal(j,1)
                            CellData{NbOutput*(i-1)+PosModes+count,1} = PhaseList{i};
                            CellData{NbOutput*(i-1)+PosModes,2} = {'mode(px)'};
                        end
                        ModeVal = calc_mode(app,PxData);
                        CellData{NbOutput*(i-1)+PosModes,j+2} = ModeVal;
                        
                        if isequal(app.ExcludezerosCheckBox.Value,1)
                            NumberPixels(NbOutput*(i-1)+PosModes,j) = NbPxTemp;
                            NumberPixelsExcluded(NbOutput*(i-1)+PosModes,j) = NbPxExTemp;
                        end
                    end
                    
                    if app.MedianCheckBox.Value
                        if isequal(j,1)
                            CellData{NbOutput*(i-1)+Posmedian,1} = PhaseList{i};
                            CellData{NbOutput*(i-1)+Posmedian,2} = {'median(px)'};
                        end
                        CellData{NbOutput*(i-1)+Posmedian,j+2} = median(PxData);
                        
                        if isequal(app.ExcludezerosCheckBox.Value,1)
                            NumberPixels(NbOutput*(i-1)+Posmedian,j) = NbPxTemp;
                            NumberPixelsExcluded(NbOutput*(i-1)+Posmedian,j) = NbPxExTemp;
                        end
                    end
                    if app.MADCheckBox.Value
                        if isequal(j,1)
                            CellData{NbOutput*(i-1)+PosMAD,1} = PhaseList{i};
                            CellData{NbOutput*(i-1)+PosMAD,2} = {'MAD(px)'};
                        end
                        CellData{NbOutput*(i-1)+PosMAD,j+2} = mad(PxData);
                        
                        if isequal(app.ExcludezerosCheckBox.Value,1)
                            NumberPixels(NbOutput*(i-1)+PosMAD,j) = NbPxTemp;
                            NumberPixelsExcluded(NbOutput*(i-1)+PosMAD,j) = NbPxExTemp;
                        end
                    end
                    
                    if app.MeanCheckBox.Value
                        if isequal(j,1)
                            CellData{NbOutput*(i-1)+Posmean,1} = PhaseList{i};
                            CellData{NbOutput*(i-1)+Posmean,2} = {'mean(px)'};
                        end
                        CellData{NbOutput*(i-1)+Posmean,j+2} = mean(PxData);
                        
                        if isequal(app.ExcludezerosCheckBox.Value,1)
                            NumberPixels(NbOutput*(i-1)+Posmean,j) = NbPxTemp;
                            NumberPixelsExcluded(NbOutput*(i-1)+Posmean,j) = NbPxExTemp;
                        end
                    end
                    if app.StandarddeviationCheckBox.Value
                        if isequal(j,1)
                            CellData{NbOutput*(i-1)+PosStd,1} = PhaseList{i};
                            CellData{NbOutput*(i-1)+PosStd,2} = {'Std(px)'};
                        end
                        CellData{NbOutput*(i-1)+PosStd,j+2} = std(PxData);
                        
                        if isequal(app.ExcludezerosCheckBox.Value,1)
                            NumberPixels(NbOutput*(i-1)+PosStd,j) = NbPxTemp;
                            NumberPixelsExcluded(NbOutput*(i-1)+PosStd,j) = NbPxExTemp;
                        end
                    end
                    if app.NumberCheckBox.Value
                        if isequal(j,1)
                            for n = 1:length(PosNumber)
                                CellData{NbOutput*(i-1)+PosNumber(n),1} = PhaseList{i};
                                CellData{NbOutput*(i-1)+PosNumber(n),2} = {'randsel(px)'};
                            end
                            SelectedPx = randi(length(PxData),1,length(PosNumber));
                        end
                        
                        for n = 1:length(PosNumber)
                            CellData{NbOutput*(i-1)+PosNumber(n),j+2} = PxData(SelectedPx(n));
                        end
                    end
                end
            end
            
            Labels = [{'mineral'},{'analysis_type'},ElemList];
            
            AddExtraColumns(app,CellData,Labels,NumberPixelsExcluded,NumberPixels);
            
        end
        
        
        function GenerateCellFromAllData(app)
            
            [MaskFile,Data_Sel,ElemList,PhaseList,MaskMap,IsSubMask,MaskIdx,SubMaskIdx] = PrepareExtraction(app);
            
            PhaseList = MaskFile.Masks(app.MaskFileDropDown.Value).Names(2:end); % Replace by the phase list without submasks
            
            PxIdx = find(app.BRC); % we only save the unfiltered pixels
            
            X = [1:size(MaskMap,2)];
            Y = [1:size(MaskMap,1)];
            
            [XGrid,YGrid] = meshgrid(X, Y);
            
            NbElem = length(Data_Sel.Data(app.SecondaryDropDown.Value).CData);
            
            Data = zeros(size(PxIdx,1),NbElem);
            
            for i = 1:NbElem
                Data(:,i) = Data_Sel.Data(app.SecondaryDropDown.Value).CData(i).Map(PxIdx);
            end
            
            SumData = sum(Data,2);
            
            ElIdx = find(SumData == 0);
            
            Data(ElIdx,:) = [];
            PxIdx(ElIdx) = [];
            
            NbOutput = length(PxIdx);
            
            CellData = cell(NbOutput,length(ElemList) + 5); % add mineral, submask, analysis_type, X & Y
            
            CountWaitBar = 0;
            
            for i = 1:NbOutput
                
                CountWaitBar = CountWaitBar+1;
                if CountWaitBar > 100
                    app.WaitBar.Value = i/NbOutput;
                    CountWaitBar = 0;
                end
                
                iPx = PxIdx(i);
                MaskID = MaskMap(YGrid(iPx),XGrid(iPx));
                
                CellData{i,1} = PhaseList{MaskID};
                
                if ~isempty(MaskFile.Masks(app.MaskFileDropDown.Value).SubMask(MaskID+1).Names)
                    SubMaskID = MaskFile.Masks(app.MaskFileDropDown.Value).SubMask(MaskID+1).MaskSelMaskMap(YGrid(iPx),XGrid(iPx));
                    CellData{i,2} = MaskFile.Masks(app.MaskFileDropDown.Value).SubMask(MaskID+1).Names{SubMaskID+1};
                else
                    CellData{i,2} = 'none';
                end
                
                CellData{i,3} = 'pixel';
                CellData{i,4} = XGrid(iPx);
                CellData{i,5} = YGrid(iPx);
                
                CellData(i,6:end) = num2cell(Data(i,:));
                
%                 for j = 1:length(ElemList)
%                     CellData{i,j+5} = Data_Sel.Data(app.SecondaryDropDown.Value).CData(j).Map(iPx);
%                 end
                
            end
            
            Labels = [{'mineral'},{'submask'},{'analysis_type'},{'X'},{'Y'},ElemList];
            
            NumberPixelsExcluded = 0;
            NumberPixels = 1;
            
            AddExtraColumns(app,CellData,Labels,NumberPixelsExcluded,NumberPixels);
            
        end
        
        function AddExtraColumns(app,CellData,Labels,NumberPixelsExcluded,NumberPixels)
            
            % Add columns for other selections
            if app.Include_SampleCheckBox.Value
                SampleNames = cell(size(CellData,1),1);
                [SampleNames{:}] = deal(app.SampleNameEditField.Value);
                CellData = [SampleNames,CellData(:,1:end)];
                Labels = [{'sample'},Labels];
            end
            
            if app.Include_PressureCheckBox.Value
                PressureValues = cell(size(CellData,1),1);
                [PressureValues{:}] = deal(app.PressureGPaEditField.Value);
                CellData = [CellData(:,1:end),PressureValues];
                Labels = [Labels,{'pressure_gpa'}];
            end
            
            if app.Include_TemperatureCheckBox.Value
                TemperatureValues = cell(size(CellData,1),1);
                [TemperatureValues{:}] = deal(app.TemperatureCEditField.Value);
                CellData = [CellData(:,1:end),TemperatureValues];
                Labels = [Labels,{'temperature_degreeC'}];
            end
            
            if app.PrintAssemblageCheckBox.Value
                Assemblage = sprintf('%s+',PhaseList{:});
                AssemblageValues = cell(size(CellData,1),1);
                [AssemblageValues{:}] = deal(Assemblage(1:end-1));
                CellData = [CellData(:,1:end),AssemblageValues];
                Labels = [Labels,{'assemblage'}];
            end
            
            if isequal(app.ExcludezerosCheckBox.Value,1)
                TotalPixelsExcluded = sum(NumberPixelsExcluded,2);
                TotalPixels = sum(NumberPixels,2);
                FractionExcluded = zeros(size(TotalPixels));
                for i = 1:length(TotalPixelsExcluded)
                    if TotalPixels(i) > 0
                        FractionExcluded(i) = TotalPixelsExcluded(i)./TotalPixels(i) .* 100;
                    end
                end
                CellData = [CellData(:,1:end),num2cell(FractionExcluded)];
                Labels = [Labels,{'fraction_excluded_px_percent'}];
            end
            
            app.GeneratedDataCellFormat = CellData;
            app.GeneratedLabelCellFormat = Labels;
            
        end
        
        
        function CalculateBRC(app)
            
            TheNbPx = app.SizeEditField.Value;
            TheNbPxOnGarde = app.ThresholdEditField.Value;
            
            MaskFile = app.XMapToolsApp.XMapToolsData.MapData.MaskFile;
            
            MaskMap = MaskFile.Masks(app.MaskFileDropDown.Value).MaskMap;
            MaskNames = MaskFile.Masks(app.MaskFileDropDown.Value).Names;
            
            % Proceed to the correction
            TheLin = size(MaskMap,1);
            TheCol = size(MaskMap,2);
            %CoordMatrice = reshape([1:TheLin*TheCol],TheLin,TheCol);
            
            TheMaskFinal = zeros(size(MaskMap));
            
            Position = round(TheNbPx/2);
            TheNbPxInSel = TheNbPx^2;
            TheCriterion = TheNbPxInSel*TheNbPxOnGarde/100;
            
            for i=1:length(MaskNames) - 1                % for each phase
                
                TheMask = zeros(size(MaskMap));
                VectorOk = find(MaskMap == i);
                
                TheMask(VectorOk) = ones(size(VectorOk));
                
                TheWorkingMat = zeros(size(TheMask,1)*size(TheMask,2),TheNbPxInSel+1);
                
                VectMask = TheMask(:);
                TheWorkingMat(find(VectMask)) = 1000*ones(size(find(VectMask)));
                
                Compt = 1;
                for iLin = 1:TheNbPx
                    
                    
                    for iCol = 1:TheNbPx
                        
                        % SCAN
                        TheTempMat = zeros(size(TheMask));
                        TheTempMat(Position:end-(Position-1),Position:end-(Position-1)) = TheMask(iLin:end-(TheNbPx-iLin),iCol:end-(TheNbPx-iCol));
                        Compt = Compt+1;
                        TheWorkingMat(:,Compt) = TheTempMat(:);
                        
                    end
                end
                
                TheSum = sum(TheWorkingMat,2);
                OnVire1 = find(TheSum < 1000+TheCriterion & TheSum > 1000);
                TheMaskFinal(OnVire1) = ones(size(OnVire1));
                
            end
            
            app.BRC = ones(size(TheMaskFinal));
            app.BRC(find(TheMaskFinal(:) == 1)) = zeros(length(find(TheMaskFinal(:) == 1)),1);
            
        end
        
        function ModeVal = calc_mode(app,PxData)
            
            % This is the XMapTools version (not working for some LAICPMS
            % data?)
            [N,EDGES] = histcounts(PxData);
            if length(EDGES) < 300
                [N,EDGES] = histcounts(PxData,300);
            end
            [Val,PosMax] = max(N);
            dPos = (EDGES(2)-EDGES(1)); %/2;
            ModeVal = EDGES(PosMax)+dPos;
            
            
            return
            % Version with kde, slow and unstable.
            
            [bandwidth,density,xmesh,cdf] = kde(PxData);
            % figure, plot(xmesh,density)
            
            [MaxX,PosMaxX] = max(density);
            ModeVal = xmesh(PosMaxX);
            
            return
            % previous version with mode() below:
            
            ModeVal = mode(PxData);
            
            if abs((ModeVal - mean(PxData))/ModeVal) > 0.10  % 10% deviation to mean = problem in the mode determination?
                %
                %                 [X,edges] = histcounts(PxData);
                %                 [MaxX,PosMaxX] = max(X);
                %                 ModeVal = edges(PosMaxX);
                %
                %                 if length(edges) < 500 && length(PxData) > 10000
                %                     [X,edges] = histcounts(PxData,500);
                %                     [MaxX,PosMaxX] = max(X);
                %                     ModeVal = edges(PosMaxX);
                %                     disp('Mode: ** histogram method (500)')
                %
                %                 elseif length(edges) < 200 && length(PxData) > 1000
                %                     [X,edges] = histcounts(PxData,200);
                %                     [MaxX,PosMaxX] = max(X);
                %                     ModeVal = edges(PosMaxX);
                %                     disp('Mode: ** histogram method (200)')
                %                 else
                %                     [X,edges] = histcounts(PxData,80);
                %                     [MaxX,PosMaxX] = max(X);
                %                     ModeVal = edges(PosMaxX);
                %                     disp('Mode: ** histogram method (80)')
                %                 end
                
                OriginalMode = ModeVal;
                
                [bandwidth,density,xmesh,cdf] = kde(PxData);
                % figure, plot(xmesh,density)
                
                [MaxX,PosMaxX] = max(density);
                ModeVal = xmesh(PosMaxX);
                
                if ModeVal < 0
                    ModeVal = 0;
                end
                
                disp(['Mode was ',num2str(OriginalMode),' changed to ',num2str(ModeVal)])
            end
        end
        
        function [xClean, keep] = sigma3iter(app,x)
            keep = ~isnan(x);
            while true
                mu = mean(x(keep));
                sd = std(x(keep));
                newKeep = keep & abs(x - mu) <= 3*sd;
                if isequal(newKeep, keep), break; end
                keep = newKeep;
            end
            xClean = x(keep);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, XMapToolsApp)
            
            % XMapTools is a free software solution for the analysis of chemical maps
            % Copyright © 2022-2026 University of Lausanne, Institute of Earth Sciences, Pierre Lanari
            
            % XMapTools is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or any
            % later version.
            
            % XMapTools is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
            % GNU General Public License for more details.
            
            % You should have received a copy of the GNU General Public License
            % along with XMapTools. If not, see https://www.gnu.org/licenses.
            
            app.DataExport.Visible = 'off';
            
            movegui(app.DataExport,'center');
            
            app.XMapToolsApp = XMapToolsApp;
            
            app.MaskFileDropDown.Items = app.XMapToolsApp.XMapToolsData.MapData.MaskFile.Names;
            app.MaskFileDropDown.ItemsData = [1:length(app.MaskFileDropDown.Items)];
            
            if numel(app.XMapToolsApp.XMapToolsData.SpotData.Names) >= 1
                app.DatasetDropDown.Items = app.XMapToolsApp.XMapToolsData.SpotData.Names;
                app.DatasetDropDown.ItemsData = [1:numel(app.XMapToolsApp.XMapToolsData.SpotData.Names)];
            else
                app.DatasetDropDown.Items = {'No dataset available'};
                app.DatasetDropDown.Enable = 'off';
                app.SpotData_MedianCheckBox.Enable = 'off';
            end
            
            %app.DatasetDropDown.Items =
            
            app.Mode_Merged_Button.Value = 1;
            update_export_source(app);
            
            update_gui_method(app);
            
            CalculateBRC(app);
            
            ExportMapDataCheckBoxValueChanged(app, 0);
            
            app.DataExport.Visible = 'on';


        end

        % Close request function: DataExport
        function DataExportCloseRequest(app, event)
            
            if ~isempty(app.WaitBar)
                if isvalid(app.WaitBar)
                    Answer = uiconfirm(gcbf,'Do you want to close the Data Export Module?','Confirm','Options',{'Yes','Cancel (unfreeze)'},'DefaultOption',1,'CancelOption',2,'Icon','question');
                    if isequal(Answer,'Cancel (unfreeze)')
                        close(app.WaitBar)
                        return
                    end
                end
            end
            delete(app)
        end

        % Value changed function: MaskFileDropDown
        function MaskFileDropDownValueChanged(app, event)
            CalculateBRC(app)
        end

        % Value changed function: ExportFormatDropDown
        function ExportFormatDropDownValueChanged(app, event)
            update_gui_method(app)
        end

        % Value changed function: Mode_Merged_Button
        function Mode_Merged_ButtonValueChanged(app, event)
            if isequal(app.Mode_Merged_Button.Value,1)
                app.Mode_Quanti_Button.Value = 0;
                app.Mode_Results_Button.Value = 0;
            else
                app.Mode_Quanti_Button.Value = 1;
                app.Mode_Results_Button.Value = 0;
            end
            update_export_source(app);
        end

        % Value changed function: Mode_Quanti_Button
        function Mode_Quanti_ButtonValueChanged(app, event)
            if isequal(app.Mode_Quanti_Button.Value,1)
                app.Mode_Merged_Button.Value = 0;
                app.Mode_Results_Button.Value = 0;
            else
                app.Mode_Merged_Button.Value = 1;
                app.Mode_Results_Button.Value = 0;
            end
            update_export_source(app)
        end

        % Value changed function: Mode_Results_Button
        function Mode_Results_ButtonValueChanged(app, event)
            if isequal(app.Mode_Results_Button.Value,1)
                app.Mode_Merged_Button.Value = 0;
                app.Mode_Quanti_Button.Value = 0;
            else
                app.Mode_Quanti_Button.Value = 1;
                app.Mode_Merged_Button.Value = 0;
            end
            update_export_source(app)
        end

        % Button pushed function: GenerateSaveButton
        function GenerateSaveButtonPushed(app, event)
            
            app.WaitBar = uiprogressdlg(app.DataExport,'Title','XMapTools','Value',0);
            app.WaitBar.Message = 'XMapTools is pulling out numbers for you, please wait';
            
            switch app.ExportFormatDropDown.Value
                case 'ThermoFit'
                    uialert(app.DataExport,'The ThermoFit format is not yet available, stay tuned!','XMapTools');
                    app.ExportFormatDropDown.Value = 'MinPlot';
                    close(app.WaitBar);
                    return
            end
            
            if isempty(app.SecondaryDropDown.Value)
                uialert(app.DataExport,'No data selected in the drop-down menu','XMapTools');
                close(app.WaitBar);
                return
            end
            
            if isequal(app.MedianCheckBox.Value,0) && isequal(app.MeanCheckBox.Value,0) && isequal(app.NumberCheckBox.Value,0) && isequal(app.AllCheckBox.Value,0)
                uialert(app.DataExport,'No data selected in Export Map Data','XMapTools');
                close(app.WaitBar);
                return
            end
            
            if isequal(app.ExportMapDataCheckBox.Value,1)
                if isequal(app.AllCheckBox.Value,1)
                    GenerateCellFromAllData(app);                           % Export all data
                else
                    GenerateCellFromSelectedDataIncludingSubmasks(app);     % Export map data
                end
                         
            else
                GenerateCellFromSelectedSpotData(app);                      % Export spot data
            end
            
            T = cell2table(app.GeneratedDataCellFormat,'VariableNames',app.GeneratedLabelCellFormat);
            
            [Success,Message,MessageID] = mkdir('Exported-MinComp');
            DateStr = char(datestr(now));
            DateStr(find(DateStr == ' ')) = '_'; DateStr(find(DateStr == ':')) = '_';
            ProjectName = ['MinComp_',app.ExportFormatDropDown.Value,'_',DateStr];
            Directory = fullfile(cd,'Exported-MinComp');
            [Success,Message,MessageID] = mkdir(Directory);
            
            
            switch app.ExportFormatDropDown.Value
                
                case 'MinPlot'
                    
                    writetable(T,fullfile(Directory,[ProjectName,'.csv']));
                    
                    close(app.WaitBar);
                    
                    uialert(app.DataExport,'The file has been saved in the folder /Exported-MinComp','XMapTools','Icon','success');
                    
                case 'Thermofit'
                    %fid = fopen(fullfile(Directory,[ProjectName,'.txt']),'w');
                    %fclose(fid);
            end
            
            
        end

        % Value changed function: SizeEditField
        function SizeEditFieldValueChanged(app, event)
            value = app.SizeEditField.Value;
            isodd = rem(value,2) == 1;
            if ~isodd
                app.SizeEditField.Value = 3;
                uialert(app.DataExport,'This parameter must be an odd value (3, 5, 7, etc.)','XMapTools');
            end
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            app.WaitBar = uiprogressdlg(app.DataExport,'Title','XMapTools','Indeterminate','on');
            app.WaitBar.Message = 'Generating plots (can take some time)';
            
            MaskFile = app.XMapToolsApp.XMapToolsData.MapData.MaskFile;
            
            MaskMap = MaskFile.Masks(app.MaskFileDropDown.Value).MaskMap;
            MaskNames = MaskFile.Masks(app.MaskFileDropDown.Value).Names(2:end);
            
            figure
            tiledlayout('flow')
            
            BRC = app.BRC;
            
            for i = 1:length(MaskNames)
                PhaseMap = zeros(size(MaskMap));
                Idx = find(MaskMap == i);
                PhaseMap(Idx) = 1;
                Idx2 = find(MaskMap == i & BRC == 0);
                PhaseMap(Idx2) = 2;
                
                nexttile
                imagesc(PhaseMap), axis image
                title(MaskNames{i})
            end
            
            colormap([1,1,1;0,0,0;1,0,0])
            
            close(app.WaitBar)
            
        end

        % Value changed function: IncludebothMaskSubMasksCheckBox
        function IncludebothMaskSubMasksCheckBoxValueChanged(app, event)
            if isequal(app.IncludebothMaskSubMasksCheckBox.Value,1)
                app.IncludeSubMasksCheckBox.Value = 1;
            end
            if isequal(app.AllCheckBox.Value,1)
                app.IncludebothMaskSubMasksCheckBox.Value = 0;
                app.IncludeSubMasksCheckBox.Value = 0;
            end
        end

        % Value changed function: IncludeSubMasksCheckBox
        function IncludeSubMasksCheckBoxValueChanged(app, event)
            if isequal(app.IncludeSubMasksCheckBox.Value,0)
                app.IncludebothMaskSubMasksCheckBox.Value = 0;
            end
            if isequal(app.AllCheckBox.Value,1)
                app.IncludeSubMasksCheckBox.Value = 0;
            end
        end

        % Value changed function: ExportMapDataCheckBox
        function ExportMapDataCheckBoxValueChanged(app, event)
            if isequal(app.ExportMapDataCheckBox.Value,1)
                app.ExportMapDataPanel.Enable = 'on';
                app.ExportSpotDataPanel.Enable = 'off';
                app.ExportSpotDataCheckBox.Value = 0;
            else
                app.ExportMapDataPanel.Enable = 'off';
                app.ExportSpotDataPanel.Enable = 'on';
                app.ExportSpotDataCheckBox.Value = 1;
            end
        end

        % Value changed function: ExportSpotDataCheckBox
        function ExportSpotDataCheckBoxValueChanged(app, event)
            if isequal(app.ExportSpotDataCheckBox.Value,1)
                app.ExportMapDataPanel.Enable = 'off';
                app.ExportSpotDataPanel.Enable = 'on';
                app.ExportMapDataCheckBox.Value = 0;
            else
                app.ExportMapDataPanel.Enable = 'on';
                app.ExportSpotDataPanel.Enable = 'off';
                app.ExportMapDataCheckBox.Value = 1;
            end
        end

        % Value changed function: SpotData_MedianCheckBox
        function SpotData_MedianCheckBoxValueChanged(app, event)
            app.SpotData_MedianCheckBox.Value = 1; % Force the option as there is no alternative option so far.
        end

        % Value changed function: AllCheckBox
        function AllCheckBoxValueChanged(app, event)
            if isequal(app.AllCheckBox.Value,1)
                app.NumberCheckBox.Value = 0;
                app.MedianCheckBox.Value = 0;
                app.MeanCheckBox.Value = 0;
                app.ModeCheckBox.Value = 0;
                app.OutlierrejectionCheckBox.Value = 0;
                app.StandarddeviationCheckBox.Value = 0;
                app.MADCheckBox.Value = 0;
                app.IncludeSubMasksCheckBox.Value = 1;
                app.IncludebothMaskSubMasksCheckBox.Value = 0;
                app.ExcludezerosCheckBox.Value = 0;
                
                app.NumberCheckBox.Enable = 'off';
                app.MedianCheckBox.Enable = 'off';
                app.MeanCheckBox.Enable = 'off';
                app.ModeCheckBox.Enable = 'off';
                app.OutlierrejectionCheckBox.Enable = 'off';
                app.StandarddeviationCheckBox.Enable = 'off';
                app.MADCheckBox.Enable = 'off';
                app.IncludeSubMasksCheckBox.Enable = 'off';
                app.IncludebothMaskSubMasksCheckBox.Enable = 'off';
                app.ExcludezerosCheckBox.Enable = 'off';
                app.NumberValue.Enable = 'off';
                
            else
                app.NumberCheckBox.Enable = 'on';
                app.MedianCheckBox.Enable = 'on';
                app.MeanCheckBox.Enable = 'on';
                % app.ModeCheckBox.Enable = 'on';
                app.OutlierrejectionCheckBox.Enable = 'on';
                app.StandarddeviationCheckBox.Enable = 'on';
                app.MADCheckBox.Enable = 'on';
                app.IncludeSubMasksCheckBox.Enable = 'on';
                app.IncludebothMaskSubMasksCheckBox.Enable = 'on';
                app.ExcludezerosCheckBox.Enable = 'on';
                app.NumberValue.Enable = 'on';
            end
        end

        % Value changed function: MedianCheckBox
        function MedianCheckBoxValueChanged(app, event)
            if isequal(app.AllCheckBox.Value,1)
                app.MedianCheckBox.Value = 0;
            end
        end

        % Value changed function: MeanCheckBox
        function MeanCheckBoxValueChanged(app, event)
            if isequal(app.AllCheckBox.Value,1)
                app.MeanCheckBox.Value = 0;
            end
        end

        % Value changed function: NumberCheckBox
        function NumberCheckBoxValueChanged(app, event)
            if isequal(app.NumberCheckBox.Value,1)
                app.AllCheckBox.Value = 0;
            end
        end

        % Value changed function: StandarddeviationCheckBox
        function StandarddeviationCheckBoxValueChanged(app, event)
            if isequal(app.AllCheckBox.Value,1)
                app.StandarddeviationCheckBox.Value = 0;
            end
        end

        % Value changed function: MADCheckBox
        function MADCheckBoxValueChanged(app, event)
            if isequal(app.AllCheckBox.Value,1)
                app.MADCheckBox.Value = 0;
            end
        end

        % Value changed function: OutlierrejectionCheckBox
        function OutlierrejectionCheckBoxValueChanged(app, event)
            if isequal(app.AllCheckBox.Value,1) 
                app.OutlierrejectionCheckBox.Value = 0;
            end
        end

        % Value changed function: ExcludezerosCheckBox
        function ExcludezerosCheckBoxValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataExport = uifigure('Visible', 'off');
            app.DataExport.Name = 'XMapTools Export Module';
            app.DataExport.CloseRequestFcn = createCallbackFcn(app, @DataExportCloseRequest, true);
            app.GridLayout = uigridlayout(app.DataExport);
            app.DataSourcePanel = uipanel(app.GridLayout);
            app.DataSourcePanel.Title = 'Data Source';
            app.GridLayout2 = uigridlayout(app.DataSourcePanel);
            app.Mode_Merged_Button = uibutton(app.GridLayout2, 'state');
            app.Mode_Merged_Button.ValueChangedFcn = createCallbackFcn(app, @Mode_Merged_ButtonValueChanged, true);
            app.Mode_Merged_Button.Icon = '236-folder.png';
            app.Mode_Merged_Button.Text = 'Merged';
            app.Mode_Quanti_Button = uibutton(app.GridLayout2, 'state');
            app.Mode_Quanti_Button.ValueChangedFcn = createCallbackFcn(app, @Mode_Quanti_ButtonValueChanged, true);
            app.Mode_Quanti_Button.Icon = '220-layers.png';
            app.Mode_Quanti_Button.Text = 'Quanti';
            app.Mode_Results_Button = uibutton(app.GridLayout2, 'state');
            app.Mode_Results_Button.ValueChangedFcn = createCallbackFcn(app, @Mode_Results_ButtonValueChanged, true);
            app.Mode_Results_Button.Icon = '321-exit.png';
            app.Mode_Results_Button.Text = 'Results';
            app.ExportMapDataPanel = uipanel(app.GridLayout);
            app.ExportMapDataPanel.Title = 'Export Map Data ';
            app.GridLayout5 = uigridlayout(app.ExportMapDataPanel);
            app.MeanCheckBox = uicheckbox(app.GridLayout5);
            app.MeanCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeanCheckBoxValueChanged, true);
            app.MeanCheckBox.Text = 'Mean';
            app.MedianCheckBox = uicheckbox(app.GridLayout5);
            app.MedianCheckBox.ValueChangedFcn = createCallbackFcn(app, @MedianCheckBoxValueChanged, true);
            app.MedianCheckBox.Text = 'Median';
            app.ModeCheckBox = uicheckbox(app.GridLayout5);
            app.ModeCheckBox.Enable = 'off';
            app.ModeCheckBox.Text = 'Mode';
            app.ExportDataLabel_2 = uilabel(app.GridLayout5);
            app.ExportDataLabel_2.Text = 'Export Data:';
            app.UncertaintiesLabel = uilabel(app.GridLayout5);
            app.UncertaintiesLabel.Text = 'Uncertainties:';
            app.StandarddeviationCheckBox = uicheckbox(app.GridLayout5);
            app.StandarddeviationCheckBox.ValueChangedFcn = createCallbackFcn(app, @StandarddeviationCheckBoxValueChanged, true);
            app.StandarddeviationCheckBox.Text = 'Standard deviation';
            app.BRCCheckBox = uicheckbox(app.GridLayout5);
            app.BRCCheckBox.Tooltip = {'Activate border-removing correction (see Lanari et al. 2019)'};
            app.BRCCheckBox.Text = 'BRC';
            app.BRCCheckBox.Value = true;
            app.SizeEditFieldLabel = uilabel(app.GridLayout5);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SizeEditField.Limits = [1 100];
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @SizeEditFieldValueChanged, true);
            app.SizeEditField.Value = 3;
            app.ThresholdEditFieldLabel = uilabel(app.GridLayout5);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ThresholdEditField.Limits = [1 100];
            app.ThresholdEditField.Value = 80;
            app.PlotButton = uibutton(app.GridLayout5, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = '165-layers.png';
            app.PlotButton.Text = 'Plot';
            app.MasksSubmasksLabel = uilabel(app.GridLayout5);
            app.MasksSubmasksLabel.Text = 'Masks & Submasks';
            app.IncludebothMaskSubMasksCheckBox = uicheckbox(app.GridLayout5);
            app.IncludebothMaskSubMasksCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludebothMaskSubMasksCheckBoxValueChanged, true);
            app.IncludebothMaskSubMasksCheckBox.Text = 'Include both';
            app.IncludeSubMasksCheckBox = uicheckbox(app.GridLayout5);
            app.IncludeSubMasksCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeSubMasksCheckBoxValueChanged, true);
            app.IncludeSubMasksCheckBox.Text = 'Include submasks';
            app.IncludeSubMasksCheckBox.Value = true;
            app.ExcludezerosCheckBox = uicheckbox(app.GridLayout5);
            app.ExcludezerosCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludezerosCheckBoxValueChanged, true);
            app.ExcludezerosCheckBox.Text = 'Exclude zeros';
            app.NumberCheckBox = uicheckbox(app.GridLayout5);
            app.NumberCheckBox.ValueChangedFcn = createCallbackFcn(app, @NumberCheckBoxValueChanged, true);
            app.NumberCheckBox.Text = 'Number';
            app.NumberValue = uieditfield(app.GridLayout5, 'numeric');
            app.NumberValue.Limits = [1 1000000];
            app.NumberValue.ValueDisplayFormat = '%.0f';
            app.NumberValue.Value = 20;
            app.AllCheckBox = uicheckbox(app.GridLayout5);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCheckBoxValueChanged, true);
            app.AllCheckBox.Text = 'All';
            app.OutlierrejectionCheckBox = uicheckbox(app.GridLayout5);
            app.OutlierrejectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutlierrejectionCheckBoxValueChanged, true);
            app.OutlierrejectionCheckBox.Tooltip = {'Apply an iterative 3s outlier rejection for each quantity (element in mask).'};
            app.OutlierrejectionCheckBox.Text = 'Outlier rejection';
            app.MADCheckBox = uicheckbox(app.GridLayout5);
            app.MADCheckBox.ValueChangedFcn = createCallbackFcn(app, @MADCheckBoxValueChanged, true);
            app.MADCheckBox.Text = 'MAD';
            app.MetadataPanel = uipanel(app.GridLayout);
            app.MetadataPanel.Title = 'Metadata';
            app.GridLayout5_2 = uigridlayout(app.MetadataPanel);
            app.SampleNameEditField = uieditfield(app.GridLayout5_2, 'text');
            app.SampleNameEditField.Value = 'none';
            app.SampleNameEditFieldLabel = uilabel(app.GridLayout5_2);
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.PressureGPaEditField = uieditfield(app.GridLayout5_2, 'numeric');
            app.PressureGPaEditField.Limits = [0 50];
            app.PressureGPaEditField.Value = 0.5;
            app.PressureGPaEditFieldLabel = uilabel(app.GridLayout5_2);
            app.PressureGPaEditFieldLabel.Text = 'Pressure (GPa)';
            app.TemperatureCEditField = uieditfield(app.GridLayout5_2, 'numeric');
            app.TemperatureCEditField.Limits = [0 2000];
            app.TemperatureCEditField.Value = 600;
            app.TemperatureCEditFieldLabel = uilabel(app.GridLayout5_2);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (°C)';
            app.PrintAssemblageCheckBox = uicheckbox(app.GridLayout5_2);
            app.PrintAssemblageCheckBox.Text = 'Print Assemblage';
            app.Include_SampleCheckBox = uicheckbox(app.GridLayout5_2);
            app.Include_SampleCheckBox.Text = '';
            app.Include_PressureCheckBox = uicheckbox(app.GridLayout5_2);
            app.Include_PressureCheckBox.Text = '';
            app.Include_TemperatureCheckBox = uicheckbox(app.GridLayout5_2);
            app.Include_TemperatureCheckBox.Text = '';
            app.GenerateSaveButton = uibutton(app.GridLayout, 'push');
            app.GenerateSaveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSaveButtonPushed, true);
            app.GenerateSaveButton.Icon = '022-save-as.png';
            app.GenerateSaveButton.Text = 'Generate & Save ';
            app.ExportFormatDropDown = uidropdown(app.GridLayout);
            app.ExportFormatDropDown.Items = {'MinPlot', 'ThermoFit'};
            app.ExportFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @ExportFormatDropDownValueChanged, true);
            app.ExportFormatDropDown.Value = 'MinPlot';
            app.ExportFormatDropDownLabel = uilabel(app.GridLayout);
            app.ExportFormatDropDownLabel.Text = 'Export Format';
            app.ExportSpotDataPanel = uipanel(app.GridLayout);
            app.ExportSpotDataPanel.Title = 'Export Spot Data';
            app.GridLayout5_3 = uigridlayout(app.ExportSpotDataPanel);
            app.SourceDatasetLabel = uilabel(app.GridLayout5_3);
            app.SourceDatasetLabel.Text = 'Source Dataset:';
            app.DatasetDropDown = uidropdown(app.GridLayout5_3);
            app.DatasetDropDown.Items = {'None'};
            app.DatasetDropDown.Value = 'None';
            app.DatasetDropDownLabel = uilabel(app.GridLayout5_3);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.SpotData_MedianCheckBox = uicheckbox(app.GridLayout5_3);
            app.SpotData_MedianCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpotData_MedianCheckBoxValueChanged, true);
            app.SpotData_MedianCheckBox.Text = 'Median';
            app.SpotData_MedianCheckBox.Value = true;
            app.ExportDataLabel = uilabel(app.GridLayout5_3);
            app.ExportDataLabel.Text = 'Export Data:';
            app.SpotData_ExportAllDatasetsCheckBox = uicheckbox(app.GridLayout5_3);
            app.SpotData_ExportAllDatasetsCheckBox.Text = 'Export All';
            app.SpotData_ExportAllDatasetsCheckBox.Value = true;
            app.SpotData_MADCheckBox = uicheckbox(app.GridLayout5_3);
            app.SpotData_MADCheckBox.Text = 'MAD';
            app.SpotData_MADCheckBox.Value = true;
            app.UncertaintiesLabel_2 = uilabel(app.GridLayout5_3);
            app.UncertaintiesLabel_2.Text = 'Uncertainties:';
            app.ExportOptionsPanel = uipanel(app.GridLayout);
            app.ExportOptionsPanel.Title = 'Export Options';
            app.GridLayout5_4 = uigridlayout(app.ExportOptionsPanel);
            app.SelectionofmaskfileandmergedmapsLabel = uilabel(app.GridLayout5_4);
            app.SelectionofmaskfileandmergedmapsLabel.Text = 'Selection of maskfile and merged maps:';
            app.MaskFileDropDownLabel = uilabel(app.GridLayout5_4);
            app.MaskFileDropDownLabel.Text = 'Mask File';
            app.MaskFileDropDown = uidropdown(app.GridLayout5_4);
            app.MaskFileDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskFileDropDownValueChanged, true);
            app.SecondaryDropDown = uidropdown(app.GridLayout5_4);
            app.SecondaryDropDownLabel = uilabel(app.GridLayout5_4);
            app.SecondaryDropDownLabel.Text = 'Merged';
            app.PanelMode = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.PanelMode);
            app.ExportMapDataCheckBox = uicheckbox(app.GridLayout6);
            app.ExportMapDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportMapDataCheckBoxValueChanged, true);
            app.ExportMapDataCheckBox.Text = 'Export Map Data';
            app.ExportMapDataCheckBox.Value = true;
            app.ExportSpotDataCheckBox = uicheckbox(app.GridLayout6);
            app.ExportSpotDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportSpotDataCheckBoxValueChanged, true);
            app.ExportSpotDataCheckBox.Text = 'Export Spot Data';
            app.MODELabel = uilabel(app.GridLayout6);
            app.MODELabel.Text = 'MODE:  ';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo_xmap_final.png';
            % Show the figure after all components are created
            app.DataExport.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Export(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataExport)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataExport)
        end
    end
end