classdef Data_Visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualizationTool               matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        PlotTab                         matlab.ui.container.Tab
        GridLayout7_8                   matlab.ui.container.GridLayout
        VariableDefinitionsLabel        matlab.ui.control.Label
        DropDown_X                      matlab.ui.control.DropDown
        CheckBox_X_Menu                 matlab.ui.control.CheckBox
        CheckBox_X_Manual               matlab.ui.control.CheckBox
        EditField_X                     matlab.ui.control.EditField
        CheckBox_Y_Menu                 matlab.ui.control.CheckBox
        DropDown_Y                      matlab.ui.control.DropDown
        CheckBox_Y_Manual               matlab.ui.control.CheckBox
        EditField_Y                     matlab.ui.control.EditField
        CheckBox_Z_Menu                 matlab.ui.control.CheckBox
        DropDown_Z                      matlab.ui.control.DropDown
        CheckBox_Z_Manual               matlab.ui.control.CheckBox
        EditField_Z                     matlab.ui.control.EditField
        Label_X                         matlab.ui.control.Label
        Label_Y                         matlab.ui.control.Label
        Label_Z                         matlab.ui.control.Label
        Button_Histogram                matlab.ui.control.StateButton
        Button_Binary                   matlab.ui.control.StateButton
        Button_RGB                      matlab.ui.control.StateButton
        Button_Ternary                  matlab.ui.control.StateButton
        PlottingModeLabel               matlab.ui.control.Label
        CheckBox_Masks                  matlab.ui.control.CheckBox
        Label_Maks                      matlab.ui.control.Label
        DropDown_Masks                  matlab.ui.control.DropDown
        Label_Maks_2                    matlab.ui.control.Label
        CheckBox_LowRessourcesMode      matlab.ui.control.CheckBox
        DropDown_Masks_2                matlab.ui.control.DropDown
        CheckBox_FilterZeros            matlab.ui.control.CheckBox
        Label_Maks_3                    matlab.ui.control.Label
        OptionsTab                      matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_Options_Hist                matlab.ui.container.Tab
        GridLayout7_2                   matlab.ui.container.GridLayout
        Tab_Options_Binary              matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        histogramwithlogYscaleCheckBox  matlab.ui.control.CheckBox
        Tab_Options_RGB                 matlab.ui.container.Tab
        GridLayout7_3                   matlab.ui.container.GridLayout
        Tab_Options_Ternary             matlab.ui.container.Tab
        GridLayout7_4                   matlab.ui.container.GridLayout
        ExploreTab                      matlab.ui.container.Tab
        GridLayout7_5                   matlab.ui.container.GridLayout
        IdentifyPixelsToolsLabel        matlab.ui.control.Label
        Explore_IdentifyPixels_PolygonButton  matlab.ui.control.Button
        ClassifyTab                     matlab.ui.container.Tab
        GridLayout7_6                   matlab.ui.container.GridLayout
        StatisticsOtherTab              matlab.ui.container.Tab
        GridLayout7_7                   matlab.ui.container.GridLayout
        Statistics_TextArea             matlab.ui.control.TextArea
        Plotting_TextArea               matlab.ui.control.TextArea
        StatisticsPlottedDataLabel      matlab.ui.control.Label
        PlottingReportlastplotonlyLabel  matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        Button_help                     matlab.ui.control.Button
        GridLayout6                     matlab.ui.container.GridLayout
        AutoZoom                        matlab.ui.control.Button
        ResetZoom                       matlab.ui.control.Button
        YminLabel                       matlab.ui.control.Label
        ManualZoom                      matlab.ui.control.Button
        GridLayout8                     matlab.ui.container.GridLayout
        Field_Ymax                      matlab.ui.control.NumericEditField
        YmaxLabel                       matlab.ui.control.Label
        GridLayout8_2                   matlab.ui.container.GridLayout
        Field_Ymin                      matlab.ui.control.NumericEditField
        GridLayout9                     matlab.ui.container.GridLayout
        Field_Xmin                      matlab.ui.control.NumericEditField
        XminLabel                       matlab.ui.control.Label
        GridLayout9_2                   matlab.ui.container.GridLayout
        Field_Xmax                      matlab.ui.control.NumericEditField
        XmaxLabel                       matlab.ui.control.Label
        FigPlot2                        matlab.ui.control.UIAxes
        FigPlot3                        matlab.ui.control.UIAxes
        FigPlot1                        matlab.ui.control.UIAxes
        FigPlot6                        matlab.ui.control.UIAxes
        FigPlot5                        matlab.ui.control.UIAxes
        FigPlot4                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        XMapToolsApp % Description
        Names % Description
        Data
        MaskFile
        LastPlot % Description
        WaitBar % Description
        
        ROI_PI_PolygonSingle 
        ROI_PI_PolygonSingle_Listener
        
        ROI_PI_PolygonSingle_Dens 
        ROI_PI_PolygonSingle_Dens_Listener
        
        ROI_PI_PolygonSingleTernary 
        ROI_PI_PolygonSingleTernary_Listener
        
        ROI_PI_PolygonSingleTernary_Dens 
        ROI_PI_PolygonSingleTernary_Dens_Listener
        
        LastSelectedPx % Description
        
    end
    
    methods (Access = private)
        
        function CheckState(app)
            
            app.CheckBox_Masks.Enable = 'off';
            app.Label_Maks.Enable = 'off';
            app.DropDown_Masks.Enable = 'off';
            
            % HISTOGRAM
            if isequal(app.Button_Histogram.Value,1)
                
                app.EditField_Y.Visible = 'off';
                app.CheckBox_Y_Manual.Visible = 'off';
                app.DropDown_Y.Visible = 'off';
                app.CheckBox_Y_Menu.Visible = 'off';
                app.Label_Y.Visible = 'off';
                
                app.EditField_Z.Visible = 'off';
                app.CheckBox_Z_Manual.Visible = 'off';
                app.DropDown_Z.Visible = 'off';
                app.CheckBox_Z_Menu.Visible = 'off';
                app.Label_Z.Visible = 'off';
                
                app.Explore_IdentifyPixels_PolygonButton.Enable = 'off';
                
            end
            
            % BINARY
            if isequal(app.Button_Binary.Value,1)
                
                app.EditField_Y.Visible = 'on';
                app.CheckBox_Y_Manual.Visible = 'on';
                app.DropDown_Y.Visible = 'on';
                app.CheckBox_Y_Menu.Visible = 'on';
                app.Label_Y.Visible = 'on';
                
                app.EditField_Z.Visible = 'off';
                app.CheckBox_Z_Manual.Visible = 'off';
                app.DropDown_Z.Visible = 'off';
                app.CheckBox_Z_Menu.Visible = 'off';
                app.Label_Z.Visible = 'off';
                
                if ~isempty(app.MaskFile.Names)
                    app.CheckBox_Masks.Enable = 'on';
                    app.Label_Maks.Enable = 'on';
                    app.DropDown_Masks.Enable = 'on';
                end
                
                app.Explore_IdentifyPixels_PolygonButton.Enable = 'on';
            end
            
            % RGB
            if isequal(app.Button_RGB.Value,1)
                
                app.EditField_Y.Visible = 'on';
                app.CheckBox_Y_Manual.Visible = 'on';
                app.DropDown_Y.Visible = 'on';
                app.CheckBox_Y_Menu.Visible = 'on';
                app.Label_Y.Visible = 'on';
                
                app.EditField_Z.Visible = 'on';
                app.CheckBox_Z_Manual.Visible = 'on';
                app.DropDown_Z.Visible = 'on';
                app.CheckBox_Z_Menu.Visible = 'on';
                app.Label_Z.Visible = 'on';
                
                app.Explore_IdentifyPixels_PolygonButton.Enable = 'off';
            end
            
            % Ternary
            if isequal(app.Button_Ternary.Value,1)
                
                app.EditField_Y.Visible = 'on';
                app.CheckBox_Y_Manual.Visible = 'on';
                app.DropDown_Y.Visible = 'on';
                app.CheckBox_Y_Menu.Visible = 'on';
                app.Label_Y.Visible = 'on';
                
                app.EditField_Z.Visible = 'on';
                app.CheckBox_Z_Manual.Visible = 'on';
                app.DropDown_Z.Visible = 'on';
                app.CheckBox_Z_Menu.Visible = 'on';
                app.Label_Z.Visible = 'on';
                
                if ~isempty(app.MaskFile.Names)
                    app.CheckBox_Masks.Enable = 'on';
                    app.Label_Maks.Enable = 'on';
                    app.DropDown_Masks.Enable = 'on';
                end
                
                app.Explore_IdentifyPixels_PolygonButton.Enable = 'on';
            end
            
            cla(app.FigPlot2,'reset')
            app.FigPlot2.Visible = 'off';
            
            PlotData(app);
        end
        
        
        function PlotData(app)
            
            app.WaitBar = uiprogressdlg(app.VisualizationTool,'Title','Display is Being Updated, Please Wait!','Indeterminate','on');
            
            delete(app.ROI_PI_PolygonSingle);
            cla(app.FigPlot1,'reset')
            
            app.WaitBar.Message = 'Extracting data';
            
            if isequal(app.CheckBox_X_Menu.Value,1)
                SelData_X = app.Data(app.DropDown_X.Value).Map(:);
                Labels_X = char(app.DropDown_X.Items(app.DropDown_X.Value));
            else
                SelData_X = ExtractDataFromCode(app,app.EditField_X.Value);
                SelData_X = SelData_X(:);
                if isequal(SelData_X,-1)
                    close(app.WaitBar)
                    return
                end
                Labels_X = app.EditField_X.Value;
                
                % 
            end
            
            if length(app.Data) > 1
                if isequal(app.CheckBox_Y_Menu.Value,1)
                    SelData_Y = app.Data(app.DropDown_Y.Value).Map(:);
                    Labels_Y = char(app.DropDown_Y.Items(app.DropDown_Y.Value));
                else
                    SelData_Y = ExtractDataFromCode(app,app.EditField_Y.Value);
                    SelData_Y = SelData_Y(:);
                    if isequal(SelData_Y,-1)
                        close(app.WaitBar)
                        return
                    end
                    Labels_Y = app.EditField_Y.Value;
                end
            else
                SelData_Y = [];
                Labels_Y = '';
            end
            
            if length(app.Data) > 2
                if isequal(app.CheckBox_Z_Menu.Value,1)
                    SelData_Z = app.Data(app.DropDown_Z.Value).Map(:);
                    Labels_Z = char(app.DropDown_Z.Items(app.DropDown_Z.Value));
                else
                    SelData_Z = ExtractDataFromCode(app,app.EditField_Z.Value);
                    SelData_Z = SelData_Z(:);
                    if isequal(SelData_Z,-1)
                        close(app.WaitBar)
                        return
                    end
                    Labels_Z = app.EditField_Z.Value;
                end
            else
                SelData_Z = [];
                Labels_Z = '';
            end
            
            % Potential Mask...
            if app.DropDown_Masks_2.Value > 0
                MaskPixels = find(app.MaskFile.Masks(app.DropDown_Masks.Value).MaskMap == app.DropDown_Masks_2.Value);
                Mask = zeros(size(SelData_X));
                Mask(MaskPixels) = 1;
                
                SelData_X = SelData_X.*Mask;
                if isequal(length(SelData_X),length(SelData_Y))
                    SelData_Y = SelData_Y.*Mask;
                end
                if isequal(length(SelData_X),length(SelData_Z))
                    SelData_Z = SelData_Z.*Mask;
                end
            end
            
            
            if app.CheckBox_FilterZeros.Value
                if isequal(app.Button_Histogram.Value,1)
                    Ind = find(SelData_X == 0);
                    SelData_X(Ind) = NaN;
                elseif isequal(app.Button_Binary.Value,1)
                    Ind = find(SelData_X == 0 & SelData_Y == 0);
                    SelData_X(Ind) = NaN;
                    SelData_Y(Ind) = NaN;
                else
                    Ind = find(SelData_X == 0 & SelData_Y == 0 & SelData_Z == 0);
                    SelData_X(Ind) = NaN;
                    SelData_Y(Ind) = NaN;
                    SelData_Z(Ind) = NaN;
                end
            end
            
            
            % HISTOGRAM
            if isequal(app.Button_Histogram.Value,1)
                app.WaitBar.Message = 'Plotting data';
                
                app.FigPlot1.Visible = 'on';
                
                %SelData_X = app.Data(app.DropDown_X.Value).Map(:);
                tic
                Text2Disp_Report = '';
                Text2Disp_Report = [Text2Disp_Report,'Plotting histogram data \n\n'];
                
                Text2Disp_Statistics = '';
                Text2Disp_Statistics = [Text2Disp_Statistics,'Histogram (',Labels_X,') \n\n'];
                
                Idx = find(SelData_X > 0);
                
                Percentage = 100;
                Text2Disp_Report = [Text2Disp_Report,'Size of dataset: ',num2str(length(Idx)),' data points (',num2str(round(Percentage)),'%% plotted)\n'];
                
                histogram(app.FigPlot1,SelData_X(Idx),'BinCountsMode','auto');
                title(app.FigPlot1,['Histogram of ',Labels_X])
                axis(app.FigPlot1,'auto')
                
                disableDefaultInteractivity(app.FigPlot1);
                tb = axtoolbar(app.FigPlot1,{'export'});
                
                app.LastPlot.X = SelData_X(Idx);
                app.LastPlot.SelData_X = SelData_X;
                
                Calc_Mean = mean(app.LastPlot.X);
                Calc_Median = median(app.LastPlot.X);
                Calc_Std = std(app.LastPlot.X);
                Calc_HWHM = Calc_Std*sqrt(2*log(2));
                
                Text2Disp_Statistics = [Text2Disp_Statistics,'> Assuming a Gaussian distribution:\n'];
                Text2Disp_Statistics = [Text2Disp_Statistics,'Mean\t\t',num2str(Calc_Mean),'\n'];
                Text2Disp_Statistics = [Text2Disp_Statistics,'Median\t\t',num2str(Calc_Median),'\n'];
                Text2Disp_Statistics = [Text2Disp_Statistics,'Stdev\t\t',num2str(Calc_Std),'\n'];
                Text2Disp_Statistics = [Text2Disp_Statistics,'HWHM\t\t',num2str(Calc_HWHM),'\n\n'];
                
                [h,p] = kstest(app.LastPlot.X);
                switch h
                    case 1
                        Text2Disp_Statistics = [Text2Disp_Statistics,'One-sample Kolmogorov-Smirnov test: failed (p-value: ',num2str(p),') \n'];
                    case 0
                        Text2Disp_Statistics = [Text2Disp_Statistics,'One-sample Kolmogorov-Smirnov test: passed (p-value: ',num2str(p),') \n'];
                end
                h = lillietest(app.LastPlot.X);
                switch h
                    case 1
                        Text2Disp_Statistics = [Text2Disp_Statistics,'Lilliefors test: failed \n'];
                    case 0
                        Text2Disp_Statistics = [Text2Disp_Statistics,'Lilliefors test: passed \n'];
                end
                
                cla(app.FigPlot3,'reset')
                app.FigPlot3.Visible = 'off';
                cla(app.FigPlot4,'reset')
                app.FigPlot4.Visible = 'off';
                cla(app.FigPlot5,'reset')
                app.FigPlot5.Visible = 'off';
                cla(app.FigPlot6,'reset')
                app.FigPlot6.Visible = 'off';
                
                cla(app.FigPlot2,'reset')
                app.FigPlot2.Visible = 'off';
                
                app.Field_Xmin.Visible = 'on';
                app.Field_Xmax.Visible = 'on';
                app.Field_Ymin.Visible = 'off';
                app.Field_Ymax.Visible = 'off';
                app.XminLabel.Visible = 'on';
                app.XmaxLabel.Visible = 'on';
                app.YminLabel.Visible = 'off';
                app.YmaxLabel.Visible = 'off';
                
                app.AutoZoom.Enable = 'off';
                app.ResetZoom.Enable = 'off';
                app.ManualZoom.Enable = 'off';
                
                ht = toc;
                Text2Disp_Report = [Text2Disp_Report,'Plotting histogram & adjusting plot options: ',num2str(ht), 's\n'];
                
                app.Plotting_TextArea.Value = sprintf(Text2Disp_Report);
                app.Statistics_TextArea.Value = sprintf(Text2Disp_Statistics);
            end
            
            
            % Binary Diagram
            if isequal(app.Button_Binary.Value,1)
                
                %disp(' ')
                Text2Disp_Report = '';
                if app.CheckBox_LowRessourcesMode.Value
                    Text2Disp_Report = [Text2Disp_Report,'Plotting binary data [low ressource mode: ON]\n\n'];
                else
                    Text2Disp_Report = [Text2Disp_Report,'Plotting binary data \n\n'];
                end
                
                Text2Disp_Statistics = '';
                Text2Disp_Statistics = [Text2Disp_Statistics,'Histogram (',Labels_X,' vs ',Labels_Y,') \n\n'];
                
                app.WaitBar.Message = 'Plotting data';
                
                app.FigPlot1.Visible = 'on';
                app.FigPlot3.Visible = 'on';
                app.FigPlot4.Visible = 'on';
                app.FigPlot5.Visible = 'on';
                
                %SelData_X = app.Data(app.DropDown_X.Value).Map(:);
                %SelData_Y = app.Data(app.DropDown_Y.Value).Map(:);
                
                tic
                if app.CheckBox_Masks.Value
                    MaskId = app.DropDown_Masks.Value;
                    
                    MaskNames = app.MaskFile.Masks(MaskId).Names(2:end);
                    Colors2Plot = InterpColor(app,length(MaskNames));
                    
                    Mask = app.MaskFile.Masks(MaskId).MaskMap;
                    
                    hold(app.FigPlot1,'on')
                    for i = 1:length(MaskNames)
                        Idx = find(Mask(:) == i & SelData_X > 0 & SelData_Y > 0);
                        
                        if ~isempty(Idx)
                            plot(app.FigPlot1,-1,-1,'.k','Markersize',30,'Color',Colors2Plot(i,:))
                            plot(app.FigPlot1,SelData_X(Idx),SelData_Y(Idx),'.k','Markersize',1,'Color',Colors2Plot(i,:),'HandleVisibility','off')
                        end
                    end
                    hold(app.FigPlot1,'off')
                    legend(app.FigPlot1,MaskNames,'Location','best')
                    app.FigPlot1.Color = [0.1,0.1,0.1];
                    Idx = find(SelData_X > 0 & SelData_Y > 0);
                    
                    if app.CheckBox_LowRessourcesMode.Value
                        Val = rand(size(Idx),'single');
                        Where = find(Val>0.7);
                        Percentage = length(Where)/length(Val)*100;
                        Idx = Idx(Where);
                    else
                        Percentage = 100;
                    end
                    
                else
                    Idx = find(SelData_X > 0 & SelData_Y > 0);
                    
                    if app.CheckBox_LowRessourcesMode.Value
                        Val = rand(size(Idx),'single');
                        Where = find(Val>0.7);
                        Percentage = length(Where)/length(Val)*100;
                        Idx = Idx(Where);
                    else
                        Percentage = 100;
                    end
                    
                    plot(app.FigPlot1,SelData_X(Idx),SelData_Y(Idx),'.k','Markersize',1)
                    app.FigPlot1.Color = [1,1,1];
                end
                                
                ht = toc;
                Text2Disp_Report = [Text2Disp_Report,'Size of dataset: ',num2str(length(Idx)),' data points (',num2str(round(Percentage)),'%% plotted)\n'];
                Text2Disp_Report = [Text2Disp_Report,'Extracting & Plotting data: ',num2str(ht),'s\n'];
                
                tic
                app.FigPlot1.XTickMode = 'Auto';
                app.FigPlot1.YTickMode = 'Auto';
                
                Xmin = min(SelData_X(Idx));
                Xmax = max(SelData_X(Idx));
                Ymin = min(SelData_Y(Idx));
                Ymax = max(SelData_Y(Idx));
                
                axis(app.FigPlot1,[Xmin,Xmax,Ymin,Ymax])
                
                app.Field_Xmin.Value = Xmin;
                app.Field_Xmax.Value = Xmax;
                app.Field_Ymin.Value = Ymin;
                app.Field_Ymax.Value = Ymax;
                
                app.LastPlot.X = SelData_X(Idx);
                app.LastPlot.Y = SelData_Y(Idx);
                app.LastPlot.SelData_X = SelData_X;
                app.LastPlot.SelData_Y = SelData_Y;
                
                R = corrcoef(app.LastPlot.X,app.LastPlot.Y);
                Text2Disp_Statistics = [Text2Disp_Statistics,'Correlation coefficient\t',num2str(R(1,2)),'\n'];
                
                disableDefaultInteractivity(app.FigPlot1);
                tb = axtoolbar(app.FigPlot1,{'export'});
                
                title(app.FigPlot1,['Binary plot ',char(Labels_X),' vs ',char(Labels_Y),' (',num2str(round(Percentage)),'% data plotted)'],'interpreter','none')
                xlabel(app.FigPlot1,char(Labels_X),'interpreter','none')
                ylabel(app.FigPlot1,char(Labels_Y),'interpreter','none')
                
                drawnow
                
                ht = toc;
                Text2Disp_Report = [Text2Disp_Report,'Adjusting plot; force plotting: ',num2str(ht), 's\n'];
                                
                tic
                histogram(app.FigPlot3,app.LastPlot.X,'BinMethod','auto');
                xlabel(app.FigPlot3,char(Labels_X),'interpreter','none')
                ylabel(app.FigPlot3,'#')
                title(app.FigPlot3,['Histogram for ',char(Labels_X)],'interpreter','none')
                if isequal(app.histogramwithlogYscaleCheckBox.Value,1)
                    app.FigPlot3.YScale = 'log';
                else
                    app.FigPlot3.YScale = 'linear';
                end
                
                disableDefaultInteractivity(app.FigPlot3);
                tb = axtoolbar(app.FigPlot3,{'export'});
                
                ht = toc;
                Text2Disp_Report = [Text2Disp_Report,'Plotting the first histogram : ',num2str(ht), 's\n'];
                
                tic
                histogram(app.FigPlot4,app.LastPlot.Y,'BinMethod','auto');
                xlabel(app.FigPlot4,char(Labels_Y),'interpreter','none')
                title(app.FigPlot4,['Histogram for ',char(Labels_Y)],'interpreter','none')
                ylabel(app.FigPlot4,'#')
                if isequal(app.histogramwithlogYscaleCheckBox.Value,1)
                    app.FigPlot4.YScale = 'log';
                else
                    app.FigPlot4.YScale = 'linear';
                end
                
                disableDefaultInteractivity(app.FigPlot4);
                tb = axtoolbar(app.FigPlot4,{'export'});
                
                ht = toc;
                Text2Disp_Report = [Text2Disp_Report,'Plotting the second histogram : ',num2str(ht), 's\n'];
                
                app.WaitBar.Message = 'Generating density map';
                
                tic
                plotDensityBin(app)
                
                ht = toc;
                Text2Disp_Report = [Text2Disp_Report,'Calculating and plotting the density map : ',num2str(ht), 's\n'];
                
                tic
                app.FigPlot6.Visible = 'off';
                app.FigPlot2.Visible = 'off';
                
                app.TabGroup2.SelectedTab = app.Tab_Options_Binary;
                
                app.Field_Xmin.Visible = 'on';
                app.Field_Xmax.Visible = 'on';
                app.Field_Ymin.Visible = 'on';
                app.Field_Ymax.Visible = 'on';
                app.XminLabel.Visible = 'on';
                app.XmaxLabel.Visible = 'on';
                app.YminLabel.Visible = 'on';
                app.YmaxLabel.Visible = 'on';
                
                cla(app.FigPlot6,'reset');
                app.FigPlot6.Visible = 'off';
                
                app.AutoZoom.Enable = 'on';
                app.ResetZoom.Enable = 'on';
                app.ManualZoom.Enable = 'on';
                
                ht = toc;
                Text2Disp_Report = [Text2Disp_Report,'Final update of plots & interface : ',num2str(ht), 's\n'];
                
                app.Plotting_TextArea.Value = sprintf(Text2Disp_Report);
                app.Statistics_TextArea.Value = sprintf(Text2Disp_Statistics);
            end
            
            
            % Ternary diagram
            if isequal(app.Button_Ternary.Value,1)
                
                app.WaitBar.Message = 'Plotting data';
                
                Text2Disp_Report = '';
                Text2Disp_Report = [Text2Disp_Report,'no report available\n\n'];
                
                Text2Disp_Statistics = '';
                Text2Disp_Statistics = [Text2Disp_Statistics,'Ternary Diagram (',Labels_X,' vs ',Labels_Y,' vs ',Labels_Z,') \n\n'];
                
                a = char(Labels_X);
                b = char(Labels_Y);
                c = char(Labels_Z);
                
                Xa = SelData_X./(SelData_X+SelData_Y+SelData_Z);
                Xb = SelData_Y./(SelData_X+SelData_Y+SelData_Z);
                Xc = SelData_Z./(SelData_X+SelData_Y+SelData_Z);
                
                cla(app.FigPlot1,'reset');
                
                if app.CheckBox_Masks.Value
                    
                    plot(app.FigPlot1,[0,0.5,1,0],[0,1,0,0],'-','color',[1,1,1],'LineWidth',2,'HandleVisibility','off')
                    hold(app.FigPlot1,'on')
                    
                    MaskId = app.DropDown_Masks.Value;
                    
                    MaskNames = app.MaskFile.Masks(MaskId).Names(2:end);
                    Colors2Plot = InterpColor(app,length(MaskNames));
                    
                    Mask = app.MaskFile.Masks(MaskId).MaskMap;
                    
                    hold(app.FigPlot1,'on')
                    for i = 1:length(MaskNames)
                        Idx = find(Mask(:) == i & Xa > 0 & Xb > 0 & Xc > 0);
                        
                        if ~isempty(Idx)
                            plot(app.FigPlot1,-10,-10,'.','Markersize',30,'Color',Colors2Plot(i,:))
                            plot(app.FigPlot1,Xc(Idx)+(1-(Xc(Idx)+Xa(Idx)))./2,Xb(Idx),'.k','markersize',1,'Color',Colors2Plot(i,:),'HandleVisibility','off')
                            
                            %plot(app.FigPlot1,-1,-1,'.k','Markersize',30,'Color',Colors2Plot(i,:))
                            %plot(app.FigPlot1,SelData_X(Idx),SelData_Y(Idx),'.k','Markersize',1,'Color',Colors2Plot(i,:),'HandleVisibility','off')
                        end
                    end
                    hold(app.FigPlot1,'off')
                    legend(app.FigPlot1,MaskNames,'Location','best')
                    app.FigPlot1.Color = [0.1,0.1,0.1];
                    Idx = find(Xa > 0 & Xb > 0 & Xc > 0);
                else
                    
                    plot(app.FigPlot1,[0,0.5,1,0],[0,1,0,0],'-','color',[0,0,0],'LineWidth',1,'HandleVisibility','off')
                    hold(app.FigPlot1,'on')
                    
                    
                    plot(app.FigPlot1,[0.4,0.8],[0.8,0],':','color',[0,0,0,0.5],'HandleVisibility','off')
                    plot(app.FigPlot1,[0.3,0.6],[0.6,0],':','color',[0,0,0,0.5],'HandleVisibility','off')
                    plot(app.FigPlot1,[0.2,0.4],[0.4,0],':','color',[0,0,0,0.5],'HandleVisibility','off')
                    plot(app.FigPlot1,[0.1,0.2],[0.2,0],':','color',[0,0,0,0.5],'HandleVisibility','off')
                    
                    plot(app.FigPlot1,[0.8,0.9],[0,0.2],':','color',[0,0,0,0.5],'HandleVisibility','off')
                    plot(app.FigPlot1,[0.6,0.8],[0,0.4],':','color',[0,0,0,0.5],'HandleVisibility','off')
                    plot(app.FigPlot1,[0.4,0.7],[0,0.6],':','color',[0,0,0,0.5],'HandleVisibility','off')
                    plot(app.FigPlot1,[0.2,0.6],[0,0.8],':','color',[0,0,0,0.5],'HandleVisibility','off')
                    
                    plot(app.FigPlot1,[0.4,0.6],[0.8,0.8],':','color',[0,0,0,0.5],'HandleVisibility','off')
                    plot(app.FigPlot1,[0.3,0.7],[0.6,0.6],':','color',[0,0,0,0.5],'HandleVisibility','off')
                    plot(app.FigPlot1,[0.2,0.8],[0.4,0.4],':','color',[0,0,0,0.5],'HandleVisibility','off')
                    plot(app.FigPlot1,[0.1,0.9],[0.2,0.2],':','color',[0,0,0,0.5],'HandleVisibility','off')
                    
                    Idx = find(Xa > 0 & Xb > 0 & Xc > 0);
                    plot(app.FigPlot1,Xc(Idx)+(1-(Xc(Idx)+Xa(Idx)))./2,Xb(Idx),'.k','markersize',1)
                    app.FigPlot1.Color = [1,1,1];
                end
                
                axis(app.FigPlot1,[-0.2 1.2 -0.12 1.12])
                
                app.LastPlot.X = Xc(Idx)+(1-(Xc(Idx)+Xa(Idx)))./2;
                app.LastPlot.Y = Xb(Idx);
                app.LastPlot.SelData_X = Xc+(1-(Xc+Xa))./2;
                app.LastPlot.SelData_Y = Xb;
                
                
                if app.CheckBox_Masks.Value
                    ColorText = [1,1,1];
                else
                    ColorText = [0,0,0];
                end
                text(app.FigPlot1,0-(length(char(a))/2)*0.018,-0.05,char(a),'Color',ColorText,'interpreter','none')
                text(app.FigPlot1,0.5-(length(char(b))/2)*0.018,1.05,char(b),'Color',ColorText,'interpreter','none')
                text(app.FigPlot1,1-(length(char(c))/2)*0.018,-0.05,char(c),'Color',ColorText,'interpreter','none')
                
                hold(app.FigPlot1,'off')
                
                title(app.FigPlot1,['Ternary plot ',a,' - ',b,' - ',c],'interpreter','none')
                
                app.FigPlot1.XTickMode = 'Manual';
                app.FigPlot1.YTickMode = 'Manual';
                app.FigPlot1.XTick = [];
                app.FigPlot1.YTick = [];
                
                disableDefaultInteractivity(app.FigPlot1);
                tb = axtoolbar(app.FigPlot1,{'export'});
                
                drawnow
                
                cla(app.FigPlot3,'reset');
                histogram(app.FigPlot3,SelData_X(Idx),'BinMethod','auto');
                xlabel(app.FigPlot3,a,'interpreter','none')
                ylabel(app.FigPlot3,'#')
                title(app.FigPlot3,['Histogram for ',a],'interpreter','none')
                if isequal(app.histogramwithlogYscaleCheckBox.Value,1)
                    app.FigPlot3.YScale = 'log';
                else
                    app.FigPlot3.YScale = 'linear';
                end
                
                disableDefaultInteractivity(app.FigPlot3);
                tb = axtoolbar(app.FigPlot3,{'export'});
                
                cla(app.FigPlot4,'reset');
                histogram(app.FigPlot4,SelData_Y(Idx),'BinMethod','auto');
                xlabel(app.FigPlot4,b,'interpreter','none')
                title(app.FigPlot4,['Histogram for ',b],'interpreter','none')
                ylabel(app.FigPlot4,'#')
                if isequal(app.histogramwithlogYscaleCheckBox.Value,1)
                    app.FigPlot4.YScale = 'log';
                else
                    app.FigPlot4.YScale = 'linear';
                end
                
                disableDefaultInteractivity(app.FigPlot4);
                tb = axtoolbar(app.FigPlot4,{'export'});
                
                cla(app.FigPlot6,'reset');
                histogram(app.FigPlot6,SelData_Z(Idx),'BinMethod','auto');
                xlabel(app.FigPlot6,c,'interpreter','none')
                title(app.FigPlot6,['Histogram for ',c],'interpreter','none')
                ylabel(app.FigPlot6,'#')
                if isequal(app.histogramwithlogYscaleCheckBox.Value,1)
                    app.FigPlot6.YScale = 'log';
                else
                    app.FigPlot6.YScale = 'linear';
                end
                
                disableDefaultInteractivity(app.FigPlot6);
                tb = axtoolbar(app.FigPlot6,{'export'});
                
                app.Field_Xmin.Visible = 'off';
                app.Field_Xmax.Visible = 'off';
                app.Field_Ymin.Visible = 'off';
                app.Field_Ymax.Visible = 'off';
                app.XminLabel.Visible = 'off';
                app.XmaxLabel.Visible = 'off';
                app.YminLabel.Visible = 'off';
                app.YmaxLabel.Visible = 'off';
                
                app.WaitBar.Message = 'Generating density map';
                plotDensityTern(app)
                
                app.AutoZoom.Enable = 'off';
                app.ResetZoom.Enable = 'off';
                app.ManualZoom.Enable = 'off';
                
                app.Plotting_TextArea.Value = sprintf(Text2Disp_Report);
                app.Statistics_TextArea.Value = sprintf(Text2Disp_Statistics);
            end
            
            % RGB plot
            if isequal(app.Button_RGB.Value,1)
                
                app.WaitBar.Message = 'Plotting data';
                
                Text2Disp_Statistics = '';
                Text2Disp_Statistics = [Text2Disp_Statistics,'RGB Plot (',Labels_X,' vs ',Labels_Y,' vs ',Labels_Z,') \n\n'];
                
                Text2Disp_Report = '';
                Text2Disp_Report = [Text2Disp_Report,'no report available\n\n'];
                
                ReshapeSize = size(app.Data(1).Map);
                
                SelData_X = reshape(SelData_X,ReshapeSize);
                SelData_Y = reshape(SelData_Y,ReshapeSize);
                SelData_Z = reshape(SelData_Z,ReshapeSize);
                
                a = char(Labels_X);
                b = char(Labels_Y);
                c = char(Labels_Z);
                
                RedChannel = zeros(size(SelData_X));
                GreenChannel = zeros(size(SelData_X));
                BlueChannel = zeros(size(SelData_X));
                
                RedChannel = SelData_X./max(SelData_X(:));
                GreenChannel = SelData_Y./max(SelData_Y(:));
                BlueChannel = SelData_Z./max(SelData_Z(:)); 
                
                RgbImage = cat(3, RedChannel, GreenChannel, BlueChannel);
                
                cla(app.FigPlot1,'reset');
                image(app.FigPlot1,RgbImage)
                
                title(app.FigPlot1,['RGB image ',a,' - ',b,' - ',c],'interpreter','none')
                
                disableDefaultInteractivity(app.FigPlot1);
                tb = axtoolbar(app.FigPlot1,{'export'});
                
                cla(app.FigPlot3,'reset');
                app.FigPlot3.Visible = 'off';
                cla(app.FigPlot4,'reset');
                app.FigPlot4.Visible = 'off';
                cla(app.FigPlot6,'reset');
                app.FigPlot6.Visible = 'off';
                
                close(app.WaitBar)
                
                plotTriangle4RGB(app,a,b,c)
                
                app.Field_Xmin.Visible = 'off';
                app.Field_Xmax.Visible = 'off';
                app.Field_Ymin.Visible = 'off';
                app.Field_Ymax.Visible = 'off';
                app.XminLabel.Visible = 'off';
                app.XmaxLabel.Visible = 'off';
                app.YminLabel.Visible = 'off';
                app.YmaxLabel.Visible = 'off';
                
                app.AutoZoom.Enable = 'off';
                app.ResetZoom.Enable = 'off';
                app.ManualZoom.Enable = 'off';
                
                app.Plotting_TextArea.Value = sprintf(Text2Disp_Report);
                app.Statistics_TextArea.Value = sprintf(Text2Disp_Statistics);
            end
            
            
            %keyboard
            
            close(app.WaitBar)
            
        end
        
        
        function plotDensityBin(app)
            
            if app.CheckBox_LowRessourcesMode.Value
                cla(app.FigPlot5,'reset');
                app.FigPlot5.Visible = 'off';
                return
            end
            
            MapSize = 80;
            %tic
            Lims = axis(app.FigPlot1);
            E1 = app.LastPlot.X;
            E2 = app.LastPlot.Y;
            
            Xmin = Lims(1);
            Xmax = Lims(2);
            Ymin = Lims(3);
            Ymax = Lims(4);
            
            % Update limits in the main plot
            app.Field_Xmin.Value = Xmin;
            app.Field_Xmax.Value = Xmax;
            app.Field_Ymin.Value = Ymin;
            app.Field_Ymax.Value = Ymax;
            
            Xstep = (Xmax-Xmin)/(MapSize-1);
            Ystep = (Ymax-Ymin)/(MapSize-1);
            
            Xi = Xmin:Xstep:Xmax;
            Yi = Ymin:Ystep:Ymax;
            
            X = E1(find(E1 > 0 & E2 > 0));
            Y = E2(find(E1 > 0 & E2 > 0));
            
            minx = Xmin;
            maxx = Xmax;
            miny = Ymin;
            maxy = Ymax;
            
            nbins = [min(numel(unique(X)),MapSize) ,min(numel(unique(Y)),MapSize) ];
            
            edges1 = linspace(minx, maxx, nbins(1)+1);
            ctrs1 = edges1(1:end-1) + .5*diff(edges1);
            edges1 = [-Inf edges1(2:end-1) Inf];
            edges2 = linspace(miny, maxy, nbins(2)+1);
            ctrs2 = edges2(1:end-1) + .5*diff(edges2);
            edges2 = [-Inf edges2(2:end-1) Inf];
            
            [n,p] = size(X);
            bin = zeros(n,2);
            % Reverse the columns to put the first column of X along the horizontal
            % axis, the second along the vertical.
            [dum,bin(:,2)] = histc(X,edges1);
            [dum,bin(:,1)] = histc(Y,edges2);
            
            ImageDistribution2D = accumarray(bin,1,nbins([2 1]));
            
            imagesc(app.FigPlot5,Xi,Yi,log(ImageDistribution2D))
            axis(app.FigPlot5,[Xmin Xmax Ymin Ymax]) 
            
            Cmin = min(log(ImageDistribution2D(find(ImageDistribution2D))))+1;
            Cmax = max(log(ImageDistribution2D(:)))+1;
            
            app.FigPlot5.YDir = 'normal';
            colormap(app.FigPlot5,Turbo(app,64))
            title(app.FigPlot5,['Density map ',char(app.DropDown_X.Items(app.DropDown_X.Value)),' vs ',char(app.DropDown_Y.Items(app.DropDown_Y.Value))],'interpreter','none')
            xlabel(app.FigPlot5,char(app.DropDown_X.Items(app.DropDown_X.Value)),'interpreter','none')
            ylabel(app.FigPlot5,char(app.DropDown_Y.Items(app.DropDown_Y.Value)),'interpreter','none')
            
            tb = axtoolbar(app.FigPlot5,{'export'});
            
            tk = logspace(log(Cmin),log(Cmax),5);
            
            %originalSize1 = get(gca, 'Position');
            hc = colorbar(app.FigPlot5,'vertical');
            colormap(app.FigPlot5,[Turbo(app,64)])
            %set(gca,'Position',originalSize1);
            
            Labels = get(hc,'YTickLabel');
            
            for i = 1:length(Labels)
                LabelsOk{i} = num2str(round(exp(str2num(char(Labels(i,:))))),'%.0f');
            end
            
            %LabelsOk;
            
            set(hc,'YTickLabel',LabelsOk);
            drawnow
            %toc
            
        end
        
        
        function plotDensityTern(app)
            MapSize = 120;
            %tic
            Lims = axis(app.FigPlot1);
            E1 = app.LastPlot.X;
            E2 = app.LastPlot.Y;
            
            Xmin = Lims(1);
            Xmax = Lims(2);
            Ymin = Lims(3);
            Ymax = Lims(4);
            
            Xstep = (Xmax-Xmin)/(MapSize-1);
            Ystep = (Ymax-Ymin)/(MapSize-1);
            
            Xi = Xmin:Xstep:Xmax;
            Yi = Ymin:Ystep:Ymax;
            
            X = E1(find(E1 > 0 & E2 > 0));
            Y = E2(find(E1 > 0 & E2 > 0));
            
            minx = Xmin;
            maxx = Xmax;
            miny = Ymin;
            maxy = Ymax;
            
            nbins = [min(numel(unique(X)),MapSize) ,min(numel(unique(Y)),MapSize) ];
            
            edges1 = linspace(minx, maxx, nbins(1)+1);
            ctrs1 = edges1(1:end-1) + .5*diff(edges1);
            edges1 = [-Inf edges1(2:end-1) Inf];
            edges2 = linspace(miny, maxy, nbins(2)+1);
            ctrs2 = edges2(1:end-1) + .5*diff(edges2);
            edges2 = [-Inf edges2(2:end-1) Inf];
            
            [n,p] = size(X);
            bin = zeros(n,2);
            % Reverse the columns to put the first column of X along the horizontal
            % axis, the second along the vertical.
            [dum,bin(:,2)] = histc(X,edges1);
            [dum,bin(:,1)] = histc(Y,edges2);
            
            ImageDistribution2D = accumarray(bin,1,nbins([2 1]));
            
            cla(app.FigPlot5,'reset');
            imagesc(app.FigPlot5,Xi,Yi,log(ImageDistribution2D))
            axis(app.FigPlot5,[Xmin Xmax Ymin Ymax]) 
            
            Cmin = min(log(ImageDistribution2D(find(ImageDistribution2D))))+1;
            Cmax = max(log(ImageDistribution2D(:)))+1;
            
            app.FigPlot5.YDir = 'normal';
            colormap(app.FigPlot5,Turbo(app,64))
            title(app.FigPlot5,['Density map ',char(app.DropDown_X.Items(app.DropDown_X.Value)),' vs ',char(app.DropDown_Y.Items(app.DropDown_Y.Value))],'interpreter','none')
            xlabel(app.FigPlot5,char(app.DropDown_X.Items(app.DropDown_X.Value)),'interpreter','none')
            ylabel(app.FigPlot5,char(app.DropDown_Y.Items(app.DropDown_Y.Value)),'interpreter','none')
            
            tb = axtoolbar(app.FigPlot5,{'export'});
            
            tk = logspace(log(Cmin),log(Cmax),5);
            
            %originalSize1 = get(gca, 'Position');
            hc = colorbar(app.FigPlot5,'vertical');
            colormap(app.FigPlot5,[Turbo(app,64)])
            %set(gca,'Position',originalSize1);
            
            Labels = get(hc,'YTickLabel');
            
            for i = 1:length(Labels)
                LabelsOk{i} = num2str(round(exp(str2num(char(Labels(i,:))))),'%.0f');
            end
            
            %LabelsOk;
            
            set(hc,'YTickLabel',LabelsOk);
            
            hold(app.FigPlot5,'on')
            plot(app.FigPlot5,[0,0.5,1,0],[0,1,0,0],'-','color',[1,1,1],'linewidth',2)
            hold(app.FigPlot5,'off')
            
            app.FigPlot5.Title.String = 'Density Map (Ternary Plot)';
            app.FigPlot5.XTickMode = 'Manual';
            app.FigPlot5.YTickMode = 'Manual';
            app.FigPlot5.XTick = [];
            app.FigPlot5.YTick = [];
            
            xlabel(app.FigPlot5,'');
            ylabel(app.FigPlot5,'');
            
            drawnow
            %toc
            
        end
        
        function plotTriangle4RGB(app,a,b,c)
            Nb=100;
            Step = (1-0)/(Nb);
            
            RiS = ones(Nb+1,Nb+1);
            BiS = ones(Nb+1,Nb+1);
            GiS = ones(Nb+1,Nb+1);
            
            for i = 1:Nb+1
                RiS(i,1:i) = (1-(i-1)*Step).*ones(size(RiS(i,1:i)));
                BiS(i:end,i) = (0+(i-1)*Step)*ones(size(BiS(i:end,i)));
                GiS(i:end,i) = [0:Step:(Nb+1-i)*Step]';
            end
            
            Fac = 2;
            
            Ri = ones(Fac*(Nb+1),Fac*(Nb+1));
            Bi = ones(Fac*(Nb+1),Fac*(Nb+1));
            Gi = ones(Fac*(Nb+1),Fac*(Nb+1));
            
            Where = round(Nb/Fac+1);
            
            Ri(Where:Where+Nb,Where:Where+Nb) = RiS;
            Bi(Where:Where+Nb,Where:Where+Nb) = BiS;
            Gi(Where:Where+Nb,Where:Where+Nb) = GiS;
            
            RgbImage = cat(3, Ri, Gi, Bi);
            
            cla(app.FigPlot5,'reset');
            image(app.FigPlot5,RgbImage); 
            hold(app.FigPlot5,'on')
            %imshow(RgbImage,'Border','loose'); hold on
            
            plot(app.FigPlot5,[(Nb+2)/Fac,(Nb+2)/Fac],[(Nb+2)/Fac,Nb+(Nb+2)/Fac],'k','LineWidth',1)
            plot(app.FigPlot5,[(Nb+2)/Fac,Nb+(Nb+2)/Fac],[(Nb+2)/Fac,Nb+(Nb+2)/Fac],'k','LineWidth',1)
            plot(app.FigPlot5,[(Nb+2)/Fac,Nb+(Nb+2)/Fac],[Nb+(Nb+2)/Fac,Nb+(Nb+2)/Fac],'k','LineWidth',1)
            
            plot(app.FigPlot5,(Nb+2)/Fac,(Nb+2)/Fac,'o','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',4);
            plot(app.FigPlot5,(Nb+2)/Fac,Nb+(Nb+2)/Fac,'o','MarkerEdgeColor','k','MarkerFaceColor','g','MarkerSize',4);
            plot(app.FigPlot5,Nb+(Nb+2)/Fac,Nb+(Nb+2)/Fac,'o','MarkerEdgeColor','k','MarkerFaceColor','b','MarkerSize',4);
            
            plot(app.FigPlot5,[1,1],[1,Fac*(Nb+1)],'k','LineWidth',1)
            plot(app.FigPlot5,[1,Fac*(Nb+1)],[1,1],'k','LineWidth',1)
            plot(app.FigPlot5,[Fac*(Nb+1),Fac*(Nb+1)],[0,Fac*(Nb+1)],'k','LineWidth',1)
            plot(app.FigPlot5,[1,Fac*(Nb+1)],[Fac*(Nb+1),Fac*(Nb+1)],'k','LineWidth',1)
            
            text(app.FigPlot5,Nb/Fac,Nb/Fac-0.15*Nb,a,'HorizontalAlignment','Center','interpreter','none');
            text(app.FigPlot5,Nb/Fac,Nb+Nb/Fac+0.15*Nb,b,'HorizontalAlignment','Center','interpreter','none');
            text(app.FigPlot5,Nb+Nb/Fac,Nb+Nb/Fac+0.15*Nb,c,'HorizontalAlignment','Center','interpreter','none');
            
            disableDefaultInteractivity(app.FigPlot5);
            tb = axtoolbar(app.FigPlot5,{'export'});
            
            app.FigPlot5.XTickMode = 'Manual';
            app.FigPlot5.YTickMode = 'Manual';
            app.FigPlot5.XTick = [];
            app.FigPlot5.YTick = [];
            
            app.FigPlot1.XTickMode = 'Manual';
            app.FigPlot1.YTickMode = 'Manual';
            app.FigPlot1.XTick = [];
            app.FigPlot1.YTick = [];

        end


        function PixelIndentifier(app,Mode)
            
            switch Mode
                case 'Single'
                    
                    %x = app.Data(app.DropDown_X.Value).Map(:);
                    %y = app.Data(app.DropDown_Y.Value).Map(:);
                    
                    x = app.LastPlot.SelData_X;
                    y = app.LastPlot.SelData_Y;
                    
                    MapSize = size(app.Data(app.DropDown_X.Value).Map);
                    
                    P =  app.ROI_PI_PolygonSingle.Position;
                    
                    % Extract the pixels in the polygon
                    IN = inpolygon(x,y,P(:,1),P(:,2));
                    Indx = find(IN);
                    
                    % Check if all the pixels have been defined (or not)
                    NonNull = find(x>0 & y>0);
                    Null = find(x==0 & y==0);
                    
                    if isequal(length(NonNull),length(x))
                        OuiMask = 0;
                    else
                        OuiMask = 1;
                    end
                    
                    LeMask = zeros(MapSize);
                    LeMask(NonNull) = 1.5*ones(size(NonNull));
                    LeMask(Indx) = 2.5*ones(size(Indx));
                    
                    SelectedPx = ones(MapSize);
                    SelectedPx(Indx) = 2*ones(size(Indx));
                    
                    app.LastSelectedPx = SelectedPx;
                    
                    imagesc(app.FigPlot2,LeMask)
                    axis(app.FigPlot2,'image')
                    
                    app.FigPlot2.XTick = [];
                    app.FigPlot2.YTick = [];
                    app.FigPlot2.XLabel.String = '';
                    app.FigPlot2.YLabel.String = '';
                    app.FigPlot2.Title.String = '';
                    app.FigPlot2.Visible = 'On';
                    
                    if isequal(OuiMask,1)
                        colormap(app.FigPlot2,[0,0,0;0.2,0.3,1;1,0,0])
                        
                        if size(LeMask,2) > size(LeMask,1)
                            colorbar(app.FigPlot2,'XTickLabel',{'Undefined','Unselected (>0)','Selected'},'Location','SouthOutside','XTickMode','manual','XTick',[0.5,1.5,2.5]); 
                        else
                            colorbar(app.FigPlot2,'XTickLabel',{'Undefined','Unselected (>0)','Selected'},'XTickMode','manual','XTick',[0.5,1.5,2.5])
 
                        end
                        
                        caxis(app.FigPlot2,[0 3]);
                        %handles.colorbar = hcb;
                        %set(hcb,'FontName','Times New Roman');
                    else
                        colormap(app.FigPlot2,[0,0,0;0.2,0.3,1;1,0,0])
                          
                        if size(LeMask,2) > size(LeMask,1)
                            colorbar(app.FigPlot2,'XTickLabel',{'Undefined','Unselected (>0)','Selected'},'Location','SouthOutside','XTickMode','manual','XTick',[1.5,2.5]);
                        else
                            colorbar(app.FigPlot2,'XTickLabel',{'Undefined','Unselected (>0)','Selected'},'XTickMode','manual','XTick',[1.5,2.5])
                        end
                        caxis(app.FigPlot2,[0 3]);
                        
                    end

            end
            
            
        end


        function ROI_ShapeChangedSingle(app,~)
            
            app.ROI_PI_PolygonSingle_Dens.Position = app.ROI_PI_PolygonSingle.Position;
            PixelIndentifier(app,'Single');
        end
        function ROI_ShapeChangedSingleDens(app,~)
            
            app.ROI_PI_PolygonSingle.Position = app.ROI_PI_PolygonSingle_Dens.Position;
            PixelIndentifier(app,'Single');
        end
        
        function ROI_ShapeChangedSingleTernary(app,~)
            
            app.ROI_PI_PolygonSingleTernary_Dens.Position = app.ROI_PI_PolygonSingleTernary.Position;
            PixelIndentifier(app,'Single-Ternary');
        end
        function ROI_ShapeChangedSingleTernaryDens(app,~)
            
            app.ROI_PI_PolygonSingleTernary.Position = app.ROI_PI_PolygonSingleTernary_Dens.Position;
            PixelIndentifier(app,'Single-Ternary');
        end 
        
        
        function ColorMap = InterpColor(app,ResColorMap)
            
            ColorData = [0.647	0.000	0.149; ...
                0.843	0.188	0.153; ...
                0.957	0.427	0.263; ...
                0.992	0.682	0.380; ...
                0.996	0.878	0.565; ...
                1.000	1.000	0.749; ...
                0.878	0.953	0.973; ...
                0.671	0.851	0.914; ...
                0.455	0.678	0.820; ...
                0.271	0.459	0.706; ...
                0.192	0.212	0.584];
            
            ColorData = flipud(ColorData);
            
            Xi = 1:ResColorMap;
            Step = (ResColorMap-1)/(size(ColorData,1)-1);
            X = 1:Step:ResColorMap;
            
            ColorMap = zeros(length(Xi),size(ColorData,2));
            for i = 1:size(ColorData,2)
                ColorMap(:,i) = interp1(X',ColorData(:,i),Xi);
                % = polyval(P,);
            end
            
            
        end


        function SelData = ExtractDataFromCode(app,Value)
            
            for i = 1:length(app.Names)
                eval([char(app.Names{i}),' = app.Data(i).Map;']);
            end
            
            try
                eval(['SelData = ',Value,';']);
            catch ME
                errordlg(ME.message,'Oups wrong code!')
                SelData = -1;
            end
            
        end
        
        function map = Turbo(app,Value)
            
            
            values = [
                0.18995 0.07176 0.23217
                0.19483 0.08339 0.26149
                0.19956 0.09498 0.29024
                0.20415 0.10652 0.31844
                0.20860 0.11802 0.34607
                0.21291 0.12947 0.37314
                0.21708 0.14087 0.39964
                0.22111 0.15223 0.42558
                0.22500 0.16354 0.45096
                0.22875 0.17481 0.47578
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                0.47960 0.01583 0.01055
                ];
            
            P = size(values,1);
            map = interp1(1:size(values,1), values, linspace(1,P,Value), 'linear');            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, XMapToolsApp, Names, Data, Selected, MaskFile)
            %
            app.VisualizationTool.Visible = 'off';
            
            diary('log_dvt.txt');
            
            if isequal(Selected,0)
                Selected = 1;
            end
            
            app.XMapToolsApp = XMapToolsApp;
            app.Names = Names;
            app.Data = Data;
            app.MaskFile = MaskFile;
            
            % Update GUI
            app.DropDown_X.Items = Names;
            app.DropDown_X.ItemsData = [1:length(Data)];
            app.CheckBox_X_Menu.Value = 1;
            app.CheckBox_X_Manual.Value = 0;
            
            app.DropDown_X.Value =  Selected;
            
            % Update the check boxes
            app.CheckBox_X_Menu.Enable = 'off';
            app.CheckBox_X_Menu.Value = 1;
            app.CheckBox_Y_Menu.Enable = 'off';
            app.CheckBox_Y_Menu.Value = 1;
            app.CheckBox_Z_Menu.Enable = 'off';
            app.CheckBox_Z_Menu.Value = 1;
            
            app.EditField_X.Enable = 'off';
            app.EditField_Y.Enable = 'off';
            app.EditField_Z.Enable = 'off';
            
            if length(Data) > 1
                app.DropDown_Y.Items = Names;
                app.DropDown_Y.ItemsData = [1:length(Data)];
                app.CheckBox_Y_Menu.Value = 1;
                app.CheckBox_Y_Manual.Value = 0;
                
                app.Button_Binary.Value = 1;
                
                if length(Data) >= Selected+1
                    app.DropDown_Y.Value =  Selected+1;
                else
                    app.DropDown_Y.Value = 2;
                end
            else
                app.EditField_Y.Visible = 'off';
                app.CheckBox_Y_Manual.Visible = 'off';
                app.DropDown_Y.Visible = 'off';
                app.CheckBox_Y_Menu.Visible = 'off';
                
                app.Button_Binary.Value = 1;
                
                % Need to start in Histogram Mode
                app.Button_Binary.Value = 0;
                app.Button_Histogram.Value = 1;
                
                app.Button_Binary.Visible = 'off';
                app.Button_Ternary.Visible = 'off';
                app.Button_RGB.Visible = 'off';
            end
            
            if isequal(length(Data),2)
                app.Button_Ternary.Visible = 'off';
                app.Button_RGB.Visible = 'off';
            end
            
            
            app.CheckBox_Masks.Value = 0;
            
            if ~isempty(app.MaskFile.Names)
                app.DropDown_Masks.Items = app.MaskFile.Names;
                app.DropDown_Masks.ItemsData = [1:length(app.MaskFile.Names)];
                app.DropDown_Masks.Value = 1;
                
                app.DropDown_Masks_2.Items = app.MaskFile.Masks(app.DropDown_Masks.Value).Names;
                app.DropDown_Masks_2.ItemsData = [0:length(app.MaskFile.Masks(app.DropDown_Masks.Value).Names)-1];
                app.DropDown_Masks_2.Value = 0;
            else
                app.DropDown_Masks.Visible = 'off';
                app.DropDown_Masks_2.Visible = 'off';
                app.DropDown_Masks_2.Items = {''};
                app.DropDown_Masks_2.ItemsData = 0;
                app.DropDown_Masks_2.Value = 0;
            end
            
            if length(Data) > 2
                app.DropDown_Z.Items = Names;
                app.DropDown_Z.ItemsData = [1:length(Data)];
                app.CheckBox_Z_Menu.Value = 1;
                app.CheckBox_Z_Manual.Value = 0;
                
                if length(Data) >= Selected+2
                    app.DropDown_Z.Value =  Selected+2;
                else
                    app.DropDown_Z.Value = 3;
                end
                
            else
                app.EditField_Z.Visible = 'off';
                app.CheckBox_Z_Manual.Visible = 'off';
                app.DropDown_Z.Visible = 'off';
                app.CheckBox_Z_Menu.Visible = 'off';
            end
            
            movegui(app.VisualizationTool,"center");
            
            app.VisualizationTool.Visible = 'on'; % Can be improved if waitbar deactivated while Visible is off
            
            CheckState(app);
        end

        % Value changed function: Button_Ternary
        function Button_TernaryValueChanged(app, event)
            % Check if the button was not selected before
            if isequal(app.Button_RGB.Value,0) && isequal(app.Button_Binary.Value,0) && isequal(app.Button_Histogram.Value,0)
                app.Button_Ternary.Value = 1;
                return
            else
                app.Button_RGB.Value = 0;
                app.Button_Binary.Value = 0;
                app.Button_Histogram.Value = 0;
            end
            
            CheckState(app);
        end

        % Value changed function: Button_RGB
        function Button_RGBValueChanged(app, event)
            % Check if the button was not selected before
            if isequal(app.Button_Ternary.Value,0) && isequal(app.Button_Binary.Value,0) && isequal(app.Button_Histogram.Value,0)
                app.Button_RGB.Value = 1;
                return
            else
                app.Button_Ternary.Value = 0;
                app.Button_Binary.Value = 0;
                app.Button_Histogram.Value = 0;
            end
            
            CheckState(app);
        end

        % Value changed function: Button_Binary
        function Button_BinaryValueChanged(app, event)
            % Check if the button was not selected before
            if isequal(app.Button_Ternary.Value,0) && isequal(app.Button_RGB.Value,0) && isequal(app.Button_Histogram.Value,0)
                app.Button_Binary.Value = 1;
                return
            else
                app.Button_Ternary.Value = 0;
                app.Button_RGB.Value = 0;
                app.Button_Histogram.Value = 0;
            end
            
            CheckState(app);
        end

        % Value changed function: Button_Histogram
        function Button_HistogramValueChanged(app, event)
            
            % Check if the button was not selected before
            if isequal(app.Button_Ternary.Value,0) && isequal(app.Button_RGB.Value,0) && isequal(app.Button_Binary.Value,0)
                app.Button_Histogram.Value = 1;
                return
            else
                app.Button_Ternary.Value = 0;
                app.Button_RGB.Value = 0;
                app.Button_Binary.Value = 0;
            end
            
            CheckState(app);
        end

        % Value changed function: DropDown_X
        function DropDown_XValueChanged(app, event)
            PlotData(app);
        end

        % Value changed function: DropDown_Y
        function DropDown_YValueChanged(app, event)
            PlotData(app);
        end

        % Value changed function: DropDown_Z
        function DropDown_ZValueChanged(app, event)
            PlotData(app);
        end

        % Button pushed function: AutoZoom
        function AutoZoomButtonPushed(app, event)
            
            if isequal(app.Button_Binary.Value,1)
                
                PreviousAxisValues = axis(app.FigPlot1);
                XminP = PreviousAxisValues(1);
                XmaxP = PreviousAxisValues(2);
                YminP = PreviousAxisValues(3);
                YmaxP = PreviousAxisValues(4);
                
                X = app.LastPlot.X;
                Y = app.LastPlot.Y;
                
                X = X(X >= XminP & X <= XmaxP);
                Y = Y(Y >= YminP & Y <= YmaxP);
                
                SortedDataX = sort(X(:));
                SortedDataY = sort(Y(:));
                SelCrit = round(numel(SortedDataX) * 0.0001);
                
                MinX1 = SortedDataX(SelCrit);
                MaxX1 = SortedDataX(end-SelCrit);
                
                MinY1 = SortedDataY(SelCrit);
                MaxY1 = SortedDataY(end-SelCrit);
                
                % Current values:
                axis(app.FigPlot1,[MinX1,MaxX1,MinY1,MaxY1])
                
                app.WaitBar = uiprogressdlg(app.VisualizationTool,'Title','Please Wait!','Indeterminate','on');
                app.WaitBar.Message = 'Generating density map';
                plotDensityBin(app)
                close(app.WaitBar)
            end
            
            
        end

        % Button pushed function: ResetZoom
        function ResetZoomButtonPushed(app, event)
            
            if isequal(app.Button_Binary.Value,1)
                X = app.LastPlot.X;
                Y = app.LastPlot.Y;
                
                axis(app.FigPlot1,[min(X(:)),max(X(:)),min(Y(:)),max(Y(:))]);
                
                app.WaitBar = uiprogressdlg(app.VisualizationTool,'Title','Please Wait!','Indeterminate','on');
                app.WaitBar.Message = 'Generating density map';
                plotDensityBin(app)
                close(app.WaitBar)
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if isequal(selectedTab.Title,'Options')
                if isequal(app.Button_Binary.Value,1)
                    app.TabGroup2.SelectedTab = app.Tab_Options_Binary;
                    
                end
                if isequal(app.Button_Histogram.Value,1)
                    app.TabGroup2.SelectedTab = app.Tab_Options_Hist;
                end
                
            end


        end

        % Value changed function: histogramwithlogYscaleCheckBox
        function histogramwithlogYscaleCheckBoxValueChanged(app, event)
            PlotData(app);
        end

        % Value changed function: Field_Xmax, Field_Xmin, 
        % Field_Ymax, Field_Ymin
        function Field_XminXMaxYminYmaxValueChanged(app, event)
            
            if isequal(app.Button_Binary.Value,1)
                
                % Main figure
                axis(app.FigPlot1,[app.Field_Xmin.Value,app.Field_Xmax.Value,app.Field_Ymin.Value,app.Field_Ymax.Value]);
                
                % Histograms
                app.FigPlot3.XLim = [app.Field_Xmin.Value,app.Field_Xmax.Value];
                app.FigPlot4.XLim = [app.Field_Ymin.Value,app.Field_Ymax.Value];
                
                axis(app.FigPlot5,[app.Field_Xmin.Value,app.Field_Xmax.Value,app.Field_Ymin.Value,app.Field_Ymax.Value]);
                
                app.WaitBar = uiprogressdlg(app.VisualizationTool,'Title','Please Wait!','Indeterminate','on');
                app.WaitBar.Message = 'Generating density map';
                plotDensityBin(app)
                close(app.WaitBar)
                
            end
            
            
            %keyboard
        end

        % Button pushed function: 
        % Explore_IdentifyPixels_PolygonButton
        function Explore_IdentifyPixels_PolygonButtonPushed(app, event)
            
            if isequal(app.Button_Binary.Value,1) || isequal(app.Button_Ternary.Value,1)
                
                app.ROI_PI_PolygonSingle = drawpolygon(app.FigPlot1,'Color',[1,0,0]);
                app.ROI_PI_PolygonSingle_Listener = addlistener(app.ROI_PI_PolygonSingle, 'ROIMoved', @(varargin)ROI_ShapeChangedSingle(app, app.ROI_PI_PolygonSingle));
                
                app.ROI_PI_PolygonSingle_Dens = drawpolygon(app.FigPlot5,'Position',app.ROI_PI_PolygonSingle.Position,'Color',[1,0,0]);
                app.ROI_PI_PolygonSingle_Dens_Listener = addlistener(app.ROI_PI_PolygonSingle_Dens, 'ROIMoved', @(varargin)ROI_ShapeChangedSingleDens(app, app.ROI_PI_PolygonSingle_Dens));
                
                PixelIndentifier(app,'Single');
            end
           
        end

        % Value changed function: CheckBox_Masks
        function CheckBox_MasksValueChanged(app, event)
            PlotData(app);
        end

        % Value changed function: DropDown_Masks
        function DropDown_MasksValueChanged(app, event)
            PlotData(app);
        end

        % Button pushed function: ManualZoom
        function ManualZoomButtonPushed(app, event)
            
            Rect = drawrectangle(app.FigPlot1,'color',[0,0,0]);
            
            Position = Rect.Position;
            Rect.delete;
            
            axis(app.FigPlot1,[Position(1),Position(1)+Position(3),Position(2),Position(2)+Position(4)])
            
            app.WaitBar = uiprogressdlg(app.VisualizationTool,'Title','Please Wait!','Indeterminate','on');
            app.WaitBar.Message = 'Generating density map';
            plotDensityBin(app)
            close(app.WaitBar)


        end

        % Value changed function: CheckBox_X_Manual, 
        % CheckBox_Y_Manual, CheckBox_Z_Manual
        function ManualSelectionCheckBox_ValueChanged(app, event)
            Update = 1;
            if isequal(app.CheckBox_X_Manual.Value,1)
                app.CheckBox_X_Menu.Enable = 'on';
                app.CheckBox_X_Menu.Value = 0;
                app.CheckBox_X_Manual.Enable = 'off';
                app.EditField_X.Enable = 'on';
                app.DropDown_X.Enable = 'off';
                
                if isempty(app.EditField_X.Value)
                    app.EditField_X.Value = char(app.DropDown_X.Items(app.DropDown_X.Value));
                    Update = 0;
                end
                
            else
                app.CheckBox_X_Menu.Enable = 'off';
                app.CheckBox_X_Menu.Value = 1;
                app.CheckBox_X_Manual.Enable = 'on';
                app.EditField_X.Enable = 'off';
                app.DropDown_X.Enable = 'on';
            end
            
            if isequal(app.CheckBox_Y_Manual.Value,1)
                app.CheckBox_Y_Menu.Enable = 'on';
                app.CheckBox_Y_Menu.Value = 0;
                app.CheckBox_Y_Manual.Enable = 'off';
                app.EditField_Y.Enable = 'on';
                app.DropDown_Y.Enable = 'off';
                
                if isempty(app.EditField_Y.Value)
                    app.EditField_Y.Value = char(app.DropDown_Y.Items(app.DropDown_Y.Value));
                    Update = 0;
                end
                
            else
                app.CheckBox_Y_Menu.Enable = 'off';
                app.CheckBox_Y_Menu.Value = 1;
                app.CheckBox_Y_Manual.Enable = 'on';
                app.EditField_Y.Enable = 'off';
                app.DropDown_Y.Enable = 'on';
            end
            
            if isequal(app.CheckBox_Z_Manual.Value,1)
                app.CheckBox_Z_Menu.Enable = 'on';
                app.CheckBox_Z_Menu.Value = 0;
                app.CheckBox_Z_Manual.Enable = 'off';
                app.EditField_Z.Enable = 'on';
                app.DropDown_Z.Enable = 'off';
                
                if isempty(app.EditField_Z.Value)
                    app.EditField_Z.Value = char(app.DropDown_Z.Items(app.DropDown_Z.Value));
                    Update = 0;
                end
                
            else
                app.CheckBox_Z_Menu.Enable = 'off';
                app.CheckBox_Z_Menu.Value = 1;
                app.CheckBox_Z_Manual.Enable = 'on';
                app.EditField_Z.Enable = 'off';
                app.DropDown_Z.Enable = 'on';
            end
            
            if Update
                PlotData(app);
            end
            
        end

        % Value changed function: CheckBox_X_Menu, CheckBox_Y_Menu, 
        % CheckBox_Z_Menu
        function MenuSelectionCheckBox_ValueChanged(app, event)
            
            if isequal(app.CheckBox_X_Menu.Value,1)
                app.CheckBox_X_Manual.Enable = 'on';
                app.CheckBox_X_Manual.Value = 0;
                app.CheckBox_X_Menu.Enable = 'off';
                app.EditField_X.Enable = 'off';
                app.DropDown_X.Enable = 'on';
            else
                app.CheckBox_X_Manual.Enable = 'off';
                app.CheckBox_X_Manual.Value = 1;
                app.CheckBox_X_Menu.Enable = 'on';
                app.EditField_X.Enable = 'on';
                app.DropDown_X.Enable = 'off';
            end
            
            if isequal(app.CheckBox_Y_Menu.Value,1)
                app.CheckBox_Y_Manual.Enable = 'on';
                app.CheckBox_Y_Manual.Value = 0;
                app.CheckBox_Y_Menu.Enable = 'off';
                app.EditField_Y.Enable = 'off';
                app.DropDown_Y.Enable = 'on';
            else
                app.CheckBox_Y_Manual.Enable = 'off';
                app.CheckBox_Y_Manual.Value = 1;
                app.CheckBox_Y_Menu.Enable = 'on';
                app.EditField_Y.Enable = 'on';
                app.DropDown_Y.Enable = 'off';
            end
            
            if isequal(app.CheckBox_Z_Menu.Value,1)
                app.CheckBox_Z_Manual.Enable = 'on';
                app.CheckBox_Z_Manual.Value = 0;
                app.CheckBox_Z_Menu.Enable = 'off';
                app.EditField_Z.Enable = 'off';
                app.DropDown_Z.Enable = 'on';
            else
                app.CheckBox_Z_Manual.Enable = 'off';
                app.CheckBox_Z_Manual.Value = 1;
                app.CheckBox_Z_Menu.Enable = 'on';
                app.EditField_Y.Enable = 'on';
                app.DropDown_Z.Enable = 'off';
            end
            
            PlotData(app);
        end

        % Value changed function: EditField_X, EditField_Y, 
        % EditField_Z
        function EditField_XYZValueChanged(app, event)
            PlotData(app);
        end

        % Close request function: VisualizationTool
        function VisualizationToolCloseRequest(app, event)
            diary('off');
            delete(app)
        end

        % Value changed function: CheckBox_LowRessourcesMode
        function CheckBox_LowRessourcesModeValueChanged(app, event)
            PlotData(app);
        end

        % Button pushed function: Button_help
        function Button_helpPushed(app, event)
            if isempty(app.XMapToolsApp.Id_HelpTool)
                Help_Display(app.XMapToolsApp,'XMT_help_DataVizualizationTools.html');
            else
                app.XMapToolsApp.Id_HelpTool.UpdateTextHelp('XMT_help_DataVizualizationTools.html');
            end
        end

        % Value changed function: DropDown_Masks_2
        function DropDown_Masks_2ValueChanged(app, event)
            PlotData(app);
        end

        % Value changed function: CheckBox_FilterZeros
        function CheckBox_FilterZerosValueChanged(app, event)
            PlotData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualizationTool = uifigure('Visible', 'off');
            app.VisualizationTool.Name = 'Data Visualization Module – XMapTools';
            app.VisualizationTool.CloseRequestFcn = createCallbackFcn(app, @VisualizationToolCloseRequest, true);
            app.GridLayout = uigridlayout(app.VisualizationTool);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo_xmap_final.png';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.GridLayout7_8 = uigridlayout(app.PlotTab);
            app.VariableDefinitionsLabel = uilabel(app.GridLayout7_8);
            app.VariableDefinitionsLabel.Text = 'Variable Definitions';
            app.DropDown_X = uidropdown(app.GridLayout7_8);
            app.DropDown_X.Items = {'Option X'};
            app.DropDown_X.ValueChangedFcn = createCallbackFcn(app, @DropDown_XValueChanged, true);
            app.DropDown_X.Value = 'Option X';
            app.CheckBox_X_Menu = uicheckbox(app.GridLayout7_8);
            app.CheckBox_X_Menu.ValueChangedFcn = createCallbackFcn(app, @MenuSelectionCheckBox_ValueChanged, true);
            app.CheckBox_X_Menu.Text = '';
            app.CheckBox_X_Menu.Value = true;
            app.CheckBox_X_Manual = uicheckbox(app.GridLayout7_8);
            app.CheckBox_X_Manual.ValueChangedFcn = createCallbackFcn(app, @ManualSelectionCheckBox_ValueChanged, true);
            app.CheckBox_X_Manual.Text = '';
            app.CheckBox_X_Manual.Value = true;
            app.EditField_X = uieditfield(app.GridLayout7_8, 'text');
            app.EditField_X.ValueChangedFcn = createCallbackFcn(app, @EditField_XYZValueChanged, true);
            app.CheckBox_Y_Menu = uicheckbox(app.GridLayout7_8);
            app.CheckBox_Y_Menu.ValueChangedFcn = createCallbackFcn(app, @MenuSelectionCheckBox_ValueChanged, true);
            app.CheckBox_Y_Menu.Text = '';
            app.CheckBox_Y_Menu.Value = true;
            app.DropDown_Y = uidropdown(app.GridLayout7_8);
            app.DropDown_Y.Items = {'Option Y'};
            app.DropDown_Y.ValueChangedFcn = createCallbackFcn(app, @DropDown_YValueChanged, true);
            app.DropDown_Y.Value = 'Option Y';
            app.CheckBox_Y_Manual = uicheckbox(app.GridLayout7_8);
            app.CheckBox_Y_Manual.ValueChangedFcn = createCallbackFcn(app, @ManualSelectionCheckBox_ValueChanged, true);
            app.CheckBox_Y_Manual.Text = '';
            app.CheckBox_Y_Manual.Value = true;
            app.EditField_Y = uieditfield(app.GridLayout7_8, 'text');
            app.EditField_Y.ValueChangedFcn = createCallbackFcn(app, @EditField_XYZValueChanged, true);
            app.CheckBox_Z_Menu = uicheckbox(app.GridLayout7_8);
            app.CheckBox_Z_Menu.ValueChangedFcn = createCallbackFcn(app, @MenuSelectionCheckBox_ValueChanged, true);
            app.CheckBox_Z_Menu.Text = '';
            app.CheckBox_Z_Menu.Value = true;
            app.DropDown_Z = uidropdown(app.GridLayout7_8);
            app.DropDown_Z.Items = {'Option Z'};
            app.DropDown_Z.ValueChangedFcn = createCallbackFcn(app, @DropDown_ZValueChanged, true);
            app.DropDown_Z.Value = 'Option Z';
            app.CheckBox_Z_Manual = uicheckbox(app.GridLayout7_8);
            app.CheckBox_Z_Manual.ValueChangedFcn = createCallbackFcn(app, @ManualSelectionCheckBox_ValueChanged, true);
            app.CheckBox_Z_Manual.Text = '';
            app.CheckBox_Z_Manual.Value = true;
            app.EditField_Z = uieditfield(app.GridLayout7_8, 'text');
            app.EditField_Z.ValueChangedFcn = createCallbackFcn(app, @EditField_XYZValueChanged, true);
            app.Label_X = uilabel(app.GridLayout7_8);
            app.Label_X.Text = 'X';
            app.Label_Y = uilabel(app.GridLayout7_8);
            app.Label_Y.Text = 'Y';
            app.Label_Z = uilabel(app.GridLayout7_8);
            app.Label_Z.Text = 'Z';
            app.Button_Histogram = uibutton(app.GridLayout7_8, 'state');
            app.Button_Histogram.ValueChangedFcn = createCallbackFcn(app, @Button_HistogramValueChanged, true);
            app.Button_Histogram.Icon = 'XXX_stats.png';
            app.Button_Histogram.Text = 'Histogram';
            app.Button_Binary = uibutton(app.GridLayout7_8, 'state');
            app.Button_Binary.ValueChangedFcn = createCallbackFcn(app, @Button_BinaryValueChanged, true);
            app.Button_Binary.Icon = 'XXX_chess.png';
            app.Button_Binary.Text = 'Binary';
            app.Button_RGB = uibutton(app.GridLayout7_8, 'state');
            app.Button_RGB.ValueChangedFcn = createCallbackFcn(app, @Button_RGBValueChanged, true);
            app.Button_RGB.Icon = 'XXX_images.png';
            app.Button_RGB.Text = 'RGB';
            app.Button_Ternary = uibutton(app.GridLayout7_8, 'state');
            app.Button_Ternary.ValueChangedFcn = createCallbackFcn(app, @Button_TernaryValueChanged, true);
            app.Button_Ternary.Icon = 'XXX_3d_modelling.png';
            app.Button_Ternary.Text = 'Ternary';
            app.PlottingModeLabel = uilabel(app.GridLayout7_8);
            app.PlottingModeLabel.Text = 'Plotting Mode';
            app.CheckBox_Masks = uicheckbox(app.GridLayout7_8);
            app.CheckBox_Masks.ValueChangedFcn = createCallbackFcn(app, @CheckBox_MasksValueChanged, true);
            app.CheckBox_Masks.Text = '';
            app.CheckBox_Masks.Value = true;
            app.Label_Maks = uilabel(app.GridLayout7_8);
            app.Label_Maks.Text = 'Masks';
            app.DropDown_Masks = uidropdown(app.GridLayout7_8);
            app.DropDown_Masks.Items = {'Option Masks'};
            app.DropDown_Masks.ValueChangedFcn = createCallbackFcn(app, @DropDown_MasksValueChanged, true);
            app.DropDown_Masks.Value = 'Option Masks';
            app.Label_Maks_2 = uilabel(app.GridLayout7_8);
            app.Label_Maks_2.Text = 'Low resource mode ';
            app.CheckBox_LowRessourcesMode = uicheckbox(app.GridLayout7_8);
            app.CheckBox_LowRessourcesMode.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LowRessourcesModeValueChanged, true);
            app.CheckBox_LowRessourcesMode.Text = '';
            app.CheckBox_LowRessourcesMode.Value = true;
            app.DropDown_Masks_2 = uidropdown(app.GridLayout7_8);
            app.DropDown_Masks_2.Items = {'Option Masks'};
            app.DropDown_Masks_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_Masks_2ValueChanged, true);
            app.DropDown_Masks_2.Value = 'Option Masks';
            app.CheckBox_FilterZeros = uicheckbox(app.GridLayout7_8);
            app.CheckBox_FilterZeros.ValueChangedFcn = createCallbackFcn(app, @CheckBox_FilterZerosValueChanged, true);
            app.CheckBox_FilterZeros.Text = '';
            app.Label_Maks_3 = uilabel(app.GridLayout7_8);
            app.Label_Maks_3.Text = 'Filter zeros';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.GridLayout5 = uigridlayout(app.OptionsTab);
            app.TabGroup2 = uitabgroup(app.GridLayout5);
            app.Tab_Options_Hist = uitab(app.TabGroup2);
            app.Tab_Options_Hist.Title = 'Hist';
            app.GridLayout7_2 = uigridlayout(app.Tab_Options_Hist);
            app.Tab_Options_Binary = uitab(app.TabGroup2);
            app.Tab_Options_Binary.Title = 'Binary';
            app.GridLayout7 = uigridlayout(app.Tab_Options_Binary);
            app.histogramwithlogYscaleCheckBox = uicheckbox(app.GridLayout7);
            app.histogramwithlogYscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @histogramwithlogYscaleCheckBoxValueChanged, true);
            app.histogramwithlogYscaleCheckBox.Text = 'histogram with log Y-scale ';
            app.histogramwithlogYscaleCheckBox.Value = true;
            app.Tab_Options_RGB = uitab(app.TabGroup2);
            app.Tab_Options_RGB.Title = 'RGB';
            app.GridLayout7_3 = uigridlayout(app.Tab_Options_RGB);
            app.Tab_Options_Ternary = uitab(app.TabGroup2);
            app.Tab_Options_Ternary.Title = '3D';
            app.GridLayout7_4 = uigridlayout(app.Tab_Options_Ternary);
            app.ExploreTab = uitab(app.TabGroup);
            app.ExploreTab.Title = 'Explore';
            app.GridLayout7_5 = uigridlayout(app.ExploreTab);
            app.IdentifyPixelsToolsLabel = uilabel(app.GridLayout7_5);
            app.IdentifyPixelsToolsLabel.Text = 'Identify Pixels Tools';
            app.Explore_IdentifyPixels_PolygonButton = uibutton(app.GridLayout7_5, 'push');
            app.Explore_IdentifyPixels_PolygonButton.ButtonPushedFcn = createCallbackFcn(app, @Explore_IdentifyPixels_PolygonButtonPushed, true);
            app.Explore_IdentifyPixels_PolygonButton.Icon = 'XXX_pin_rounded_plus.png';
            app.Explore_IdentifyPixels_PolygonButton.Text = 'Polygon';
            app.ClassifyTab = uitab(app.TabGroup);
            app.ClassifyTab.Title = 'Classify';
            app.GridLayout7_6 = uigridlayout(app.ClassifyTab);
            app.StatisticsOtherTab = uitab(app.TabGroup);
            app.StatisticsOtherTab.Title = 'Statistics & Other';
            app.GridLayout7_7 = uigridlayout(app.StatisticsOtherTab);
            app.Statistics_TextArea = uitextarea(app.GridLayout7_7);
            app.Statistics_TextArea.Editable = 'off';
            app.Plotting_TextArea = uitextarea(app.GridLayout7_7);
            app.Plotting_TextArea.Editable = 'off';
            app.StatisticsPlottedDataLabel = uilabel(app.GridLayout7_7);
            app.StatisticsPlottedDataLabel.Text = 'Statistics – Plotted Data';
            app.PlottingReportlastplotonlyLabel = uilabel(app.GridLayout7_7);
            app.PlottingReportlastplotonlyLabel.Text = 'Plotting Report (last plot only)';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Button_help = uibutton(app.GridLayout2, 'push');
            app.Button_help.ButtonPushedFcn = createCallbackFcn(app, @Button_helpPushed, true);
            app.Button_help.Icon = '061-info.png';
            app.Button_help.Text = '';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.AutoZoom = uibutton(app.GridLayout6, 'push');
            app.AutoZoom.ButtonPushedFcn = createCallbackFcn(app, @AutoZoomButtonPushed, true);
            app.AutoZoom.Icon = 'XXX_magic-wand.png';
            app.AutoZoom.Text = '';
            app.ResetZoom = uibutton(app.GridLayout6, 'push');
            app.ResetZoom.ButtonPushedFcn = createCallbackFcn(app, @ResetZoomButtonPushed, true);
            app.ResetZoom.Icon = 'XXX_home.png';
            app.ResetZoom.Text = '';
            app.YminLabel = uilabel(app.GridLayout6);
            app.YminLabel.Text = 'Ymin';
            app.ManualZoom = uibutton(app.GridLayout6, 'push');
            app.ManualZoom.ButtonPushedFcn = createCallbackFcn(app, @ManualZoomButtonPushed, true);
            app.ManualZoom.Icon = 'XXX_zoom_in.png';
            app.ManualZoom.Text = '';
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.Field_Ymax = uieditfield(app.GridLayout8, 'numeric');
            app.Field_Ymax.ValueChangedFcn = createCallbackFcn(app, @Field_XminXMaxYminYmaxValueChanged, true);
            app.YmaxLabel = uilabel(app.GridLayout8);
            app.YmaxLabel.Text = 'Ymax';
            app.GridLayout8_2 = uigridlayout(app.GridLayout);
            app.Field_Ymin = uieditfield(app.GridLayout8_2, 'numeric');
            app.Field_Ymin.ValueChangedFcn = createCallbackFcn(app, @Field_XminXMaxYminYmaxValueChanged, true);
            app.GridLayout9 = uigridlayout(app.GridLayout);
            app.Field_Xmin = uieditfield(app.GridLayout9, 'numeric');
            app.Field_Xmin.ValueChangedFcn = createCallbackFcn(app, @Field_XminXMaxYminYmaxValueChanged, true);
            app.XminLabel = uilabel(app.GridLayout9);
            app.XminLabel.Text = 'Xmin';
            app.GridLayout9_2 = uigridlayout(app.GridLayout);
            app.Field_Xmax = uieditfield(app.GridLayout9_2, 'numeric');
            app.Field_Xmax.ValueChangedFcn = createCallbackFcn(app, @Field_XminXMaxYminYmaxValueChanged, true);
            app.XmaxLabel = uilabel(app.GridLayout9_2);
            app.XmaxLabel.Text = 'Xmax';
            app.FigPlot2 = uiaxes(app.GridLayout);
            title(app.FigPlot2, 'Title')
            xlabel(app.FigPlot2, 'X')
            ylabel(app.FigPlot2, 'Y')
            zlabel(app.FigPlot2, 'Z')
            app.FigPlot3 = uiaxes(app.GridLayout);
            title(app.FigPlot3, 'Title')
            xlabel(app.FigPlot3, 'X')
            ylabel(app.FigPlot3, 'Y')
            zlabel(app.FigPlot3, 'Z')
            app.FigPlot1 = uiaxes(app.GridLayout);
            title(app.FigPlot1, 'Title')
            xlabel(app.FigPlot1, 'X')
            ylabel(app.FigPlot1, 'Y')
            zlabel(app.FigPlot1, 'Z')
            app.FigPlot6 = uiaxes(app.GridLayout);
            title(app.FigPlot6, 'Title')
            xlabel(app.FigPlot6, 'X')
            ylabel(app.FigPlot6, 'Y')
            zlabel(app.FigPlot6, 'Z')
            app.FigPlot5 = uiaxes(app.GridLayout);
            title(app.FigPlot5, 'Title')
            xlabel(app.FigPlot5, 'X')
            ylabel(app.FigPlot5, 'Y')
            zlabel(app.FigPlot5, 'Z')
            app.FigPlot4 = uiaxes(app.GridLayout);
            title(app.FigPlot4, 'Title')
            xlabel(app.FigPlot4, 'X')
            ylabel(app.FigPlot4, 'Y')
            zlabel(app.FigPlot4, 'Z')
            % Show the figure after all components are created
            app.VisualizationTool.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Visualization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualizationTool)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualizationTool)
        end
    end
end