classdef Help_Display < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        XMapToolsDocumentationHelpUIFigure  matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Image        matlab.ui.control.Image
        GridLayout3  matlab.ui.container.GridLayout
        GridLayout2  matlab.ui.container.GridLayout
        HTML         matlab.ui.control.HTML
    end

    
    properties (Access = private)
        text % Description
        XMapToolsApp
        
        TestMode
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)


        function FormatText2HTML(app)
            
            app.text = regexprep(app.text, '\n', '<br>'); 
            app.text = regexprep(app.text, ' ', '&nbsp;');
            
            Style1 = '<p style="font-family:monospace">';

            app.text = [Style1,app.text,'</p>'];
            
        end
    end
    
    methods (Access = public)
        
        function UpdateTextHelp(app,HelpPage)
            if app.TestMode 
                disp(' ')
                disp(' ')
                disp('Help module called by user')
                disp(['File: ',HelpPage])
            end
           
            if isequal(HelpPage(end-3:end),'html')
                app.HTML.HTMLSource = fullfile(app.XMapToolsApp.config.xmaptools.setup_path,'Dev','help',HelpPage);
                
                if app.TestMode 
                    disp(['app.HTML.HTMLSource = ',app.HTML.HTMLSource])
                end
            else
                try 
                    HelpPage = [HelpPage,'.m'];
                    if app.TestMode
                        disp(['File changed to: ',HelpPage])
                    end
                    CheckFile = fullfile(app.XMapToolsApp.config.xmaptools.setup_path,'External','SF',HelpPage);
                    if exist(CheckFile)
                        app.text = help(CheckFile);
                    end
                    CheckFile = fullfile(app.XMapToolsApp.config.xmaptools.setup_path,'External','ME',HelpPage);
                    if exist(CheckFile)
                        app.text = help(CheckFile);
                    end
                    CheckFile = fullfile(app.XMapToolsApp.config.xmaptools.setup_path,'External','TB',HelpPage);
                    if exist(CheckFile)
                        app.text = help(CheckFile);
                    end
                    FormatText2HTML(app);
                    app.HTML.HTMLSource = app.text;
                catch ME
                    delete(app)
                    return
                end
            end
            
            
%             try
%                 app.text = fileread(fullfile(app.XMapToolsApp.config.xmaptools.setup_path,'Dev','help',HelpPage));
%                 
%             catch
%                 try
%                     app.text = help(HelpPage);
%                     FormatText2HTML(app);
%                 catch
%                     delete(app)
%                     return
%                 end
%             end            
%             app.HTML.HTMLSource = app.text;
            
            if app.TestMode
                disp('** Function UpdateTextHelp called from XMapTools')
                %disp(['app.HTML.HTMLSource = ',app.HTML.HTMLSource])
            end
            
            figure(app.XMapToolsDocumentationHelpUIFigure);
            
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, XMapToolsApp, HelpPage)
            app.XMapToolsDocumentationHelpUIFigure.Visible = 'off';
            
            app.TestMode = 1;
            
            movegui(app.XMapToolsDocumentationHelpUIFigure,'center'); 
            
            app.XMapToolsApp = XMapToolsApp;
            app.XMapToolsApp.Id_HelpTool = app;
            
            if app.TestMode 
                disp(' ')
                disp(' ')
                disp('Help module activated by user')
                disp(['File: ',HelpPage])
            end
            
            
            if isequal(HelpPage(end-3:end),'html')  
                app.HTML.HTMLSource = '';
                app.HTML.HTMLSource = fullfile(app.XMapToolsApp.config.xmaptools.setup_path,'Dev','help',HelpPage);
                
                if app.TestMode 
                    disp(['app.HTML.HTMLSource = ',app.HTML.HTMLSource])
                end
            else
                HelpPage = [HelpPage,'.m'];
                if app.TestMode
                    disp(['File changed to: ',HelpPage])
                end
                try 
                    CheckFile = fullfile(app.XMapToolsApp.config.xmaptools.setup_path,'External','SF',HelpPage);
                    if exist(CheckFile)
                        app.text = help(CheckFile);
                    end
                    CheckFile = fullfile(app.XMapToolsApp.config.xmaptools.setup_path,'External','ME',HelpPage);
                    if exist(CheckFile)
                        app.text = help(CheckFile);
                    end
                    CheckFile = fullfile(app.XMapToolsApp.config.xmaptools.setup_path,'External','TB',HelpPage);
                    if exist(CheckFile)
                        app.text = help(CheckFile);
                    end
                    FormatText2HTML(app);
                    app.HTML.HTMLSource = app.text;
                catch 
                    disp(['File ',fullfile(app.XMapToolsApp.config.xmaptools.setup_path,'External','SF',HelpPage),' not found'])
                    delete(app)
                    return
                end
            end
            
            %app.HTML.HTMLSource = app.text;
            
            figure(app.XMapToolsDocumentationHelpUIFigure);
            
            app.XMapToolsDocumentationHelpUIFigure.Visible = 'on';
        end

        % Callback function
        function CloseButtonPushed(app, event)
            
            XMapToolsDocumentationHelpUIFigureCloseRequest(app, event)
            
        end

        % Close request function: XMapToolsDocumentationHelpUIFigure
        function XMapToolsDocumentationHelpUIFigureCloseRequest(app, event)
            
            app.XMapToolsApp.Id_HelpTool = [];
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.XMapToolsDocumentationHelpUIFigure = uifigure('Visible', 'off');
            app.XMapToolsDocumentationHelpUIFigure.Name = 'XMapTools Documentation & Help';
            app.XMapToolsDocumentationHelpUIFigure.CloseRequestFcn = createCallbackFcn(app, @XMapToolsDocumentationHelpUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.XMapToolsDocumentationHelpUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo_xmap_final.png';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.HTML = uihtml(app.GridLayout);
            % Show the figure after all components are created
            app.XMapToolsDocumentationHelpUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Help_Display(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.XMapToolsDocumentationHelpUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.XMapToolsDocumentationHelpUIFigure)
        end
    end
end