classdef ImageConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageConverterGUI       matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        Image                   matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image2_2                matlab.ui.control.Image
        Image2_3                matlab.ui.control.Image
        Panel                   matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        Label                   matlab.ui.control.Label
        LoadImageButton         matlab.ui.control.Button
        PanelApply              matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        ApplyButton             matlab.ui.control.Button
        Image2_4                matlab.ui.control.Image
        MaxvalueEditField       matlab.ui.control.NumericEditField
        MaxvalueEditFieldLabel  matlab.ui.control.Label
        MinvalueEditField       matlab.ui.control.NumericEditField
        MinvalueEditFieldLabel  matlab.ui.control.Label
        SaveMapButton           matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        XMapToolsApp
        WaitBar
        
        ImageRawData
        ImageConvertedData
        
        FileName
        
    end
    
    methods (Access = private)
        
        function updateplot(app)
            
            if ~isempty(app.ImageRawData)
                image(app.UIAxes,app.ImageRawData)
                axis(app.UIAxes,'image');
                
                app.UIAxes.Visible = "on";
                
                disableDefaultInteractivity(app.UIAxes);
                
                app.PanelApply.Visible = 'on';
            end
            
            if ~isempty(app.ImageConvertedData)
                imagesc(app.UIAxes_2,app.ImageConvertedData)
                axis(app.UIAxes_2,'image');
                if size(app.ImageConvertedData,2) > 1.2*size(app.ImageConvertedData,1)
                    colorbar(app.UIAxes_2,'horizontal');
                else
                    colorbar(app.UIAxes_2,'vertical');
                end
                colormap(app.UIAxes_2,app.XMapToolsApp.ColorMapValues_noMask);
                
                app.UIAxes_2.Visible = "on";
                
                disableDefaultInteractivity(app.UIAxes_2);
                
                app.SaveMapButton.Visible = 'on';
                
            end
            
        end
        
        function ApplyConversion(app)
            
            B = im2double(app.ImageRawData);
            
            if size(B,3) > 1
                B = rgb2gray(B);
            end
            
            Min = app.MinvalueEditField.Value;
            Max = app.MaxvalueEditField.Value;
            
            app.ImageConvertedData = Min + B.*(Max-Min);
            
            updateplot(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, XMapToolsApp)
            
            % XMapTools is a free software solution for the analysis of chemical maps
            % Copyright © 2022-2025 University of Lausanne, Institute of Earth Sciences, Pierre Lanari
            
            % XMapTools is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or any
            % later version.
            
            % XMapTools is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
            % GNU General Public License for more details.
            
            % You should have received a copy of the GNU General Public License
            % along with XMapTools. If not, see https://www.gnu.org/licenses.
            
            
            app.ImageConverterGUI.Visible = 'off';
            
            movegui(app.ImageConverterGUI,'center');
            
            app.XMapToolsApp = XMapToolsApp;
            
            app.UIAxes.Visible = 'off';
            app.UIAxes_2.Visible = 'off';
            app.PanelApply.Visible = 'off';
            app.SaveMapButton.Visible = 'off';
            
            app.ImageRawData = [];
            app.ImageConvertedData = [];
            
            app.ImageConverterGUI.Visible = 'on';
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            
            app.WaitBar = uiprogressdlg(app.ImageConverterGUI,'Title','XMapTools','Indeterminate','on');
            app.WaitBar.Message = 'Pick an image file';
            
            f=figure('Position',[1,1,5,5],'Unit','Pixel'); drawnow; f.Visible = 'off';
            [FileName,PathName] = uigetfile({'*.bmp;*.tif;*.tiff;*.png;*.jpg;*.jpeg','Image (*.bmp, *.tif, *.tiff, *.png, *.jpg, *,jpeg)';'*.*',  'All Files (*.*)'},'Pick a file');
            close(f);
            figure(app.ImageConverterGUI)
            if isempty(FileName)
                close(app.WaitBar);
                return
            end
            
            app.WaitBar.Message = 'Loading the image';
            
            app.ImageRawData = imread([PathName,FileName]);
            
            app.FileName = FileName;
            
            updateplot(app)
            
            close(app.WaitBar)
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            ApplyConversion(app);
            
        end

        % Value changed function: MinvalueEditField
        function MinvalueEditFieldValueChanged(app, event)
            if app.MinvalueEditField.Value >= app.MaxvalueEditField.Value
                app.MinvalueEditField.Value = app.MaxvalueEditField.Value - 1;
            end
            ApplyConversion(app);
        end

        % Value changed function: MaxvalueEditField
        function MaxvalueEditFieldValueChanged(app, event)
            if app.MinvalueEditField.Value >= app.MaxvalueEditField.Value
                app.MinvalueEditField.Value = app.MaxvalueEditField.Value - 1;
            end
            ApplyConversion(app);
        end

        % Button pushed function: SaveMapButton
        function SaveMapButtonPushed(app, event)
            
            app.WaitBar = uiprogressdlg(app.ImageConverterGUI,'Title','XMapTools','Indeterminate','on');
            app.WaitBar.Message = 'Save the file as...';
            
            NameStr = strread(app.FileName,'%s','delimiter','.');
            NameFileOri = char(NameStr{1});
            
            f=figure('Position',[1,1,5,5],'Unit','Pixel'); drawnow; f.Visible = 'off';
            [FileName,PathName] = uiputfile([NameFileOri,'.txt'], 'Save the map as...');
            close(f);
            figure(app.ImageConverterGUI)
            if isempty(FileName)
                close(app.WaitBar);
                return
            end
            
            app.WaitBar.Message = 'Saving the file';
            
            B = app.ImageConvertedData;
            
            save([PathName,FileName],'B','-ASCII');
            
            close(app.WaitBar)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageConverterGUI = uifigure('Visible', 'off');
            app.ImageConverterGUI.Name = 'Image Converter – XMapTools';
            app.GridLayout = uigridlayout(app.ImageConverterGUI);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo_xmap_final.png';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = 'IMGConv_image.jpg';
            app.Image2_2 = uiimage(app.GridLayout);
            app.Image2_2.ImageSource = 'IMGConv_arrow.jpg';
            app.Image2_3 = uiimage(app.GridLayout);
            app.Image2_3.ImageSource = 'IMGConv_text.jpg';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = 'ImageConverter is a module that converts images into numerical values. Load an image file (.bmp, .tif, .tiff, .png, .jpg, .jpeg), check the conversion and save your file to a text file.';
            app.LoadImageButton = uibutton(app.GridLayout, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = '056-plus.png';
            app.LoadImageButton.Text = 'Load Image';
            app.PanelApply = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.PanelApply);
            app.ApplyButton = uibutton(app.GridLayout3, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Icon = '044-repeat.png';
            app.ApplyButton.Text = 'Apply';
            app.Image2_4 = uiimage(app.GridLayout3);
            app.Image2_4.ImageSource = 'IMGConv_arrow.jpg';
            app.MaxvalueEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaxvalueEditField.ValueChangedFcn = createCallbackFcn(app, @MaxvalueEditFieldValueChanged, true);
            app.MaxvalueEditField.Value = 1;
            app.MaxvalueEditFieldLabel = uilabel(app.GridLayout3);
            app.MaxvalueEditFieldLabel.Text = 'Max value';
            app.MinvalueEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MinvalueEditField.ValueChangedFcn = createCallbackFcn(app, @MinvalueEditFieldValueChanged, true);
            app.MinvalueEditFieldLabel = uilabel(app.GridLayout3);
            app.MinvalueEditFieldLabel.Text = 'Min value';
            app.SaveMapButton = uibutton(app.GridLayout, 'push');
            app.SaveMapButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMapButtonPushed, true);
            app.SaveMapButton.Icon = '096-upload.png';
            app.SaveMapButton.Text = 'Save Map';
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.GridLayout);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.ImageConverterGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageConverter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageConverterGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageConverterGUI)
        end
    end
end