classdef LogGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LogGeneratorGUI                 matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        ScanTab                         matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        X_StartLabel                    matlab.ui.control.Label
        X_startEditField                matlab.ui.control.NumericEditField
        Y_StartLabel                    matlab.ui.control.Label
        Y_startEditField                matlab.ui.control.NumericEditField
        LaserdiametermEditFieldLabel_2  matlab.ui.control.Label
        LaserDiameterEditField          matlab.ui.control.NumericEditField
        ScanspeedmsLabel                matlab.ui.control.Label
        LaserDiameterEditField_2        matlab.ui.control.NumericEditField
        ApplyButton                     matlab.ui.control.Button
        SpotTab                         matlab.ui.container.Tab
        GridLayout2_2                   matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        UITableSignalSelection          matlab.ui.control.Table
        Panel                           matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        SignalDefinitionTab             matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        SelectedFileName                matlab.ui.control.Label
        InfoSweep                       matlab.ui.control.Label
        Sweep_StartLabel                matlab.ui.control.Label
        Sweep1Field                     matlab.ui.control.NumericEditField
        Sweep_EndLabel                  matlab.ui.control.Label
        Sweep2Field                     matlab.ui.control.NumericEditField
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        ResetROIButton                  matlab.ui.control.Button
        AddROIButton                    matlab.ui.control.Button
        AddtotableButton                matlab.ui.control.Button
        APPLYTab                        matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        ApplytoSelectedButton           matlab.ui.control.Button
        ApplytoSameNameButton           matlab.ui.control.Button
        ApplytoAllButton                matlab.ui.control.Button
        Plot                            matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        RenameMenu                      matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        EliminateRowMenu                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        DataFiles % Description
        SelectedCells % Description
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DataFiles)
            %
            % XMapTools is a free software solution for the analysis of chemical maps
            % Copyright © 2022-2023 University of Bern, Institute of Geological Sciences, Pierre Lanari
            %
            % XMapTools is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or any
            % later version.
            %
            % XMapTools is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with XMapTools. If not, see https://www.gnu.org/licenses.
            
            app.LogGeneratorGUI.Visible = 'off';
            
            movegui(app.LogGeneratorGUI,"center");
            
            app.LogGeneratorGUI.Visible = 'on';
            
            
            app.DataFiles = DataFiles;
             
            app.UITable.ColumnFormat = {'char',{'Scan','Spot'},'numeric','numeric'};
            
            Data4Table = cell(length(app.DataFiles),4);
            
            for i = 1:length(app.DataFiles)
                Data4Table{i,1} = app.DataFiles(i).FileName(1:end-4);
                Data4Table{i,2} = 'Scan';
            end
            
            app.UITable.Data = Data4Table;
            
            
            % keyboard
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            
            answer = inputdlg('Enter new value','XMapTools');
            
            for i = 1:size(app.SelectedCells,1)
                app.UITable.Data(app.SelectedCells(i,1),app.SelectedCells(i,2)) = answer;
            end
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.SelectedCells = event.Indices;
            
            if isequal(size(app.SelectedCells,1),1)
                if isequal(app.SelectedCells(2),1)
                    Idx = app.SelectedCells(1);
                    
                    SignalSum = sum(app.DataFiles(Idx).DataCps,2);
                    
                    plot(app.Plot,SignalSum,'-')
                    app.Plot.YScale = 'log';
                    
                    app.SelectedFileName.Text = app.DataFiles(Idx).FileName;
                    app.InfoSweep.Text = ['with ',num2str(length(SignalSum)),' sweeps'];
                    
                end
            end

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if isequal(size(indices,1),1)
                if isequal(indices(2),2)
                    Name = app.UITable.Data(indices(1),indices(1));
                    ListNames = app.UITable.Data(:,1); 
                    
                    Idx = find(ismember(ListNames,Name));
                    
                    if length(Idx) > 1
                        Answer = uiconfirm(app.LogGeneratorGUI,'Do you want to apply this change to all measurements with identical names?','XMapTools','Options',{'Yes','No'},'DefaultOption','Yes');
                        for i = 1:length(Idx)
                            app.UITable.Data(Idx(i),2) = {newData};
                        end
                    end
                end
            end
        end

        % Menu selected function: EliminateRowMenu
        function EliminateRowMenuSelected(app, event)


            keyboard
        end

        % Button pushed function: ApplytoAllButton
        function ApplytoAllButtonPushed(app, event)
            
        end

        % Button pushed function: ApplytoSelectedButton
        function ApplytoSelectedButtonPushed(app, event)
            
        end

        % Button pushed function: ApplytoSameNameButton
        function ApplytoSameNameButtonPushed(app, event)
            
        end

        % Button pushed function: ResetROIButton
        function ResetROIButtonPushed(app, event)
            
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            
        end

        % Button pushed function: AddtotableButton
        function AddtotableButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LogGeneratorGUI = uifigure('Visible', 'off');
            app.LogGeneratorGUI.Name = 'Log Generator – XMapTools';
            app.GridLayout = uigridlayout(app.LogGeneratorGUI);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo_xmap_final.png';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.GridLayout2 = uigridlayout(app.ScanTab);
            app.X_StartLabel = uilabel(app.GridLayout2);
            app.X_StartLabel.Text = 'X_Start';
            app.X_startEditField = uieditfield(app.GridLayout2, 'numeric');
            app.X_startEditField.Value = 1;
            app.Y_StartLabel = uilabel(app.GridLayout2);
            app.Y_StartLabel.Text = 'Y_Start';
            app.Y_startEditField = uieditfield(app.GridLayout2, 'numeric');
            app.Y_startEditField.Value = 1;
            app.LaserdiametermEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.LaserdiametermEditFieldLabel_2.Text = 'Laser diameter (µm)';
            app.LaserDiameterEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LaserDiameterEditField.Value = 15;
            app.ScanspeedmsLabel = uilabel(app.GridLayout2);
            app.ScanspeedmsLabel.Text = 'Scan speed (µm/s)';
            app.LaserDiameterEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.LaserDiameterEditField_2.Value = 5;
            app.ApplyButton = uibutton(app.GridLayout2, 'push');
            app.ApplyButton.Text = 'Apply';
            app.SpotTab = uitab(app.TabGroup);
            app.SpotTab.Title = 'Spot';
            app.GridLayout2_2 = uigridlayout(app.SpotTab);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITableSignalSelection = uitable(app.GridLayout);
            app.UITableSignalSelection.ColumnName = {'#'; 'Sweep_Start'; 'Sweep_End'; 'Type'};
            app.UITableSignalSelection.RowName = {};
            app.UITableSignalSelection.ColumnEditable = [false true true true];
            app.Panel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.Panel);
            app.SignalDefinitionTab = uitab(app.TabGroup2);
            app.SignalDefinitionTab.Title = 'Signal Definition';
            app.GridLayout3 = uigridlayout(app.SignalDefinitionTab);
            app.SelectedFileName = uilabel(app.GridLayout3);
            app.InfoSweep = uilabel(app.GridLayout3);
            app.Sweep_StartLabel = uilabel(app.GridLayout3);
            app.Sweep_StartLabel.Text = 'Sweep_Start';
            app.Sweep1Field = uieditfield(app.GridLayout3, 'numeric');
            app.Sweep1Field.Value = 1;
            app.Sweep_EndLabel = uilabel(app.GridLayout3);
            app.Sweep_EndLabel.Text = 'Sweep_End';
            app.Sweep2Field = uieditfield(app.GridLayout3, 'numeric');
            app.Sweep2Field.Value = 2;
            app.TypeDropDownLabel = uilabel(app.GridLayout3);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.GridLayout3);
            app.TypeDropDown.Items = {'Background', 'Measurement', 'Other laser on', 'Other laser off'};
            app.TypeDropDown.ItemsData = [1 2 3 4];
            app.TypeDropDown.Value = 1;
            app.ResetROIButton = uibutton(app.GridLayout3, 'push');
            app.ResetROIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetROIButtonPushed, true);
            app.ResetROIButton.Text = 'Reset ROI';
            app.AddROIButton = uibutton(app.GridLayout3, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Text = 'Add ROI';
            app.AddtotableButton = uibutton(app.GridLayout3, 'push');
            app.AddtotableButton.ButtonPushedFcn = createCallbackFcn(app, @AddtotableButtonPushed, true);
            app.AddtotableButton.Text = 'Add to table';
            app.APPLYTab = uitab(app.TabGroup2);
            app.APPLYTab.Title = 'APPLY';
            app.GridLayout4 = uigridlayout(app.APPLYTab);
            app.ApplytoSelectedButton = uibutton(app.GridLayout4, 'push');
            app.ApplytoSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoSelectedButtonPushed, true);
            app.ApplytoSelectedButton.Text = 'Apply to Selected';
            app.ApplytoSameNameButton = uibutton(app.GridLayout4, 'push');
            app.ApplytoSameNameButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoSameNameButtonPushed, true);
            app.ApplytoSameNameButton.Text = 'Apply to Same Name';
            app.ApplytoAllButton = uibutton(app.GridLayout4, 'push');
            app.ApplytoAllButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoAllButtonPushed, true);
            app.ApplytoAllButton.Text = 'Apply to All';
            app.Plot = uiaxes(app.GridLayout);
            xlabel(app.Plot, 'Time')
            ylabel(app.Plot, 'Intensity')
            app.ContextMenu = uicontextmenu(app.LogGeneratorGUI);
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Text = 'Rename';
            app.ContextMenu2 = uicontextmenu(app.LogGeneratorGUI);
            % Assign app.ContextMenu2
            app.UITableSignalSelection.ContextMenu = app.ContextMenu2;
            app.EliminateRowMenu = uimenu(app.ContextMenu2);
            app.EliminateRowMenu.MenuSelectedFcn = createCallbackFcn(app, @EliminateRowMenuSelected, true);
            app.EliminateRowMenu.Text = 'Eliminate Row';
            % Show the figure after all components are created
            app.LogGeneratorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LogGenerator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LogGeneratorGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LogGeneratorGUI)
        end
    end
end