classdef MaskColorEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaskColorEditorGUI  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        UITable             matlab.ui.control.Table
        Image               matlab.ui.control.Image
        GridLayout2         matlab.ui.container.GridLayout
        AppyColor           matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        XMapToolsApp 
        MaskId
        
        Mask
        
        MineralColorData
        SelectedMineralColorData
        
        
        ActiveColorbar
    end
    
    methods (Access = private)
        
        function GenerateTable(app)
            
            app.UITable.ColumnFormat = {'',app.MineralColorData,''};
            
            Cell4Table = {};
            for i = 1:length(app.Mask.Names)
                Cell4Table{i,1} = app.Mask.Names{i};
                Cell4Table{i,2} = app.SelectedMineralColorData{i};
                Cell4Table{i,3} = '';
            end
            
            app.UITable.Data = Cell4Table;
            
            for i = 1:length(app.Mask.Names)
                s = uistyle('BackgroundColor',app.Mask.Colors(i,:));
                addStyle(app.UITable,s,'cell',[i,3]);
                
                s1 = uistyle('FontWeight','bold');
                addStyle(app.UITable,s1,'cell',[i,1]);
            end
            
        end
        
        
        function PlotMaskMap(app)
            
            imagesc(app.UIAxes,app.Mask.MaskMap);
            axis(app.UIAxes,'image');
            app.ActiveColorbar = colorbar(app.UIAxes,'Location','EastOutside');
            
            colormap(app.UIAxes,app.Mask.Colors);
            
            NbMasks = length(app.Mask.Names) - 1;
            
            caxis(app.UIAxes,[0 NbMasks+1]);
            
            app.ActiveColorbar.TickLabelsMode = 'manual';
            app.ActiveColorbar.Ticks = [0.5:1:NbMasks+2];
            app.ActiveColorbar.TickLabels = app.Mask.Names(1:end);
            
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, XMapToolsApp, MaskId)
            %
            % XMapTools is a free software solution for the analysis of chemical maps
            % Copyright © 2022-2023 University of Bern, Institute of Geological Sciences, Pierre Lanari
            %
            % XMapTools is free software: you can redistribute it and/or modify
            % it under the terms of the GNU General Public License as published by
            % the Free Software Foundation, either version 3 of the License, or any
            % later version.
            %
            % XMapTools is distributed in the hope that it will be useful,
            % but WITHOUT ANY WARRANTY; without even the implied warranty of
            % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
            % GNU General Public License for more details.
            %
            % You should have received a copy of the GNU General Public License
            % along with XMapTools. If not, see https://www.gnu.org/licenses.
            
            app.MaskColorEditorGUI.Visible = 'off';
            
            movegui(app.MaskColorEditorGUI,'center');
            
            app.XMapToolsApp = XMapToolsApp;
            app.MaskId = MaskId;
            
            app.Mask = app.XMapToolsApp.XMapToolsData.MapData.MaskFile.Masks(MaskId);
            
            app.MineralColorData = app.XMapToolsApp.MineralColorData.Names;
            app.MineralColorData{end+1} = 'Unavailable';
            app.MineralColorData{end+1} = 'Edited';
            
            % Initiate Selected Mineral Color Data
            app.SelectedMineralColorData = {};
            
            for i = 1:length(app.Mask.Names)
                WhereMin = find(ismember(app.MineralColorData,app.Mask.Names{i}));
                if ~isempty(WhereMin)
                   if isequal(app.Mask.Colors(i,:),app.XMapToolsApp.MineralColorData.RGB(WhereMin,:))
                        app.SelectedMineralColorData{i} = app.MineralColorData{WhereMin};
                   else
                       app.SelectedMineralColorData{i} = app.MineralColorData{end};  % edited
                   end
                else
                    app.SelectedMineralColorData{i} = app.MineralColorData{end-1};   % unavailable
                end
            end
            
            GenerateTable(app);
            PlotMaskMap(app);
            
            app.MaskColorEditorGUI.Visible = 'on';
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if isequal(indices(2),3) && indices(1) > 1
                c = uisetcolor( app.Mask.Colors(indices(1),:),'Select a color');
                app.Mask.Colors(indices(1),:) = c;
                app.SelectedMineralColorData{indices(1)} = app.MineralColorData{end};
                GenerateTable(app);
                PlotMaskMap(app);
                figure(app.MaskColorEditorGUI)
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            Where = find(ismember(app.MineralColorData,newData));
            if ~isempty(Where) && Where <= length(app.XMapToolsApp.MineralColorData.RGB) && indices(1) > 1
                app.SelectedMineralColorData{indices(1)} = app.MineralColorData{Where};
                app.Mask.Colors(indices(1),:) = app.XMapToolsApp.MineralColorData.RGB(Where,:);
                GenerateTable(app);
                PlotMaskMap(app);
                figure(app.MaskColorEditorGUI)
            end
            
        end

        % Button pushed function: AppyColor
        function AppyColorButtonPushed(app, event)
            app.XMapToolsApp.XMapToolsData.MapData.MaskFile.Masks(app.MaskId).Colors = app.Mask.Colors;
            
            MaskColorEditorGUICloseRequest(app);
        end

        % Close request function: MaskColorEditorGUI
        function MaskColorEditorGUICloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaskColorEditorGUI = uifigure('Visible', 'off');
            app.MaskColorEditorGUI.Name = 'Mask Color Editor – XMapTools';
            app.MaskColorEditorGUI.CloseRequestFcn = createCallbackFcn(app, @MaskColorEditorGUICloseRequest, true);
            app.GridLayout = uigridlayout(app.MaskColorEditorGUI);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Mask'; 'Database'; 'Color'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo_xmap_final.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AppyColor = uibutton(app.GridLayout2, 'push');
            app.AppyColor.ButtonPushedFcn = createCallbackFcn(app, @AppyColorButtonPushed, true);
            app.AppyColor.Icon = '044-repeat.png';
            app.AppyColor.Text = 'Apply Changes';
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.MaskColorEditorGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaskColorEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaskColorEditorGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaskColorEditorGUI)
        end
    end
end